classdef FilePreviewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Toolbar                       matlab.ui.container.Toolbar
        PushTool                      matlab.ui.container.toolbar.PushTool
        ofBinsSpinner                 matlab.ui.control.Spinner
        ofBinsSpinnerLabel            matlab.ui.control.Label
        HistogramDisplaySwitch        matlab.ui.control.Switch
        HistogramDisplaySwitchLabel   matlab.ui.control.Label
        YPositionEditField            matlab.ui.control.NumericEditField
        XPositionEditField            matlab.ui.control.NumericEditField
        FOVSizeEditField              matlab.ui.control.NumericEditField
        StopButton                    matlab.ui.control.Button
        FOVColorDropDown              matlab.ui.control.DropDown
        FOVColorDropDownLabel         matlab.ui.control.Label
        ColormapOptionsDropDown       matlab.ui.control.DropDown
        ColormapOptionsDropDownLabel  matlab.ui.control.Label
        YPositionSlider               matlab.ui.control.Slider
        YPositionSliderLabel          matlab.ui.control.Label
        XPositionSlider               matlab.ui.control.Slider
        XPositionSliderLabel          matlab.ui.control.Label
        ofChannelsSwitch              matlab.ui.control.Switch
        ofChannelsSwitchLabel         matlab.ui.control.Label
        FOVSizeSlider                 matlab.ui.control.Slider
        FOVSizeSliderLabel            matlab.ui.control.Label
        ClearDataButton               matlab.ui.control.Button
        Ch2Lamp                       matlab.ui.control.Lamp
        Ch1Lamp                       matlab.ui.control.Lamp
        ChooseCh2Button               matlab.ui.control.Button
        ChooseCh1Button               matlab.ui.control.Button
        SpeedEditField                matlab.ui.control.NumericEditField
        SpeedSlider                   matlab.ui.control.Slider
        SpeedSliderLabel              matlab.ui.control.Label
        FrameSelectionCheckBox        matlab.ui.control.CheckBox
        EndFrameEditField             matlab.ui.control.NumericEditField
        EndFrameEditFieldLabel        matlab.ui.control.Label
        StartFrameEditField           matlab.ui.control.NumericEditField
        StartFrameEditFieldLabel      matlab.ui.control.Label
        LoopContinuousCheckBox        matlab.ui.control.CheckBox
        RunButton                     matlab.ui.control.Button
        ofColorsSlider                matlab.ui.control.Slider
        ofColorsSliderLabel           matlab.ui.control.Label
        ColormapLabel                 matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
        FramesEditField               matlab.ui.control.NumericEditField
        FramesSlider                  matlab.ui.control.Slider
        FramesSliderLabel             matlab.ui.control.Label
        FOVHistUIAxes                 matlab.ui.control.UIAxes
        HistUIAxes                    matlab.ui.control.UIAxes
        Ch2FOVUIAxes                  matlab.ui.control.UIAxes
        Ch2UIAxes                     matlab.ui.control.UIAxes
        Ch1FOVUIAxes                  matlab.ui.control.UIAxes
        Ch1UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CallingApp; % Parent app
        filePath;
        Ch1Stack; % Channel 1 Image stack
        Ch2Stack; % Channel 2 Image stack
        isColormapOn;
        height = 100;
        width = 1000;
        isFrameSelectionEnabled;
        stackSpeed;
        FOVsquare;
        FOV2square;
        currRectangle;
        currentFOVcoors;
        isStop;
        
        FOVpos;
    end
    
    methods (Access = public)
        function file = colorMapChange(~, op, stack, x1, x2, y1, y2, frame, val)
            if (strcmp(op, 'Parula'))
                file = ind2rgb(uint8(255 * mat2gray(stack(y1:y2,x1:x2,frame))), parula(val));
            elseif (strcmp(op, 'Turbo'))
                file = ind2rgb(uint8(255 * mat2gray(stack(y1:y2,x1:x2,frame))), turbo(val));
            elseif (strcmp(op, 'HSV'))
                file = ind2rgb(uint8(255 * mat2gray(stack(y1:y2,x1:x2,frame))), hsv(val));
            elseif (strcmp(op, 'Cool'))
                file = ind2rgb(uint8(255 * mat2gray(stack(y1:y2,x1:x2,frame))), cool(val));
            elseif (strcmp(op, 'Hot'))
                file = ind2rgb(uint8(255 * mat2gray(stack(y1:y2,x1:x2,frame))), hot(val));
            else
                file = ind2rgb(uint8(255 * mat2gray(stack(y1:y2,x1:x2,frame))), jet(val));
            end
        end
        
        function showHist(app)
            frame = ceil(app.FramesSlider.Value);
            x1 = ceil(app.XPositionSlider.Value) + 1;
            x2 = x1 + ceil(app.FOVSizeSlider.Value);
            y1 = ceil(app.YPositionSlider.Value) + 1;
            y2 = y1 + ceil(app.FOVSizeSlider.Value);
            
            if (strcmp(app.HistogramDisplaySwitch.Value, 'Ch1'))
                histogram(app.HistUIAxes, app.Ch1Stack(:,:,frame), app.ofBinsSpinner.Value);
                histogram(app.FOVHistUIAxes, app.Ch1Stack(y1:y2,x1:x2,frame), app.ofBinsSpinner.Value);
            elseif (strcmp(app.ofChannelsSwitch.Value, '2'))
                if (strcmp(app.HistogramDisplaySwitch.Value, 'Ch2'))
                    histogram(app.HistUIAxes, app.Ch2Stack(:,:,frame), app.ofBinsSpinner.Value);
                    histogram(app.FOVHistUIAxes, app.Ch2Stack(y1:y2,x1:x2,frame), app.ofBinsSpinner.Value);
                end
            end
            
            
        end
        
        function checkColorMap(app, xPos, yPos, FOVsize, frame, val)
            op = app.ColormapOptionsDropDown.Value;
                    
            x1 = xPos + 1;
            x2 = x1 + FOVsize;
            y1 = yPos + 1;
            y2 = y1 + FOVsize;
            
            % Check if colormap off
            if (strcmp(app.isColormapOn, 'Off'))
                if (~isempty(app.Ch1Stack))
                    file1 = app.Ch1Stack(:,:,frame);
                    file1FOV = app.Ch1Stack(y1:y2,x1:x2,frame);
                    
                    imshow(file1, [app.height, app.width], 'Parent', app.Ch1UIAxes);
                    imshow(file1FOV, [app.height, app.width], 'Parent', app.Ch1FOVUIAxes);
                    
                    showHist(app);
                    
                    if (strcmp(app.ofChannelsSwitch.Value, '2')) 
                        if (~isempty(app.Ch2Stack))
                            file2 = app.Ch2Stack(:,:,frame);
                            file2FOV = app.Ch2Stack(y1:y2,x1:x2,frame);
                            
                            imshow(file2, [app.height, app.width], 'Parent', app.Ch2UIAxes);
                            imshow(file2FOV, [app.height, app.width], 'Parent', app.Ch2FOVUIAxes);
                            
                            showHist(app);
                        end
                    end
                end
                   
            % Colormap on
            else
                if (~isempty(app.Ch1Stack))
                    file1 = colorMapChange(app, op, app.Ch1Stack, 1, size(app.Ch1Stack, 2), 1, ...
                        size(app.Ch1Stack, 1), frame, val);
                    file1FOV = colorMapChange(app, op, app.Ch1Stack, x1, x2, y1, y2, frame, val);
                    
                    imshow(file1, [app.height, app.width], 'Parent', app.Ch1UIAxes);
                    imshow(file1FOV, [app.height, app.width], 'Parent', app.Ch1FOVUIAxes);
                    
                    showHist(app);
    
                    if (~isempty(app.Ch2Stack))
                        if (strcmp(app.ofChannelsSwitch.Value, '2'))
                            file2 = colorMapChange(app, op, app.Ch2Stack, 1, size(app.Ch2Stack, 2), 1, ...
                                size(app.Ch2Stack, 1), frame, val);
                            file2FOV = colorMapChange(app, op, app.Ch2Stack, x1, x2, y1, y2, frame, val);
                            
                            imshow(file2, [app.height, app.width], 'Parent', app.Ch2UIAxes);
                            imshow(file2FOV, [app.height, app.width], 'Parent', app.Ch2FOVUIAxes);
                            
                            showHist(app);
                        end
                    end
                end
            end
        end
        
        function setFOVColor(app, color)
            % Check if 2 channel activated
            if (strcmp(app.ofChannelsSwitch.Value, '2'))
                if (~isempty(app.FOV2square))
                    if (strcmp(color, 'Blue'))
                        app.FOV2square.Color = [0.0, 0.0, 1.0];
                    elseif (strcmp(color, 'Red'))
                        app.FOV2square.Color = [1.0, 0.0, 0.0];
                    elseif (strcmp(color, 'Green'))
                        app.FOV2square.Color = [0.0, 1.0, 0.0];
                    elseif (strcmp(color, 'Yellow'))
                        app.FOV2square.Color = [1.0, 1.0, 0.0];
                    elseif (strcmp(color, 'Orange'))
                        app.FOV2square.Color = [0.91, 0.41, 0.17];
                    elseif (strcmp(color, 'White'))
                        app.FOV2square.Color = [1.0, 1.0, 1.0];
                    else
                        app.FOV2square.Color = [0.0, 0.0, 0.0];
                    end
                end
            end
            
            % Change Channel 1 color
            if (~isempty(app.FOVsquare))
                if (strcmp(color, 'Blue'))
                    app.FOVsquare.Color = [0.0, 0.0, 1.0];
                elseif (strcmp(color, 'Red'))
                    app.FOVsquare.Color = [1.0, 0.0, 0.0];
                elseif (strcmp(color, 'Green'))
                    app.FOVsquare.Color = [0.0, 1.0, 0.0];
                elseif (strcmp(color, 'Yellow'))
                    app.FOVsquare.Color = [1.0, 1.0, 0.0];
                elseif (strcmp(color, 'Orange'))
                    app.FOVsquare.Color = [0.91, 0.41, 0.17];
                elseif (strcmp(color, 'White'))
                    app.FOVsquare.Color = [1.0, 1.0, 1.0];
                else
                    app.FOVsquare.Color = [0.0, 0.0, 0.0];
                end
            end
        end
        
        function drawFOV(app, p1, p2, p3, p4)
            if (~isempty(app.Ch1Stack))
                app.FOVsquare = drawrectangle(app.Ch1UIAxes, 'Position',...
                    [p1, p2, p3, p4], 'AspectRatio', 1, 'FixedAspectRatio', true, 'InteractionsAllowed', 'none');
            end
            
            
            % Check for Channel 2 FOV
            if (strcmp(app.ofChannelsSwitch.Value, '2'))
                if (~isempty(app.Ch2Stack))
                    app.FOV2square = drawrectangle(app.Ch2UIAxes, 'Position',...
                        [p1, p2, p3, p4], 'AspectRatio', 1, 'FixedAspectRatio', true, 'InteractionsAllowed', 'none');
                end
            end
            
            if (~isempty(app.Ch1Stack) || ~isempty(app.Ch2Stack))
                setFOVColor(app, app.FOVColorDropDown.Value);
                app.currentFOVcoors = app.FOVsquare.Position;
            end
        end
        
        function showFrames(app, startFrame, endFrame)
            xPos = ceil(app.XPositionSlider.Value);
            yPos = ceil(app.YPositionSlider.Value);
            FOVsize = ceil(app.FOVSizeSlider.Value);
            val = ceil(app.ofColorsSlider.Value);
            
            % Loop through frames
            for i = startFrame:endFrame
                % Check for Stop Clicked
                if (strcmp(app.isStop, 'Off'))
                    checkColorMap(app, xPos, yPos, FOVsize, i, val);
                    
                    oldFOVpos = app.currentFOVcoors;
                    drawFOV(app, oldFOVpos(1), oldFOVpos(2), oldFOVpos(3), oldFOVpos(4));
                                
                    app.FramesSlider.Value = i;
                    app.FramesEditField.Value = i;
                         
                    pause(1./(app.stackSpeed * 3.33));
                else
                    return;
                end
            end
        end
        
        function disableInteractivity(app)
            disableDefaultInteractivity(app.Ch1UIAxes);
            disableDefaultInteractivity(app.Ch1FOVUIAxes);
            disableDefaultInteractivity(app.HistUIAxes);
            disableDefaultInteractivity(app.Ch2UIAxes);
            disableDefaultInteractivity(app.Ch2FOVUIAxes);
            disableDefaultInteractivity(app.FOVHistUIAxes);
            
            app.Ch1UIAxes.Toolbar.Visible = 'Off';
            app.Ch1FOVUIAxes.Toolbar.Visible = 'Off';
            app.Ch2UIAxes.Toolbar.Visible = 'Off';
            app.Ch2FOVUIAxes.Toolbar.Visible = 'Off';
            app.HistUIAxes.Toolbar.Visible = 'Off';
            app.FOVHistUIAxes.Toolbar.Visible = 'Off';
        end
        
        function enableSliders(app)
            app.FramesSlider.Enable = 'On';
            app.FramesSliderLabel.Enable = 'On';
            app.FramesEditField.Enable = 'On';
            app.ofColorsSlider.Enable = 'On';
            app.ofColorsSliderLabel.Enable = 'On';
            app.ColormapLabel.Enable = 'On';
            app.Switch.Enable = 'On';
            app.FrameSelectionCheckBox.Enable = 'On';
            app.SpeedSlider.Enable = 'On';
            app.SpeedSliderLabel.Enable = 'On';
            app.SpeedEditField.Enable = 'On';
            app.LoopContinuousCheckBox.Enable = 'On';
            app.RunButton.Enable = 'On';
            app.StopButton.Enable = 'On';
            app.FOVSizeSlider.Enable = 'On';
            app.FOVSizeSliderLabel.Enable = 'On';
            app.XPositionSlider.Enable = 'On';
            app.XPositionSliderLabel.Enable = 'On';
            app.YPositionSlider.Enable = 'On';
            app.YPositionSliderLabel.Enable = 'On';
            app.ColormapOptionsDropDown.Enable = 'On';
            app.ColormapOptionsDropDownLabel.Enable = 'On';
            app.FOVColorDropDown.Enable = 'On';
            app.FOVColorDropDownLabel.Enable = 'On';
            app.FOVSizeEditField.Enable = 'On';
            app.XPositionEditField.Enable = 'On';
            app.YPositionEditField.Enable = 'On';
            app.ofBinsSpinner.Enable = 'On';
            app.ofBinsSpinnerLabel.Enable = 'On';
            app.HistogramDisplaySwitch.Enable = 'On';
            app.HistogramDisplaySwitchLabel.Enable = 'On';
            
            disableInteractivity(app);
        end
        
        function disableSliders(app)
            app.FramesSlider.Enable = 'Off';
            app.FramesSliderLabel.Enable = 'Off';
            app.FramesEditField.Enable = 'Off';
            app.ofColorsSlider.Enable = 'Off';
            app.ofColorsSliderLabel.Enable = 'Off';
            app.ColormapLabel.Enable = 'Off';
            app.Switch.Enable = 'Off';
            app.StartFrameEditField.Enable = 'Off';
            app.StartFrameEditFieldLabel.Enable = 'Off';
            app.EndFrameEditField.Enable = 'Off';
            app.EndFrameEditFieldLabel.Enable = 'Off';
            app.FrameSelectionCheckBox.Enable = 'Off';
            app.SpeedSlider.Enable = 'Off';
            app.SpeedSliderLabel.Enable = 'Off';
            app.SpeedEditField.Enable = 'Off';
            app.LoopContinuousCheckBox.Enable = 'Off';
            app.RunButton.Enable = 'Off';
            app.StopButton.Enable = 'Off';
            app.FOVSizeSlider.Enable = 'Off';
            app.FOVSizeSliderLabel.Enable = 'Off';
            app.XPositionSlider.Enable = 'Off';
            app.XPositionSliderLabel.Enable = 'Off';
            app.YPositionSlider.Enable = 'Off';
            app.YPositionSliderLabel.Enable = 'Off';
            app.ColormapOptionsDropDown.Enable = 'Off';
            app.ColormapOptionsDropDownLabel.Enable = 'Off';
            app.FOVColorDropDown.Enable = 'Off';
            app.FOVColorDropDownLabel.Enable = 'Off';
            app.FOVSizeEditField.Enable = 'Off';
            app.XPositionEditField.Enable = 'Off';
            app.YPositionEditField.Enable = 'Off';
            app.ofBinsSpinner.Enable = 'Off';
            app.ofBinsSpinnerLabel.Enable = 'Off';
            app.HistogramDisplaySwitch.Enable = 'Off';
            app.HistogramDisplaySwitchLabel.Enable = 'Off';
            
            disableInteractivity(app);
        end
        
        function deleteImage(~, axes)
            Image = findobj(axes, 'Type', 'image');
            
            if (~isempty(Image))
                cla(axes, 'reset');
            end
        end
        
        function resetTicks(~, axes)
            set(axes,'xtick',[])
            set(axes,'xticklabel',[])
            set(axes,'ytick',[])
            set(axes,'yticklabel',[])
        end
        
        function resetAxes(app)
            axis(app.Ch1UIAxes, 'equal');
            axis(app.Ch1FOVUIAxes, 'equal');
            axis(app.HistUIAxes, 'equal');
            axis(app.Ch2UIAxes, 'equal');
            axis(app.Ch2FOVUIAxes, 'equal');
            axis(app.FOVHistUIAxes, 'equal');
            app.Ch1UIAxes.XColor = 'w';
            app.Ch1UIAxes.YColor = 'w';
            app.Ch1FOVUIAxes.XColor = 'w';
            app.Ch1FOVUIAxes.YColor = 'w';
            app.Ch2UIAxes.XColor = 'w';
            app.Ch2UIAxes.YColor = 'w';
            app.Ch2FOVUIAxes.XColor = 'w';
            app.Ch2FOVUIAxes.YColor = 'w';

            resetTicks(app, app.Ch1UIAxes);
            resetTicks(app, app.Ch1FOVUIAxes);
            resetTicks(app, app.Ch2UIAxes);
            resetTicks(app, app.Ch2FOVUIAxes);
            
            disableInteractivity(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, STICSgui1, File1, File2, Pos)
            app.CallingApp = STICSgui1;
            app.FOVpos = Pos;
            resetAxes(app);
            app.isColormapOn = app.Switch.Value;
            app.stackSpeed = app.SpeedSlider.Value;
            
            if (~isempty(File1))
                app.Ch1Stack = File1;
                
                app.FramesSlider.Limits = [1, size(app.Ch1Stack, 3)];
                app.ofColorsSlider.Limits = [1, 255];
                app.FOVSizeSlider.Limits = [1, size(app.Ch1Stack, 1) - 2];
                
                enableSliders(app);
            
                checkColorMap(app, 1, 1, ceil(app.FOVSizeSlider.Value), ceil(app.FramesSlider.Value),...
                    ceil(app.ofColorsSlider.Value));
          
                drawFOV(app, 1, 1, ceil(app.FOVSizeSlider.Value), ceil(app.FOVSizeSlider.Value));
                
                % Enable adjustable fields
                app.Ch1Lamp.Color = [0.0 1.0 0.0];
                app.ChooseCh1Button.Enable = 'Off';
                app.ChooseCh1Button.Visible = 'Off';
            end
            
            if (~isempty(File2))
                app.Ch2Stack = File2;
                
                app.ofChannelsSwitch.Value = '2';
                app.Ch2Lamp.Enable = 'On';
                
                if (isempty(File1))
                    app.FramesSlider.Limits = [1, size(app.Ch2Stack, 3)];
                    app.ofColorsSlider.Limits = [1, 255];
                    app.FOVSizeSlider.Limits = [1, size(app.Ch2Stack, 1) - 2];
                    
                    enableSliders(app);
                end
                
                checkColorMap(app, 1, 1, ceil(app.FOVSizeSlider.Value), ceil(app.FramesSlider.Value),...
                    ceil(app.ofColorsSlider.Value));
          
                drawFOV(app, 1, 1, ceil(app.FOVSizeSlider.Value), ceil(app.FOVSizeSlider.Value));
                
                % Enable adjustable fields
                app.Ch2Lamp.Color = [0.0 1.0 0.0];
                app.ChooseCh2Button.Enable = 'Off';
                app.ChooseCh2Button.Visible = 'Off';
                
            end
            
            app.HistUIAxes.XTickMode = 'auto';
            app.HistUIAxes.YTickMode = 'auto';
            app.FOVHistUIAxes.XTickMode = 'auto';
            app.FOVHistUIAxes.YTickMode = 'auto';
            
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            if (~isempty(app.FOVpos))
                FOVsize = ceil(app.FOVSizeSlider.Value);
                x1 = ceil(app.XPositionSlider.Value);
                y1 = ceil(app.YPositionSlider.Value);
                x2 = x1 + FOVsize;
                y2 = y1 + FOVsize;
                
                [x1, x2, y1, y2]
                app.CallingApp.dataFOV(:,:,:,:,app.FOVpos) = [x1, x2, y1, y2];
            end
        end

        % Button pushed function: ChooseCh1Button
        function ChooseCh1ButtonPushed(app, event)
            app.Ch1Lamp.Color = [1.0 1.0 0.0];
            
            [fileName, pathName] = uigetfile('*.tif','Please select your data set');
            
            % Check if user clicked cancel
            if (fileName == 0)
                if (~app.filePath)
                    app.Ch1Lamp.Color = [1.0 0.0 0.0];
                else
                    app.Ch1Lamp.Color = [0.0 1.0 0.0];
                end
                
                return
            end
            
            app.filePath = pathName;
            ch1name=fileName(1:end-4);
            data = [app.filePath ch1name '.tif'];
            
            % Add path to code from parent folder
            addpath('current/STICS/');
            
            % Check if data is in MultiTiff files
            if (strcmp(fileName(end-2:end),'tif'))
                try
                    app.Ch1Stack = readFileToStack(data);
                catch Error
                    % Select another file
                    [app.StackFileName, app.StackPath] = uigetfile('*.tif', 'Select Stack File');
                    app.Ch1Stack = readFileToStack([app.StackPath, app.StackFileName]);
                end
                
                isValid = 1;
            else
                isValid = 0;
            end
            
            if (~isempty(app.Ch2Stack))
                if (size(app.Ch1Stack, 3) ~= size(app.Ch2Stack, 3))
                    app.Ch1Lamp.Color = [1.0 0.0 0.0];
                    return;
                end
            end
            
            app.FramesSlider.Limits = [1, size(app.Ch1Stack, 3)];
            app.ofColorsSlider.Limits = [1, 255];
            app.FOVSizeSlider.Limits = [1, size(app.Ch1Stack, 1) - 2];
            
            checkColorMap(app, 1, 1, ceil(app.FOVSizeSlider.Value), ceil(app.FramesSlider.Value),...
                ceil(app.ofColorsSlider.Value));
          
            drawFOV(app, 1, 1, ceil(app.FOVSizeSlider.Value), ceil(app.FOVSizeSlider.Value));
            
            % Check if file valid
            if (isValid)
                % Enable adjustable fields
                app.Ch1Lamp.Color = [0.0 1.0 0.0];
                enableSliders(app);
                app.ChooseCh1Button.Enable = 'Off';
                app.ChooseCh1Button.Visible = 'Off';
                
            % File invalid
            else
                % Disable adjustable fields
                app.Ch1Lamp.Color = [1.0 0.0 0.0];
                disableSliders(app);
                app.ChooseCh1Button.Enable = 'On';
                app.ChooseCh1Button.Visible = 'On';
            end
        end

        % Button pushed function: ChooseCh2Button
        function ChooseCh2ButtonPushed(app, event)
            app.Ch2Lamp.Color = [1.0 1.0 0.0];
            
            [fileName, pathName] = uigetfile('*.tif','Please select your data set');
            
            % Check if user clicked cancel
            if (fileName == 0)
                if (~app.filePath)
                    app.Ch2Lamp.Color = [1.0 0.0 0.0];
                else
                    app.Ch2Lamp.Color = [0.0 1.0 0.0];
                end
                
                return
            end
            
            app.filePath = pathName;
            ch2name=fileName(1:end-4);
            data = [app.filePath ch2name '.tif'];
            
            % Add path to code from parent folder
            addpath('current/STICS/');
            
            % Check if data is in MultiTiff files
            if (strcmp(fileName(end-2:end),'tif'))
                try
                    app.Ch2Stack = readFileToStack(data);
                catch Error
                    % Select another file
                    [app.StackFileName, app.StackPath] = uigetfile('*.tif', 'Select Stack File');
                    app.Ch2Stack = readFileToStack([app.StackPath, app.StackFileName]);
                end
                
                isValid = 1;
            else
                isValid = 0;
            end
            
            if (~isempty(app.Ch1Stack))
                if (size(app.Ch2Stack, 3) ~= size(app.Ch1Stack, 3))
                    app.Ch2Lamp.Color = [1.0 0.0 0.0];
                    return;
                end
            end
            
            app.FramesSlider.Limits = [1, size(app.Ch2Stack, 3)];
            app.ofColorsSlider.Limits = [1, 255];
            app.FOVSizeSlider.Limits = [1, size(app.Ch2Stack, 1) - 2];
            
            checkColorMap(app, 1, 1, ceil(app.FOVSizeSlider.Value), ceil(app.FramesSlider.Value),...
                ceil(app.ofColorsSlider.Value));
            
            drawFOV(app, 1, 1, ceil(app.FOVSizeSlider.Value), ceil(app.FOVSizeSlider.Value));
            
            % Check if file valid
            if (isValid)
                % Enable adjustable fields
                app.Ch2Lamp.Color = [0.0 1.0 0.0];
                enableSliders(app);
                app.ChooseCh2Button.Enable = 'Off';
                app.ChooseCh2Button.Visible = 'Off';
                
            % File invalid
            else
                % Disable adjustable fields
                app.Ch2Lamp.Color = [1.0 0.0 0.0];
                disableSliders(app);
                app.ChooseCh2Button.Enable = 'On';
                app.ChooseCh2Button.Visible = 'On';
            end
        end

        % Value changing function: FOVSizeSlider
        function FOVSizeSliderValueChanging(app, event)
            changingValue = ceil(event.Value);
            
            checkColorMap(app, ceil(app.XPositionSlider.Value), ceil(app.YPositionSlider.Value),...
                changingValue, ceil(app.FramesSlider.Value), ceil(app.ofColorsSlider.Value));
            
            delete(app.FOVsquare);
            
            drawFOV(app, 1, 1, changingValue, changingValue);
            
            app.FOVSizeEditField.Value = changingValue;
            
            app.XPositionSlider.Limits = [0, size(app.Ch1Stack, 1) - changingValue - 1];
            app.YPositionSlider.Limits = [0, size(app.Ch1Stack, 2) - changingValue - 1];
            
            app.XPositionSlider.Value = 0;
            app.YPositionSlider.Value = 0;
        end

        % Value changed function: FOVSizeSlider
        function FOVSizeSliderValueChanged(app, event)
            value = ceil(app.FOVSizeSlider.Value);
            
            checkColorMap(app, ceil(app.XPositionSlider.Value), ceil(app.YPositionSlider.Value),...
                value, ceil(app.FramesSlider.Value), ceil(app.ofColorsSlider.Value));
            
            delete(app.FOVsquare);
            
            drawFOV(app, 1, 1, value, value);
            
            app.FOVSizeEditField.Value = value;
            
            app.XPositionSlider.Limits = [0, size(app.Ch1Stack, 1) - value - 1];
            app.YPositionSlider.Limits = [0, size(app.Ch1Stack, 2) - value - 1];
            
            app.XPositionSlider.Value = 0;
            app.YPositionSlider.Value = 0;
        end

        % Value changed function: FOVSizeEditField
        function FOVSizeEditFieldValueChanged(app, event)
            value = ceil(app.FOVSizeEditField.Value);
            
            if (value < 1)
                value = 1;
            elseif (value > app.FOVSizeSlider.Limits(2))
                value = app.FOVSizeSlider.Limits(2);
            end
            
            app.FOVSizeEditField.Value = value;
            
            if (value ~= app.FOVSizeSlider.Value)
                checkColorMap(app, ceil(app.XPositionSlider.Value), ceil(app.YPositionSlider.Value), value,...
                    ceil(app.FramesSlider.Value), ceil(app.ofColorsSlider.Value));
            
                delete(app.FOVsquare);
                
                drawFOV(app, 1, 1, value, value);
                
                app.FOVSizeSlider.Value = value;
                
                app.XPositionSlider.Limits = [0, size(app.Ch1Stack, 1) - value - 1];
                app.YPositionSlider.Limits = [0, size(app.Ch1Stack, 2) - value - 1];
                
                app.XPositionSlider.Value = 0;
                app.YPositionSlider.Value = 0;
            end
        end

        % Value changed function: FOVColorDropDown
        function FOVColorDropDownValueChanged(app, event)
            value = app.FOVColorDropDown.Value;
            
            setFOVColor(app, value);        
        end

        % Value changing function: XPositionSlider
        function XPositionSliderValueChanging(app, event)
            changingValue = ceil(event.Value);
            
            checkColorMap(app, changingValue, ceil(app.YPositionSlider.Value), ceil(app.FOVSizeSlider.Value),...
                ceil(app.FramesSlider.Value), ceil(app.ofColorsSlider.Value));
            
            delete(app.FOVsquare);
            
            oldFOVpos = app.currentFOVcoors;
            drawFOV(app, changingValue, oldFOVpos(2), oldFOVpos(3), oldFOVpos(4));
            
            app.XPositionEditField.Value = changingValue;
        end

        % Value changed function: XPositionSlider
        function XPositionSliderValueChanged(app, event)
            value = ceil(app.XPositionSlider.Value);

            checkColorMap(app, value, ceil(app.YPositionSlider.Value), ceil(app.FOVSizeSlider.Value),...
                ceil(app.FramesSlider.Value), ceil(app.ofColorsSlider.Value));
            
            delete(app.FOVsquare);
            
            oldFOVpos = app.currentFOVcoors;
            drawFOV(app, value, oldFOVpos(2), oldFOVpos(3), oldFOVpos(4));
            
            app.XPositionEditField.Value = value;
        end

        % Value changed function: XPositionEditField
        function XPositionEditFieldValueChanged(app, event)
            value = ceil(app.XPositionEditField.Value);
            
            if (value < 0)
                value = 0;
            elseif (value > app.XPositionSlider.Limits(2))
                value = floor(app.XPositionSlider.Limits(2));
            end
            
            app.XPositionEditField.Value = value;
            
            if (value ~= app.XPositionSlider.Value)
                checkColorMap(app, value, ceil(app.YPositionSlider.Value), ceil(app.FOVSizeSlider.Value),...
                    ceil(app.FramesSlider.Value), ceil(app.ofColorsSlider.Value));
            
                delete(app.FOVsquare);
                
                oldFOVpos = app.currentFOVcoors;
                drawFOV(app, value, oldFOVpos(2), oldFOVpos(3), oldFOVpos(4));
                
                app.XPositionSlider.Value = value;
            end
        end

        % Value changing function: YPositionSlider
        function YPositionSliderValueChanging(app, event)
            changingValue = ceil(event.Value);
            
            checkColorMap(app, ceil(app.XPositionSlider.Value), changingValue, ceil(app.FOVSizeSlider.Value),...
                ceil(app.FramesSlider.Value), ceil(app.ofColorsSlider.Value));
            
            delete(app.FOVsquare);
            
            oldFOVpos = app.currentFOVcoors;
            drawFOV(app, oldFOVpos(1), changingValue, oldFOVpos(3), oldFOVpos(4));
            
            app.YPositionEditField.Value = changingValue;
        end

        % Value changed function: YPositionSlider
        function YPositionSliderValueChanged(app, event)
            value = ceil(app.YPositionSlider.Value);
            
            checkColorMap(app, ceil(app.XPositionSlider.Value), value, ceil(app.FOVSizeSlider.Value),...
                ceil(app.FramesSlider.Value), ceil(app.ofColorsSlider.Value));
            
            delete(app.FOVsquare);
            
            oldFOVpos = app.currentFOVcoors;
            drawFOV(app, oldFOVpos(1), value, oldFOVpos(3), oldFOVpos(4));
            
            app.YPositionEditField.Value = value;
        end

        % Value changed function: YPositionEditField
        function YPositionEditFieldValueChanged(app, event)
            value = ceil(app.YPositionEditField.Value);
            
            % Check for out of range values
            if (value < 0)
                value = 0;
            elseif (value > app.YPositionSlider.Limits(2))
                value = floor(app.YPositionSlider.Limits(2));
            end
            
            app.YPositionEditField.Value = value;
            
            if (value ~= app.YPositionSlider.Value)
                checkColorMap(app, ceil(app.XPositionSlider.Value), value, ceil(app.FOVSizeSlider.Value),...
                    ceil(app.FramesSlider.Value), ceil(app.ofColorsSlider.Value));
            
                delete(app.FOVsquare);
                
                oldFOVpos = app.currentFOVcoors;
                drawFOV(app, oldFOVpos(1), value, oldFOVpos(3), oldFOVpos(4));
                
                app.YPositionSlider.Value = value;
            end
        end

        % Value changed function: ofChannelsSwitch
        function ofChannelsSwitchValueChanged(app, event)
            value = app.ofChannelsSwitch.Value;
            
            if (strcmp(value, '2'))
                app.ChooseCh2Button.Enable = 'On';
                app.Ch2Lamp.Enable = 'On';
            else
                app.ChooseCh2Button.Enable = 'Off';
                app.Ch2Lamp.Enable = 'Off';
            end
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            deleteImage(app, app.Ch1UIAxes);
            deleteImage(app, app.Ch1FOVUIAxes);
            deleteImage(app, app.Ch2UIAxes);
            deleteImage(app, app.Ch2FOVUIAxes);
            
            resetAxes(app);
            
            app.ChooseCh1Button.Enable = 'On';
            app.ChooseCh1Button.Visible = 'On';
            %app.ChooseCh2Button.Enable = 'On';
            app.ChooseCh2Button.Visible = 'On';
            
            app.Ch1Lamp.Color = [1.0 0.0 0.0];
            app.Ch2Lamp.Color = [1.0 0.0 0.0];
            
            app.filePath = '';
            app.Ch1Stack = '';
            app.Ch2Stack = '';
            
            disableSliders(app);
        end

        % Value changing function: FramesSlider
        function FramesSliderValueChanging(app, event)
            % Force value to round up
            changingValue = ceil(event.Value);
            
            checkColorMap(app, ceil(app.XPositionSlider.Value), ceil(app.YPositionSlider.Value),...
                ceil(app.FOVSizeSlider.Value), changingValue, ceil(app.ofColorsSlider.Value));
            
            delete(app.FOVsquare);
            oldFOVpos = app.currentFOVcoors;
            drawFOV(app, oldFOVpos(1), oldFOVpos(2), oldFOVpos(3), oldFOVpos(4));
            
            app.FramesEditField.Value = changingValue;
        end

        % Value changed function: FramesEditField
        function FramesEditFieldValueChanged(app, event)
            value = ceil(app.FramesEditField.Value);
            
            checkColorMap(app, ceil(app.XPositionSlider.Value), ceil(app.YPositionSlider.Value),...
                ceil(app.FOVSizeSlider.Value), value, ceil(app.ofColorsSlider.Value));
            
            oldFOVpos = app.currentFOVcoors;
            delete(app.FOVsquare);
            drawFOV(app, oldFOVpos(1), oldFOVpos(2), oldFOVpos(3), oldFOVpos(4));
            
            % Adjust slider value
            if (app.FramesSlider.Value ~= value)
                app.FramesSlider.Value = value;
            end
        end

        % Value changing function: ofColorsSlider
        function ofColorsSliderValueChanging(app, event)
            changingValue = ceil(event.Value);
            
            checkColorMap(app, ceil(app.XPositionSlider.Value), ceil(app.YPositionSlider.Value),...
                ceil(app.FOVSizeSlider.Value), ceil(app.FramesSlider.Value), changingValue);
            
            oldFOVpos = app.currentFOVcoors;
            delete(app.FOVsquare);
            drawFOV(app, oldFOVpos(1), oldFOVpos(2), oldFOVpos(3), oldFOVpos(4));     
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.isColormapOn = value;
            
            oldFOVpos = app.currentFOVcoors;
            
            op = app.ColormapOptionsDropDown.Value;
            frame = ceil(app.FramesSlider.Value);
            val = ceil(app.ofColorsSlider.Value);
            FOVsize = ceil(app.FOVSizeSlider.Value);
                
            x1 = oldFOVpos(1) + 1;
            x2 = x1 + FOVsize;
            y1 = oldFOVpos(1) + 1;
            y2 = y1 + FOVsize;
                
            % Check if switch flipped to on
            if (strcmp(value, 'On'))
                if (~isempty(app.Ch1Stack))
                    file1 = colorMapChange(app, op, app.Ch1Stack, 1, size(app.Ch1Stack, 2), 1, ...
                        size(app.Ch1Stack, 1), frame, val);
                    file1FOV = colorMapChange(app, op, app.Ch1Stack, x1, x2, y1, y2, frame, val);
                    
                    imshow(file1, [app.height, app.width], 'Parent', app.Ch1UIAxes);
                    imshow(file1FOV, [app.height, app.width], 'Parent', app.Ch1FOVUIAxes);
                    delete(app.FOVsquare);
                end
                
                if (strcmp(app.ofChannelsSwitch.Value, '2'))
                    if (~isempty(app.Ch2Stack))
                        file2 = colorMapChange(app, op, app.Ch2Stack, 1, size(app.Ch2Stack, 2), 1, ...
                            size(app.Ch2Stack, 1), frame, val);
                        file2FOV = colorMapChange(app, op, app.Ch2Stack, x1, x2, y1, y2, frame, val);
                        
                        imshow(file2, [app.height, app.width], 'Parent', app.Ch2UIAxes);
                        imshow(file2FOV, [app.height, app.width], 'Parent', app.Ch2FOVUIAxes);
                        delete(app.FOV2square);
                    end
                end
                
            % Switch off
            else
                if (~isempty(app.Ch1Stack))
                    imshow(app.Ch1Stack(:,:,frame), [app.height, app.width], 'Parent', app.Ch1UIAxes);
                    imshow(app.Ch1Stack(y1:y2,x1:x2,frame), [app.height, app.width], 'Parent', app.Ch1FOVUIAxes);
                    delete(app.FOVsquare);
                end
                
                if (strcmp(app.ofChannelsSwitch.Value, '2'))
                    if (~isempty(app.Ch2Stack))
                        imshow(app.Ch2Stack(:,:,frame), [app.height, app.width], 'Parent', app.Ch2UIAxes);
                        imshow(app.Ch2Stack(y1:y2,x1:x2,frame), [app.height, app.width], 'Parent', app.Ch2FOVUIAxes);
                        delete(app.FOV2square);
                    end
                end
                
            end
            
            drawFOV(app, oldFOVpos(1), oldFOVpos(2), oldFOVpos(3), oldFOVpos(4));  
        end

        % Value changed function: ColormapOptionsDropDown
        function ColormapOptionsDropDownValueChanged(app, event)
            value = app.ColormapOptionsDropDown.Value;
            
            oldFOVpos = app.currentFOVcoors;
           
            op = value;
            frame = ceil(app.FramesSlider.Value);
            val = ceil(app.ofColorsSlider.Value);
            FOVsize = ceil(app.FOVSizeSlider.Value);
                
            x1 = oldFOVpos(1) + 1;
            x2 = x1 + FOVsize;
            y1 = oldFOVpos(1) + 1;
            y2 = y1 + FOVsize;
            
            % Check if colormap on when new option clicked
            if (strcmp(app.isColormapOn, 'On'))
                if (~isempty(app.Ch1Stack))
                    file1 = colorMapChange(app, op, app.Ch1Stack, 1, size(app.Ch1Stack, 2), 1, ...
                        size(app.Ch1Stack, 1), frame, val);
                    file1FOV = colorMapChange(app, op, app.Ch1Stack, x1, x2, y1, y2, frame, val);
                    
                    imshow(file1, [app.height, app.width], 'Parent', app.Ch1UIAxes);
                    imshow(file1FOV, [app.height, app.width], 'Parent', app.Ch1FOVUIAxes);
                    delete(app.FOVsquare);
                end
                
                if (strcmp(app.ofChannelsSwitch.Value, '2'))
                    if (~isempty(app.Ch2Stack))
                        file2 = colorMapChange(app, op, app.Ch2Stack, 1, size(app.Ch2Stack, 2), 1, ...
                            size(app.Ch2Stack, 1), frame, val);
                        file2FOV = colorMapChange(app, op, app.Ch2Stack, x1, x2, y1, y2, frame, val);
                        
                        imshow(file2, [app.height, app.width], 'Parent', app.Ch2UIAxes);
                        imshow(file2FOV, [app.height, app.width], 'Parent', app.Ch2FOVUIAxes);
                        
                        delete(app.FOV2square);
                    end
                end
                
                drawFOV(app, oldFOVpos(1), oldFOVpos(2), oldFOVpos(3), oldFOVpos(4));
            end
        end

        % Value changed function: FrameSelectionCheckBox
        function FrameSelectionCheckBoxValueChanged(app, event)
            value = app.FrameSelectionCheckBox.Value;
            
            app.isFrameSelectionEnabled = value;
            
            if (value)
                app.StartFrameEditField.Enable = 'On';
                app.StartFrameEditFieldLabel.Enable = 'On';
                app.EndFrameEditField.Enable = 'On';
                app.EndFrameEditFieldLabel.Enable = 'On';
            else
                app.StartFrameEditField.Enable = 'Off';
                app.StartFrameEditFieldLabel.Enable = 'Off';
                app.EndFrameEditField.Enable = 'Off';
                app.EndFrameEditFieldLabel.Enable = 'Off';
            end
        end

        % Value changed function: StartFrameEditField
        function StartFrameEditFieldValueChanged(app, event)
            value = app.StartFrameEditField.Value;
            
            % Check if start frame > end frame
            if (value >= app.EndFrameEditField.Value && value >= 1)
                app.EndFrameEditField.Value = value + 1;
            elseif (value < 1)
                app.StartFrameEditField.Value = 1;
            end
            
        end

        % Value changed function: EndFrameEditField
        function EndFrameEditFieldValueChanged(app, event)
            value = app.EndFrameEditField.Value;
            
            if (value <= app.StartFrameEditField.Value && value <= size(app.Ch1Stack, 3))
                app.StartFrameEditField.Value = value - 1;
            elseif (value > size(app.Ch1Stack, 3))
                app.EndFrameEditField.Value = size(app.Ch1Stack, 3);
            end
            
        end

        % Value changing function: SpeedSlider
        function SpeedSliderValueChanging(app, event)
            changingValue = round(event.Value, 2);
            
            app.SpeedEditField.Value = changingValue;
            app.stackSpeed = changingValue;
        end

        % Value changed function: SpeedEditField
        function SpeedEditFieldValueChanged(app, event)
            value = round(app.SpeedEditField.Value, 1);
            
            if (value < 0.3)
                app.SpeedEditField.Value = 0.3;
            elseif (value > 3)
                app.SpeedEditField.Value = 3.0;
            end
            
            app.SpeedSlider.Value = value;
            
            app.stackSpeed = app.SpeedEditField.Value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.RunButton.Enable = 'Off';
            app.SpeedSlider.Enable = 'Off';
            app.SpeedEditField.Enable = 'Off';
            app.isStop = 'Off';
            
            % Loop continuous checked
            if (app.LoopContinuousCheckBox.Value)
                % Keep checking if continuous looping checked
                while (1)
                    % Check if frame chunk selected
                    if (app.isFrameSelectionEnabled)
                        showFrames(app, app.StartFrameEditField.Value, app.EndFrameEditField.Value);
                    else
                        showFrames(app, 1, size(app.Ch1Stack, 3));
                    end
                    
                    pause(0.3);
                    
                    if (strcmp(app.isStop, 'On') || ~app.LoopContinuousCheckBox.Value)
                        break;
                    end
                end
            % Loop continuous not checked
            else
                % Check if frame chunk selected
                if (app.isFrameSelectionEnabled)
                    showFrames(app, app.StartFrameEditField.Value, app.EndFrameEditField.Value);
                else
                    showFrames(app, 1, size(app.Ch1Stack, 3));
                end
            end
            
            app.RunButton.Enable = 'On';
            app.SpeedSlider.Enable = 'On';
            app.SpeedEditField.Enable = 'On';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.isStop = 'On';
            app.isStop
        end

        % Value changed function: ofBinsSpinner
        function ofBinsSpinnerValueChanged(app, event)
            showHist(app);
        end

        % Value changing function: ofBinsSpinner
        function ofBinsSpinnerValueChanging(app, event)
            showHist(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: HistogramDisplaySwitch
        function HistogramDisplaySwitchValueChanged(app, event)
           showHist(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'Push_Icon.png';
            app.Ch1UIAxes = uiaxes(app.UIFigure);
            app.Ch1UIAxes.XTick = [];
            app.Ch1UIAxes.YTick = [];
            app.Ch1FOVUIAxes = uiaxes(app.UIFigure);
            app.Ch1FOVUIAxes.XTick = [];
            app.Ch1FOVUIAxes.YTick = [];
            app.Ch2UIAxes = uiaxes(app.UIFigure);
            app.Ch2UIAxes.XTick = [];
            app.Ch2UIAxes.YTick = [];
            app.Ch2FOVUIAxes = uiaxes(app.UIFigure);
            app.Ch2FOVUIAxes.XTick = [];
            app.Ch2FOVUIAxes.YTick = [];
            app.HistUIAxes = uiaxes(app.UIFigure);
            app.HistUIAxes.XTick = [];
            app.HistUIAxes.YTick = [];
            app.FOVHistUIAxes = uiaxes(app.UIFigure);
            app.FOVHistUIAxes.XTick = [];
            app.FOVHistUIAxes.YTick = [];
            app.FramesSliderLabel = uilabel(app.UIFigure);
            app.FramesSliderLabel.Enable = 'off';
            app.FramesSliderLabel.Text = 'Frames';
            app.FramesSlider = uislider(app.UIFigure);
            app.FramesSlider.Limits = [1 100];
            app.FramesSlider.ValueChangingFcn = createCallbackFcn(app, @FramesSliderValueChanging, true);
            app.FramesSlider.Enable = 'off';
            app.FramesSlider.Value = 1;
            app.FramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.FramesEditField.ValueChangedFcn = createCallbackFcn(app, @FramesEditFieldValueChanged, true);
            app.FramesEditField.Enable = 'off';
            app.FramesEditField.Value = 1;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Enable = 'off';
            app.ColormapLabel = uilabel(app.UIFigure);
            app.ColormapLabel.Enable = 'off';
            app.ColormapLabel.Text = 'Colormap';
            app.ofColorsSliderLabel = uilabel(app.UIFigure);
            app.ofColorsSliderLabel.Enable = 'off';
            app.ofColorsSliderLabel.Text = '# of Colors';
            app.ofColorsSlider = uislider(app.UIFigure);
            app.ofColorsSlider.Limits = [1 255];
            app.ofColorsSlider.ValueChangingFcn = createCallbackFcn(app, @ofColorsSliderValueChanging, true);
            app.ofColorsSlider.Enable = 'off';
            app.ofColorsSlider.Value = 255;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.LoopContinuousCheckBox = uicheckbox(app.UIFigure);
            app.LoopContinuousCheckBox.Enable = 'off';
            app.LoopContinuousCheckBox.Text = 'Loop Continuous';
            app.StartFrameEditFieldLabel = uilabel(app.UIFigure);
            app.StartFrameEditFieldLabel.Enable = 'off';
            app.StartFrameEditFieldLabel.Text = 'Start Frame';
            app.StartFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartFrameEditField.ValueChangedFcn = createCallbackFcn(app, @StartFrameEditFieldValueChanged, true);
            app.StartFrameEditField.Enable = 'off';
            app.StartFrameEditField.Value = 1;
            app.EndFrameEditFieldLabel = uilabel(app.UIFigure);
            app.EndFrameEditFieldLabel.Enable = 'off';
            app.EndFrameEditFieldLabel.Text = 'End Frame';
            app.EndFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndFrameEditField.ValueChangedFcn = createCallbackFcn(app, @EndFrameEditFieldValueChanged, true);
            app.EndFrameEditField.Enable = 'off';
            app.EndFrameEditField.Value = 2;
            app.FrameSelectionCheckBox = uicheckbox(app.UIFigure);
            app.FrameSelectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @FrameSelectionCheckBoxValueChanged, true);
            app.FrameSelectionCheckBox.Enable = 'off';
            app.FrameSelectionCheckBox.Text = 'Frame Selection';
            app.SpeedSliderLabel = uilabel(app.UIFigure);
            app.SpeedSliderLabel.Enable = 'off';
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.UIFigure);
            app.SpeedSlider.Limits = [0.3 3];
            app.SpeedSlider.ValueChangingFcn = createCallbackFcn(app, @SpeedSliderValueChanging, true);
            app.SpeedSlider.Enable = 'off';
            app.SpeedSlider.Value = 1;
            app.SpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeedEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedEditFieldValueChanged, true);
            app.SpeedEditField.Enable = 'off';
            app.SpeedEditField.Value = 1;
            app.ChooseCh1Button = uibutton(app.UIFigure, 'push');
            app.ChooseCh1Button.ButtonPushedFcn = createCallbackFcn(app, @ChooseCh1ButtonPushed, true);
            app.ChooseCh1Button.Text = 'Select Channel 1';
            app.ChooseCh2Button = uibutton(app.UIFigure, 'push');
            app.ChooseCh2Button.ButtonPushedFcn = createCallbackFcn(app, @ChooseCh2ButtonPushed, true);
            app.ChooseCh2Button.Enable = 'off';
            app.ChooseCh2Button.Text = 'Select Channel 2';
            app.Ch1Lamp = uilamp(app.UIFigure);
            app.Ch2Lamp = uilamp(app.UIFigure);
            app.Ch2Lamp.Enable = 'off';
            app.ClearDataButton = uibutton(app.UIFigure, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.FOVSizeSliderLabel = uilabel(app.UIFigure);
            app.FOVSizeSliderLabel.Enable = 'off';
            app.FOVSizeSliderLabel.Text = 'FOV Size';
            app.FOVSizeSlider = uislider(app.UIFigure);
            app.FOVSizeSlider.ValueChangedFcn = createCallbackFcn(app, @FOVSizeSliderValueChanged, true);
            app.FOVSizeSlider.ValueChangingFcn = createCallbackFcn(app, @FOVSizeSliderValueChanging, true);
            app.FOVSizeSlider.Enable = 'off';
            app.ofChannelsSwitchLabel = uilabel(app.UIFigure);
            app.ofChannelsSwitchLabel.Text = '# of Channels';
            app.ofChannelsSwitch = uiswitch(app.UIFigure, 'slider');
            app.ofChannelsSwitch.Items = {'1', '2'};
            app.ofChannelsSwitch.ValueChangedFcn = createCallbackFcn(app, @ofChannelsSwitchValueChanged, true);
            app.ofChannelsSwitch.Value = '1';
            app.XPositionSliderLabel = uilabel(app.UIFigure);
            app.XPositionSliderLabel.Enable = 'off';
            app.XPositionSliderLabel.Text = 'X Position';
            app.XPositionSlider = uislider(app.UIFigure);
            app.XPositionSlider.ValueChangedFcn = createCallbackFcn(app, @XPositionSliderValueChanged, true);
            app.XPositionSlider.ValueChangingFcn = createCallbackFcn(app, @XPositionSliderValueChanging, true);
            app.XPositionSlider.Enable = 'off';
            app.YPositionSliderLabel = uilabel(app.UIFigure);
            app.YPositionSliderLabel.Enable = 'off';
            app.YPositionSliderLabel.Text = 'Y Position';
            app.YPositionSlider = uislider(app.UIFigure);
            app.YPositionSlider.ValueChangedFcn = createCallbackFcn(app, @YPositionSliderValueChanged, true);
            app.YPositionSlider.ValueChangingFcn = createCallbackFcn(app, @YPositionSliderValueChanging, true);
            app.YPositionSlider.Enable = 'off';
            app.ColormapOptionsDropDownLabel = uilabel(app.UIFigure);
            app.ColormapOptionsDropDownLabel.Enable = 'off';
            app.ColormapOptionsDropDownLabel.Text = 'Colormap Options';
            app.ColormapOptionsDropDown = uidropdown(app.UIFigure);
            app.ColormapOptionsDropDown.Items = {'Parula', 'Turbo', 'HSV', 'Cool', 'Hot', 'Jet'};
            app.ColormapOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapOptionsDropDownValueChanged, true);
            app.ColormapOptionsDropDown.Enable = 'off';
            app.ColormapOptionsDropDown.Value = 'Parula';
            app.FOVColorDropDownLabel = uilabel(app.UIFigure);
            app.FOVColorDropDownLabel.Enable = 'off';
            app.FOVColorDropDownLabel.Text = 'FOV Color';
            app.FOVColorDropDown = uidropdown(app.UIFigure);
            app.FOVColorDropDown.Items = {'Blue', 'Red', 'Green', 'Yellow', 'Orange', 'White', 'Black'};
            app.FOVColorDropDown.ValueChangedFcn = createCallbackFcn(app, @FOVColorDropDownValueChanged, true);
            app.FOVColorDropDown.Enable = 'off';
            app.FOVColorDropDown.Value = 'Blue';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.FOVSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.FOVSizeEditField.ValueChangedFcn = createCallbackFcn(app, @FOVSizeEditFieldValueChanged, true);
            app.FOVSizeEditField.Enable = 'off';
            app.XPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.XPositionEditField.ValueChangedFcn = createCallbackFcn(app, @XPositionEditFieldValueChanged, true);
            app.XPositionEditField.Enable = 'off';
            app.YPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.YPositionEditField.ValueChangedFcn = createCallbackFcn(app, @YPositionEditFieldValueChanged, true);
            app.YPositionEditField.Enable = 'off';
            app.HistogramDisplaySwitchLabel = uilabel(app.UIFigure);
            app.HistogramDisplaySwitchLabel.Enable = 'off';
            app.HistogramDisplaySwitchLabel.Text = 'Histogram Display';
            app.HistogramDisplaySwitch = uiswitch(app.UIFigure, 'slider');
            app.HistogramDisplaySwitch.Items = {'Ch1', 'Ch2'};
            app.HistogramDisplaySwitch.ValueChangedFcn = createCallbackFcn(app, @HistogramDisplaySwitchValueChanged, true);
            app.HistogramDisplaySwitch.Enable = 'off';
            app.HistogramDisplaySwitch.Value = 'Ch1';
            app.ofBinsSpinnerLabel = uilabel(app.UIFigure);
            app.ofBinsSpinnerLabel.Enable = 'off';
            app.ofBinsSpinnerLabel.Text = '# of Bins';
            app.ofBinsSpinner = uispinner(app.UIFigure);
            app.ofBinsSpinner.ValueChangingFcn = createCallbackFcn(app, @ofBinsSpinnerValueChanging, true);
            app.ofBinsSpinner.Limits = [1 Inf];
            app.ofBinsSpinner.ValueChangedFcn = createCallbackFcn(app, @ofBinsSpinnerValueChanged, true);
            app.ofBinsSpinner.Enable = 'off';
            app.ofBinsSpinner.Value = 15;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FilePreviewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end