classdef ICS_Explorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ICSExplorerUIFigure    matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        NavigatorPanel         matlab.ui.container.Panel
        NavSwitch              matlab.ui.control.Switch
        InfoButton             matlab.ui.control.Button
        YindexSpinner          matlab.ui.control.Spinner
        YindexSpinnerLabel     matlab.ui.control.Label
        XindexSpinner          matlab.ui.control.Spinner
        XindexSpinnerLabel     matlab.ui.control.Label
        FrameSpinner           matlab.ui.control.Spinner
        FrameSpinnerLabel      matlab.ui.control.Label
        ZoomSpinner            matlab.ui.control.Spinner
        ZoomSpinnerLabel       matlab.ui.control.Label
        PlayNav                matlab.ui.control.Button
        UpdateButton           matlab.ui.control.Button
        Zoom                   matlab.ui.control.UIAxes
        Preview                matlab.ui.control.UIAxes
        SelectPanel            matlab.ui.container.Panel
        LiveSTICSButton        matlab.ui.control.StateButton
        VectormapButton        matlab.ui.control.StateButton
        BackgroundButton       matlab.ui.control.Button
        Label_4                matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        Ch1Label               matlab.ui.control.Label
        CheckBox_4             matlab.ui.control.CheckBox
        CheckBox_3             matlab.ui.control.CheckBox
        CheckBox_2             matlab.ui.control.CheckBox
        CheckBox               matlab.ui.control.CheckBox
        VecMapLamp             matlab.ui.control.Lamp
        StackLamp              matlab.ui.control.Lamp
        StackButton            matlab.ui.control.Button
        UIndexSlider           matlab.ui.control.Slider
        UIndexSliderLabel      matlab.ui.control.Label
        YIndexSlider           matlab.ui.control.Slider
        YIndexSliderLabel      matlab.ui.control.Label
        XIndexSlider           matlab.ui.control.Slider
        XIndexSliderLabel      matlab.ui.control.Label
        FrameSlider            matlab.ui.control.Slider
        FrameSliderLabel       matlab.ui.control.Label
        CenterPanel            matlab.ui.container.Panel
        Button                 matlab.ui.control.Button
        FitmodeDropDown        matlab.ui.control.DropDown
        FitmodeDropDownLabel   matlab.ui.control.Label
        Show3DButtonGroup      matlab.ui.container.ButtonGroup
        asDropDown_2Label      matlab.ui.control.Label
        asDropDown_2           matlab.ui.control.DropDown
        asDropDown             matlab.ui.control.DropDown
        asDropDownLabel        matlab.ui.control.Label
        ResidualsButton        matlab.ui.control.ToggleButton
        ViewPanelsButton       matlab.ui.control.ToggleButton
        ContourButton          matlab.ui.control.ToggleButton
        FitButton              matlab.ui.control.ToggleButton
        CorrelationButton      matlab.ui.control.ToggleButton
        Play3D                 matlab.ui.control.Button
        Plot3DButton           matlab.ui.control.Button
        FitTable               matlab.ui.control.Table
        TauSpinner             matlab.ui.control.Spinner
        TauSpinnerLabel        matlab.ui.control.Label
        ICF3D                  matlab.ui.control.UIAxes
        RightPanel             matlab.ui.container.Panel
        VelTable               matlab.ui.control.Table
        Button_2               matlab.ui.control.Button
        AxisViewDropDown       matlab.ui.control.DropDown
        Show2D                 matlab.ui.control.DropDown
        ShowDropDownLabel      matlab.ui.control.Label
        AxisViewDropDownLabel  matlab.ui.control.Label
        Plot2DButton           matlab.ui.control.Button
        ICF2D                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        Opt
        PosX
        PosY
        PosT
        Stack
        VelMap
        VelMapFileName
        VelMapPath % Path to the velocity map
        CallingApp;
        k % Description
    end
    
    properties (Access = private)
        CurrentFrame
        CurrentPosX
        vix
        CurrentPosY
        viy
        MinFrame % Frame number of data that corresponds to the first vector field frame
        MaxFrame % Frame number of data that corresponds to the last vector field frame
        StackFileName
        StackPath
        ROIsquare % Description
        coordinatesNeedUpdate % Description
        currentROIcoors % Description
        tau % Description
        fracROIshift % Description
        fracTOIshift
        TOIFOV % Description
        corrfn
        meshX % Description
        meshY
        Z % Description
        minK
        coeffGfit
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, STICSgui1, stack, velocityMap, positionX, positionY, positionT, opt)
            % app.CallingApp = STICSgui1;
            
            % Initial setup
            app.Preview.Visible = 'off';
            app.Preview.XTick = [];
            app.Preview.YTick = [];
            axis(app.Preview,'image');
            axis(app.Preview,'tight');
            
            app.Zoom.Visible = 'off';
            app.Zoom.XTick = [];
            app.Zoom.YTick = [];
            axis(app.Zoom, 'image');
            axis(app.Zoom, 'tight');

            app.ICF2D.Visible = 'off';            
            set(app.ICF2D,'XColor',[0.9 0.9 0.9],'YColor',[0.9 0.9 0.9]);
            set(app.ICF2D,'Color','none','FontWeight','bold');
            app.ICF2D.Title.Color = [0.00,0.55,0.87];
            app.ICF2D.FontWeight = 'bold';
            app.ICF2D.Title.FontSize = 24;
            
            app.ICF3D.Visible = 'off';
            xlabel(app.ICF3D,'\it\eta (\mum)');
            ylabel(app.ICF3D,'\it\xi (\mum)');
            zlabel(app.ICF3D,'\itG(\tau)');            
            set(app.ICF3D,'XColor',[0.9 0.9 0.9],'YColor',[0.9 0.9 0.9],'ZColor',[0.9 0.9 0.9]);
            set(app.ICF3D,'Color','none','FontWeight','bold');
            app.ICF3D.Title.Color = [0.00,0.55,0.87];
            app.ICF3D.FontWeight = 'bold';
            app.ICF3D.Title.FontSize = 24;
            
            app.InfoButton.Enable = 0;
            app.PlayNav.Enable = 0;
            app.UpdateButton.Enable = 0;
            
            
            % Load stack if passed in from motherapp
            app.Stack = stack;
            if (isempty(app.Stack))
                app.StackLamp.Color = 'r';
            else
                app.StackLamp.Color = 'g';
                app.Preview.Visible = 'on';
                imshow(app.Stack(:,:,app.FrameSpinner.Value), [100, 1000], 'Parent', app.Preview);
            end
            
            % Load vectorMap if passed in from motherapp & stack not empty
            if (~isempty(velocityMap) && ~isempty(positionX) && ~isempty(positionY) &&...
                ~isempty(positionT) && ~isempty(opt) && ~isempty(stack))
                app.LiveSTICSButton.Value = 0;
                app.VectormapButton.Value = 1;
            
                app.VelMap = velocityMap;
                app.PosX = positionX;
                app.PosY = positionY;
                app.PosT = positionT;
                app.Opt = opt;
                
                app.fracROIshift = app.Opt.ROIsize/app.Opt.ROIshift;
                app.fracTOIshift = app.Opt.TOIsize/app.Opt.TOIshift;
            
                % Set Frame range
                app.MinFrame = ceil(app.Opt.TOIsize/2);
                app.MaxFrame = size(app.Stack, 3) - floor(app.Opt.TOIsize/2);            
                app.FrameSpinner.Limits = [app.MinFrame, app.MaxFrame];
                app.FrameSpinner.Value = app.MinFrame;
                app.CurrentFrame = app.FrameSpinner.Value;
                if mod(app.Opt.TOIsize, 2) ==  0 % TOI size is even
                    app.minK = 1;
                else
                    app.minK = 0;
                end
                
                app.k = app.CurrentFrame - floor(app.Opt.TOIsize/2) + app.minK; % Vector field frame
                % Warning: only works for odd TOI sizes!
    
                % Set vector field index range
                app.XindexSpinner.Limits = [1, size(app.PosX, 1)];
                app.XindexSpinner.Value = 1;
                app.YindexSpinner.Limits = [1, size(app.PosY, 2)];
                app.YindexSpinner.Value = 1;
                app.UIndexSlider.Limits = [1, size(app.PosX, 1)*size(app.PosY, 2)];
                
                % Set Tau range
                app.TauSpinner.Limits = [0, app.Opt.TOIsize-1]; % May change later
                app.tau = app.TauSpinner.Limits(1);
                
                % Get current position
                app.vix = app.XindexSpinner.Value;
                app.CurrentPosX = app.PosX(app.vix, 1);
                app.viy = app.YindexSpinner.Value;
                app.CurrentPosY = app.PosY(1, app.viy);
                
                % Draw rectangles
                imshow(app.Stack(:,:,app.FrameSpinner.Value), [100, 1000], 'Parent', app.Preview);            
                rectangle(app.Preview, 'Position', [(1+app.Opt.ROIsize)/2, (1+app.Opt.ROIsize)/2,...
                    size(app.Stack,1)-app.Opt.ROIsize, size(app.Stack,1)-app.Opt.ROIsize],...
                    'FaceColor','none', 'LineStyle','--');
                rectangle(app.Preview,'Position',...
                    [app.CurrentPosX-app.Opt.ROIsize/2,...
                    app.CurrentPosY-app.Opt.ROIsize/2,...
                    app.Opt.ROIsize, app.Opt.ROIsize]);
                app.ROIsquare = drawrectangle(app.Preview, "Position",...
                    [app.CurrentPosX-app.Opt.ROIsize/2,...
                    app.CurrentPosY-app.Opt.ROIsize/2,...
                    app.Opt.ROIsize, app.Opt.ROIsize],...
                    "AspectRatio",1,"FixedAspectRatio",true,"InteractionsAllowed","translate");
                app.currentROIcoors = app.ROIsquare.Position;
                hold(app.Preview, 'on');
        
                maskedPosX = app.PosX.* app.Opt.vectorPositions;
                maskedPosX(maskedPosX == 0) = NaN;
        
                maskedPosY = app.PosY.* app.Opt.vectorPositions;
                maskedPosY(maskedPosY == 0) = NaN;
        
                plot(app.Preview, maskedPosY, maskedPosX,'.r', 'MarkerSize',1); 
                plot(app.Preview, app.CurrentPosY, app.CurrentPosX,'+y', 'LineWidth', 1);           
                hold(app.Preview, 'off');
     
                
                app.Zoom.Visible = 'on';
                imshow(app.Stack(app.CurrentPosX - app.Opt.ROIsize/2:app.CurrentPosX + app.Opt.ROIsize/2,...
                    app.CurrentPosY - app.Opt.ROIsize/2:app.CurrentPosY + app.Opt.ROIsize/2,...
                    app.CurrentFrame), [100, 1000], 'Parent', app.Zoom);
                disableDefaultInteractivity(app.Zoom);
                
                app.UIndexSlider.Enable = 'On';
                app.UIndexSliderLabel.Enable = 'On';
                app.YIndexSlider.Enable = 'On';
                app.YIndexSliderLabel.Enable = 'On';
                app.XIndexSlider.Enable = 'On';
                app.XIndexSliderLabel.Enable = 'On';
                app.FrameSlider.Enable = 'On';
                app.FrameSliderLabel.Enable = 'On';
    
                app.coordinatesNeedUpdate = 0;
                
                app.InfoButton.Enable = 1;
                app.PlayNav.Enable = 1;
                app.UpdateButton.Enable = 1;
                
                app.VecMapLamp.Color = 'g';
            end


        end

        % Button pushed function: StackButton
        function StackButtonPushed(app, event)
            app.StackLamp.Color = [0.93,0.69,0.13];
            [app.StackFileName, app.StackPath] = uigetfile("*.tif");
            app.Stack = readFileToStack([app.StackPath, app.StackFileName]);
            if isempty(app.Stack)
                app.StackLamp.Color = 'r';
            else
                app.StackLamp.Color = 'g';
                app.Preview.Visible = 'on';
                imshow(app.Stack(:,:,app.FrameSpinner.Value), [100, 1000], 'Parent', app.Preview);
%                 ax = axes(app.Preview); % Trying to set axis tight to
%                 make the image bigger
%                 ax.
            end
%             disableDefaultInteractivity(app.Preview);
            % Make the app to be brought back to the front!
            
        end

        % Value changed function: VectormapButton
        function VectormapButtonPushed(app, event)
            app.LiveSTICSButton.Value = 0;
            app.VectormapButton.Value = 1;
            app.VecMapLamp.Color = [0.93,0.69,0.13];
            
            % Select and load data
            [app.VelMapFileName, app.VelMapPath] = uigetfile('*.mat', 'Select the Velocity Map');
            load([app.VelMapPath, app.VelMapFileName], '-mat');
            app.VelMap = velocityMap;
            app.PosX = position_x;
            app.PosY = position_y;
            app.PosT = position_t;
            app.Opt = opt;
            app.fracROIshift = app.Opt.ROIsize/app.Opt.ROIshift;
            app.fracTOIshift = app.Opt.TOIsize/app.Opt.TOIshift;
            
            % Check if cancel was clicked            
            if isempty(app.VelMap)
                app.StackLamp.Color = 'r';
                return;
            else
                app.StackLamp.Color = 'g';
            end
            
            % Set Frame range
            app.MinFrame = ceil(app.Opt.TOIsize/2);
            app.MaxFrame = size(app.Stack, 3) - floor(app.Opt.TOIsize/2);            
            app.FrameSpinner.Limits = [app.MinFrame, app.MaxFrame];
            app.FrameSpinner.Value = app.MinFrame;
            app.CurrentFrame = app.FrameSpinner.Value;
            if mod(app.Opt.TOIsize, 2) ==  0 % TOI size is even
                app.minK = 1;
            else
                app.minK = 0;
            end
            app.k = app.CurrentFrame - floor(app.Opt.TOIsize/2) + app.minK; % Vector field frame
            % Warning: only works for odd TOI sizes!

            % Set vector field index range
            app.XindexSpinner.Limits = [1, size(app.PosX, 1)];
            app.XindexSpinner.Value = 1;
            app.YindexSpinner.Limits = [1, size(app.PosY, 2)];
            app.YindexSpinner.Value = 1;
            app.UIndexSlider.Limits = [1, size(app.PosX, 1)*size(app.PosY, 2)];
            
            % Set Tau range
            app.TauSpinner.Limits = [0, app.Opt.TOIsize-1]; % May change later
            app.tau = app.TauSpinner.Limits(1);
            
            % Get current position
            app.vix = app.XindexSpinner.Value;
            app.CurrentPosX = app.PosX(app.vix, 1);
            app.viy = app.YindexSpinner.Value;
            app.CurrentPosY = app.PosY(1, app.viy);
            
            % Draw rectangles
            imshow(app.Stack(:,:,app.FrameSpinner.Value), [100, 1000], 'Parent', app.Preview);            
            rectangle(app.Preview, 'Position', [(1+app.Opt.ROIsize)/2, (1+app.Opt.ROIsize)/2,...
                size(app.Stack,1)-app.Opt.ROIsize, size(app.Stack,1)-app.Opt.ROIsize],...
                'FaceColor','none', 'LineStyle','--');
            rectangle(app.Preview,'Position',...
                [app.CurrentPosX-app.Opt.ROIsize/2,...
                app.CurrentPosY-app.Opt.ROIsize/2,...
                app.Opt.ROIsize, app.Opt.ROIsize]);
            app.ROIsquare = drawrectangle(app.Preview, "Position",...
                [app.CurrentPosX-app.Opt.ROIsize/2,...
                app.CurrentPosY-app.Opt.ROIsize/2,...
                app.Opt.ROIsize, app.Opt.ROIsize],...
                "AspectRatio",1,"FixedAspectRatio",true,"InteractionsAllowed","translate");
            app.currentROIcoors = app.ROIsquare.Position;
            hold(app.Preview, 'on');
    
            maskedPosX = app.PosX.* app.Opt.vectorPositions;
            maskedPosX(maskedPosX == 0) = NaN;
    
            maskedPosY = app.PosY.* app.Opt.vectorPositions;
            maskedPosY(maskedPosY == 0) = NaN;
    
            plot(app.Preview, maskedPosY, maskedPosX,'.r', 'MarkerSize',1); 
            plot(app.Preview, app.CurrentPosY, app.CurrentPosX,'+y', 'LineWidth', 1);           
            hold(app.Preview, 'off');
 
            
            app.Zoom.Visible = 'on';
            imshow(app.Stack(app.CurrentPosX - app.Opt.ROIsize/2:app.CurrentPosX + app.Opt.ROIsize/2,...
                app.CurrentPosY - app.Opt.ROIsize/2:app.CurrentPosY + app.Opt.ROIsize/2,...
                app.CurrentFrame), [100, 1000], 'Parent', app.Zoom);
            disableDefaultInteractivity(app.Zoom);
            
            app.UIndexSlider.Enable = 'On';
            app.UIndexSliderLabel.Enable = 'On';
            app.YIndexSlider.Enable = 'On';
            app.YIndexSliderLabel.Enable = 'On';
            app.XIndexSlider.Enable = 'On';
            app.XIndexSliderLabel.Enable = 'On';
            app.FrameSlider.Enable = 'On';
            app.FrameSliderLabel.Enable = 'On';

            app.coordinatesNeedUpdate = 0;
            
            app.InfoButton.Enable = 1;
            app.PlayNav.Enable = 1;
            app.UpdateButton.Enable = 1;
            
            app.VecMapLamp.Color = 'g';
        end

        % Value changed function: LiveSTICSButton
        function LiveSTICSButtonValueChanged(app, event)
            value = app.LiveSTICSButton.Value;
            switch value
                case 1
                    app.VectormapButton.Value = 0;
                    app.UpdateButton.Enable = 1;
                    app.InfoButton.Enable = 1;
                    app.PlayNav.Enable = 1;
                    % Launch Vector Viewer to get parameters from user or
                    % get them some other way
                case 0
                    app.VectormapButton.Enable = 1;
            end
        end

        % Button down function: CenterPanel
        function CenterPanelButtonDown(app, event)
            
        end

        % Button pushed function: UpdateButton
        function UpdateNavigator(app, event)
            roi = app.ROIsquare;
            oldROIpos = app.currentROIcoors;
            newROIpos = dragrect(roi.Position);


            if newROIpos(1) ~= oldROIpos(1) || newROIpos(2) ~= oldROIpos(2) % ROI was dragged
                userCurrX = (newROIpos(1) + (newROIpos(3)/2));
                [~, app.vix] = min(abs(userCurrX - app.PosX(:,1)));
                snapCurrX = app.PosX(app.vix,1);
    %             complicated way:
    %             position_x(position_x(:,1) == userCurrX + min(abs(userCurrX - position_x(:,1))))
                app.XindexSpinner.Value = app.vix;
                
                
                userCurrY = (newROIpos(2) + (newROIpos(4)/2));
                [~, app.viy] = min(abs(userCurrY - app.PosY(1,:)));
                snapCurrY = app.PosY(1,app.viy);
    %             complicated way:
    %             position_x(position_x(:,1) == userCurrX + min(abs(userCurrX - position_x(:,1))))
                app.YindexSpinner.Value = app.viy;
                
                finalROIpos = [snapCurrX - (app.Opt.ROIsize/2),...
                   snapCurrY - (app.Opt.ROIsize/2),...
                   app.Opt.ROIsize, app.Opt.ROIsize];
                app.CurrentPosX = snapCurrX;
                app.CurrentPosY = snapCurrY;
            elseif app.coordinatesNeedUpdate
                app.CurrentFrame = app.FrameSpinner.Value;
                if mod(app.Opt.TOIsize, 2) ==  0 % TOI size is even
                    app.minK = 1;
                else
                    app.minK = 0;
                end
                app.k = app.CurrentFrame - floor(app.Opt.TOIsize/2) + app.minK; % Vector field frame
                
                app.vix = app.XindexSpinner.Value;
                xpos = app.PosX(app.vix,1);
                app.CurrentPosX = xpos;
                
                app.viy = app.YindexSpinner.Value;
                ypos = app.PosY(1,app.viy);
                app.CurrentPosY = ypos;
                
                finalROIpos = [xpos - (app.Opt.ROIsize/2),...
                   ypos - (app.Opt.ROIsize/2),...
                   app.Opt.ROIsize, app.Opt.ROIsize];                
            else
                finalROIpos = newROIpos;
            end
            
            % Refresh the background image and draw dashed rectangle
            imshow(app.Stack(:,:,app.CurrentFrame), [100, 1000], 'Parent', app.Preview);
            rectangle(app.Preview, 'Position',...
                [(1+app.Opt.ROIsize)/2, (1+app.Opt.ROIsize)/2,...
                size(app.Stack,1)-app.Opt.ROIsize,...
                size(app.Stack,1)-app.Opt.ROIsize],...
                'FaceColor','none', 'LineStyle','--');
            
            app.TOIFOV = app.Stack( ...
                floor(1+(app.viy-1)/app.fracROIshift*app.Opt.ROIsize):floor(app.Opt.ROIsize*(1+((app.viy-1)/app.fracROIshift))),...
                floor(1+(app.vix-1)/app.fracROIshift*app.Opt.ROIsize):floor(app.Opt.ROIsize*(1+((app.vix-1)/app.fracROIshift))),...
                floor(1+(app.k-1)/app.fracTOIshift*app.Opt.TOIsize):floor(app.Opt.TOIsize*(1+((app.k-1)/app.fracTOIshift))));
            
            
            tf = [floor(1+(app.viy-1)/app.fracROIshift*app.Opt.ROIsize),floor(app.Opt.ROIsize*(1+((app.viy-1)/app.fracROIshift)));
                floor(1+(app.vix-1)/app.fracROIshift*app.Opt.ROIsize),floor(app.Opt.ROIsize*(1+((app.vix-1)/app.fracROIshift)));
                floor(1+(app.k-1)/app.fracTOIshift*app.Opt.TOIsize),floor(app.Opt.TOIsize*(1+((app.k-1)/app.fracTOIshift)))];
            
            rectangle(app.Preview,'Position', finalROIpos); 
            app.ROIsquare = drawrectangle(app.Preview,...
                "Position", finalROIpos, "AspectRatio", 1,...
                "FixedAspectRatio",true,"InteractionsAllowed","translate");
            app.currentROIcoors = app.ROIsquare.Position;
            
            hold(app.Preview, 'on');
            maskedPosX = app.PosX.* app.Opt.vectorPositions;
            maskedPosX(maskedPosX == 0) = NaN;
            maskedPosY = app.PosY.* app.Opt.vectorPositions;
            maskedPosY(maskedPosY == 0) = NaN;
            plot(app.Preview, maskedPosY, maskedPosX,'.r', 'MarkerSize',1); 
            plot(app.Preview, app.CurrentPosX, app.CurrentPosY,'+y', 'LineWidth', 1);           
            hold(app.Preview, 'off');               
            
            %%% Zoom plot
            imshow(app.Stack(1+floor(finalROIpos(2)):1+floor(finalROIpos(2))+app.Opt.ROIsize,...
                1+floor(finalROIpos(1)):1+floor(finalROIpos(1))+app.Opt.ROIsize,...
                app.CurrentFrame), [100, 1000], 'Parent', app.Zoom);


        end

        % Value changing function: UIndexSlider
        function UIndexSliderValueChanging(app, event)
            changingValue = ceil(event.Value);
            
            y_val = double(idivide(uint16(changingValue), size(app.PosY,2)) + 1)
            x_val = double(mod(changingValue, size(app.PosY,2)))
            
            %mod(changingValue,app.YIndexSlider.Value)
            
            if (y_val > app.YIndexSlider.Limits(2))
                return;
            end
            
            app.YIndexSlider.Value = y_val;
            app.XIndexSlider.Value = x_val;
            
            %app.YIndexSlider.Value = idivide(int16(changingValue),int16(size(app.PosY,2))) + 1;
            %app.XIndexSlider.Value = mod(changingValue,app.YIndexSlider.Value);
        end

        % Value changing function: XIndexSlider
        function XIndexSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UIndexSlider.Value = size(app.PosX,1)*app.YIndexSlider.Value + changingValue;
        end

        % Value changing function: YIndexSlider
        function YIndexSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UIndexSlider.Value = size(app.PosX,1)*changingValue + app.XIndexSlider.Value;
        end

        % Value changed function: XIndexSlider
        function XIndexSliderValueChanged(app, event)
            value = app.XIndexSlider.Value;
            %app.CurrentPosX = app.PosX(value, 1);
            app.CurrentPosX = value;
        end

        % Value changed function: YIndexSlider
        function YIndexSliderValueChanged(app, event)
            value = app.YIndexSlider.Value;
            %app.CurrentPosY = app.PosY(1, value);
            app.CurrentPosY = value;
        end

        % Value changed function: FrameSpinner
        function FrameValueChanged(app, event)
            value = app.FrameSpinner.Value;
            app.CurrentFrame = value;
            app.coordinatesNeedUpdate = 1;
            UpdateNavigator(app,event);
        end

        % Close request function: ICSExplorerUIFigure
        function ICSExplorerUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Value changed function: XindexSpinner, YindexSpinner
        function coordinatesChanged(app, event)
            app.coordinatesNeedUpdate = 1;
                        
            % Get current positions
%             oldROIposX = app.CurrentPosX;
            app.vix = app.XindexSpinner.Value;
            app.CurrentPosX = app.PosX(app.vix, 1);
            app.viy = app.YindexSpinner.Value;
            app.CurrentPosY = app.PosY(1, app.viy);
            UpdateNavigator(app,event);
        end

        % Value changed function: FitmodeDropDown, TauSpinner
        function TauValueChanged(app, event)
            value = app.TauSpinner.Value;
            app.tau = value;
            app.coordinatesNeedUpdate = 1;
            Plot3DButtonPushed(app,event)
            Plot2DButtonPushed(app,event)
        end

        % Button pushed function: Plot3DButton
        function Plot3DButtonPushed(app, event)
            %%% 3D & 2D plots
            app.ICF3D.Visible = 'on';
            
            [app.corrfn] = stics(app.TOIFOV, app.Opt.tauLimit);
            caxis(app.ICF3D,[min(app.corrfn,[],'all'), max(app.corrfn,[],'all')]);               
            if app.LiveSTICSButton.Value          
                app.FitmodeDropDown.Enable = 1;
                switch app.FitmodeDropDown.Value
                    case 'Symmetrical Gaussian'
                        % Isotropic
                        fitMode = 'time';
                    case 'Asymmetrical Gaussian'
                        % Asymmetric
                        fitMode = 'timeasym';
                    case 'Rotated Elliptical Gaussian'
                        % Rotated
                        fitMode = 'rotated';                
                end
            elseif app.VectormapButton.Value
                fitMode = 'time'; % Or fetch the fitMode from Opt
                app.FitmodeDropDown.Enable =0;
            end
            
            [app.coeffGfit] = gaussfit(app.corrfn,fitMode,...
                app.Opt.pixelSize,'n',app.Opt.fitRadius);
            
            app.TauSpinner.Limits = [0, min(app.Opt.tauLimit, size(app.coeffGfit,1))-1];
            
            G = app.coeffGfit(app.tau + 1,:);
            app.FitTable.Data = app.coeffGfit;

            [app.meshX,app.meshY] = meshgrid(-((size(app.corrfn,2)-1)/2)*app.Opt.pixelSize:app.Opt.pixelSize:((size(app.corrfn,2)-1)/2)*app.Opt.pixelSize,...
                -((size(app.corrfn,1)-1)/2)*app.Opt.pixelSize:app.Opt.pixelSize:((size(app.corrfn,1)-1)/2)*app.Opt.pixelSize);
            % High Resolution Grid
            h=3; % Resolution factor
            [xh,yh] = meshgrid(-app.Opt.ROIsize/2:app.Opt.pixelSize/h:app.Opt.ROIsize/2,...
                -app.Opt.ROIsize/2:1/h:app.Opt.ROIsize/2);
            
            
            switch fitMode
                case "time"
                    A = G(1);
                    Sx = G(2);
                    Sy = G(3);
                    Offset = G(4);
                    X_0 = G(5);
                    Y_0 = G(6);
                    app.Z = A.*exp(-(((app.meshX-X_0).^2)/(2*Sx.^2) + ((app.meshY-Y_0).^2)/(2*Sy.^2))) + Offset;
                    
            %         app.coeffGfit(:,7) = zeros(size(app.coeffGfit,1),1);
                    G(7) = 0;
                case "timeasym"
                    A = G(1);
                    Sx = G(2);
                    Sy = G(3);
                    Offset = G(4);
                    X_0 = G(5);
                    Y_0 = G(6);
                    app.Z = A.*exp(-(((app.meshX-X_0).^2)/(2*Sx.^2) + ((app.meshY-Y_0).^2)/(2*Sy.^2))) + Offset;
                    
            %         app.coeffGfit(:,7) = zeros(size(app.coeffGfit,1),1);
                    G(7) = 0;
                case "rotated"
                    A = G(1);
                    Sx = G(2);
                    Sy = G(3);
                    X_0 = G(5);
                    Y_0 = G(6);        
                    app.Z = A*exp( -(...
                        ( app.meshX*cos(G(7))-app.meshY*sin(G(7)) - X_0*cos(G(7)) + Y_0*sin(G(7)) ).^2/(2*Sx^2) + ... 
                        ( app.meshX*sin(G(7))+app.meshY*cos(G(7)) - X_0*sin(G(7)) - Y_0*cos(G(7)) ).^2/(2*Sy^2) ) );
            end
            
            xPanPos = 0; yPanPos = 0; zPanPos = 0;
           
            s = surf(app.ICF3D, app.meshX, app.meshY, app.corrfn(:,:,app.tau+1)); axis tight
            s.EdgeColor = 'none';
            s.FaceAlpha = 0.75;
            shading interp
            hold(app.ICF3D, 'on');
            
            m = mesh(app.ICF3D, app.meshX, app.meshY, app.Z);
            m.EdgeColor = [0 0 0];
            m.EdgeAlpha = 0.5;
            m.FaceColor = [1,1,1];
            m.FaceAlpha = 0.25;
            
            c = contour(app.ICF3D, app.meshX, app.meshY, app.corrfn(:,:,app.tau+1));

            xPan = fill3(app.ICF3D,[xPanPos;xPanPos;xPanPos;xPanPos],...
                        [app.meshY(1,1);app.meshY(1,1);app.meshY(end,1);app.meshY(end,1)],...
                        [min(app.corrfn(:,:,1),[],'all');max(app.corrfn(:,:,1),[],'all');max(app.corrfn(:,:,1),[],'all');min(app.corrfn(:,:,1),[],'all')],'g');
            xPan.FaceAlpha = 0.15;
            xPan.EdgeColor = 'g'; 
            xPan.LineWidth = 2;
            
            yPan = fill3(app.ICF3D,[app.meshX(1,1);app.meshX(1,1);app.meshX(1,end);app.meshX(1,end)],...
                        [yPanPos;yPanPos;yPanPos;yPanPos],...
                        [min(app.corrfn(:,:,1),[],'all');max(app.corrfn(:,:,1),[],'all');max(app.corrfn(:,:,1),[],'all');min(app.corrfn(:,:,1),[],'all')],'r');
            yPan.FaceAlpha = 0.15;
            yPan.EdgeColor = 'r'; 
            yPan.LineWidth = 2;
            
            zPan = fill3(app.ICF3D,[app.meshX(1,1);app.meshX(1,1);app.meshX(1,end);app.meshX(1,end)],...
                        [app.meshY(1,1);app.meshY(end,1);app.meshY(end,1);app.meshY(1,1)],...
                        [zPanPos;zPanPos;zPanPos;zPanPos],'m');
            zPan.FaceAlpha = 0;
            zPan.EdgeColor = 'm'; 
            zPan.LineWidth = 2;
            
            hold(app.ICF3D, 'off');                        
            
        end

        % Button pushed function: PlayNav
        function PlayNavPushed(app, event)
            app.PlayNav.Enable = 'off';
            switch app.NavSwitch.Value
                case 'TOI'
                    for frame = app.CurrentFrame-floor(app.Opt.TOIsize/2)+app.minK:app.CurrentFrame+floor(app.Opt.TOIsize/2)+app.minK
                        imshow(app.Stack(:,:,frame+app.minK), [100, 1000], 'Parent', app.Preview);
                        imshow(app.Stack(1+floor(app.currentROIcoors(2)):1+floor(app.currentROIcoors(2))+app.Opt.ROIsize,...
                            1+floor(app.currentROIcoors(1)):1+floor(app.currentROIcoors(1))+app.Opt.ROIsize,...
                            frame), [100, 1000], 'Parent', app.Zoom);                                
                        drawnow
                    end                    
                case 'All'
                    for frame = app.MinFrame:app.MaxFrame
                        imshow(app.Stack(:,:,frame), [100, 1000], 'Parent', app.Preview);
                        imshow(app.Stack(1+floor(app.currentROIcoors(2)):1+floor(app.currentROIcoors(2))+app.Opt.ROIsize,...
                            1+floor(app.currentROIcoors(1)):1+floor(app.currentROIcoors(1))+app.Opt.ROIsize,...
                            frame), [100, 1000], 'Parent', app.Zoom);                                
                        drawnow
                    end                    
            end
            
            rectangle(app.Preview, 'Position', [(1+app.Opt.ROIsize)/2, (1+app.Opt.ROIsize)/2,...
                size(app.Stack,1)-app.Opt.ROIsize, size(app.Stack,1)-app.Opt.ROIsize],...
                'FaceColor','none', 'LineStyle','--');
            rectangle(app.Preview,'Position',...
                [app.CurrentPosX-app.Opt.ROIsize/2,...
                app.CurrentPosY-app.Opt.ROIsize/2,...
                app.Opt.ROIsize, app.Opt.ROIsize]);
            app.ROIsquare = drawrectangle(app.Preview, "Position",...
                [app.CurrentPosX-app.Opt.ROIsize/2,...
                app.CurrentPosY-app.Opt.ROIsize/2,...
                app.Opt.ROIsize, app.Opt.ROIsize],...
                "AspectRatio",1,"FixedAspectRatio",true,"InteractionsAllowed","translate");
            app.currentROIcoors = app.ROIsquare.Position;
            hold(app.Preview, 'on');
    
            maskedPosX = app.PosX.* app.Opt.vectorPositions;
            maskedPosX(maskedPosX == 0) = NaN;
    
            maskedPosY = app.PosY.* app.Opt.vectorPositions;
            maskedPosY(maskedPosY == 0) = NaN;
    
            plot(app.Preview, maskedPosY, maskedPosX,'.r', 'MarkerSize',1); 
            plot(app.Preview, app.CurrentPosX, app.CurrentPosY,'+y', 'LineWidth', 1);           
            hold(app.Preview, 'off');                                    
            
            app.PlayNav.Enable = 'on';
        end

        % Callback function: Plot2DButton, Show2D
        function Plot2DButtonPushed(app, event)
            app.ICF2D.Visible = 'on';
            app.ICF2D.Title.FontSize = 24;
            
%             app.ICF2D.Color = 'w';
            G = app.FitTable.Data;
            
            switch app.Show2D.Value
                case 'Correlation Function'
                    [app.meshX,app.meshY] = meshgrid(-((size(app.corrfn,2)-1)/2)*app.Opt.pixelSize:app.Opt.pixelSize:((size(app.corrfn,2)-1)/2)*app.Opt.pixelSize,...
                        -((size(app.corrfn,1)-1)/2)*app.Opt.pixelSize:app.Opt.pixelSize:((size(app.corrfn,1)-1)/2)*app.Opt.pixelSize);                    
                    imagesc(app.meshX(1,:), app.meshY(:,1), app.corrfn(:,:,app.tau + 1), 'Parent', app.ICF2D); 
                    hold(app.ICF2D, 'on');
                    plot(app.ICF2D,G(app.tau+1,5), G(app.tau+1,6), "+r","LineWidth",1.5);
                    xlabel(app.ICF2D,'\eta (\mum)');
                    ylabel(app.ICF2D,'\xi (\mum)');
                    caxis(app.ICF2D,[min(app.corrfn,[],'all'), max(app.corrfn,[],'all')]);
                    axis(app.ICF2D, 'image');
%                     axis(app.ICF2D,"ij");
%                     app.ICF2D.Box = 'on';
%                     b = app.ICF2D.BoxStyle;
%                     b.Color = 'm';
%                     b.LineWidth = 1;
                    hold(app.ICF2D, 'off');
                case 'Fit'
                    [app.meshX,app.meshY] = meshgrid(-((size(app.corrfn,2)-1)/2)*app.Opt.pixelSize:app.Opt.pixelSize:((size(app.corrfn,2)-1)/2)*app.Opt.pixelSize,...
                        -((size(app.corrfn,1)-1)/2)*app.Opt.pixelSize:app.Opt.pixelSize:((size(app.corrfn,1)-1)/2)*app.Opt.pixelSize);                    
                    imagesc(app.Z, 'Parent', app.ICF2D); 
                    hold(app.ICF2D, 'on');
                    plot(app.ICF2D,G(app.tau+1,5)./app.Opt.pixelSize+(app.Opt.ROIsize/2)+1, G(app.tau+1,6)./app.Opt.pixelSize+(app.Opt.ROIsize/2)+1, "+r","LineWidth",1.5);                    
%                     plot(app.ICF2D,G(5), G(6), "+r","LineWidth",1.5);
                    xlabel(app.ICF2D,'X (px)');
                    ylabel(app.ICF2D,'Y (px)');
                    axis(app.ICF2D, 'image');
%                     axis(app.ICF2D,"ij");                    
                    hold(app.ICF2D, 'off');                    
                case 'Residuals'
                case 'Cross-Sections'
                    if strcmpi(app.AxisViewDropDown.Value, 'Z axis (Top)')
                    elseif strcmpi(app.AxisViewDropDown.Value, 'XY axis (Side)')
                    elseif strcmpi(app.AxisViewDropDown.Value, 'Major-Minor axis')
                    end
                case 'Projections'
                case 'Trajectory XY'
                    cmap = parula(size(G,1));
                    for i=1:size(G,1)
                        plot(app.ICF2D, G(i,5), G(i,6), "o","LineWidth",1.5, "Color", cmap(i,:), "MarkerFaceColor",cmap(i,:));
                        hold(app.ICF2D, "on");
                    end
                    app.VelMap{app.k}.vx(app.vix, app.viy)
                    app.VelMap{app.k}.vy(app.vix, app.viy)
                    q = quiver(app.ICF2D, G(1,6), G(1,5), -(app.VelMap{app.k}.vx(app.vix, app.viy)./app.Opt.pixelSize), -(app.VelMap{app.k}.vy(app.vix, app.viy))./app.Opt.pixelSize);
                    q.LineWidth = 3;
%                     xlim(app.ICF2D, [-((size(app.corrfn,2)-1)/2)*app.Opt.pixelSize, ((size(app.corrfn,2)-1)/2)*app.Opt.pixelSize]);
%                     ylim(app.ICF2D, [-((size(app.corrfn,2)-1)/2)*app.Opt.pixelSize, ((size(app.corrfn,2)-1)/2)*app.Opt.pixelSize]);
                    axis(app.ICF2D,"square");
                    xlabel(app.ICF2D,'\eta (\mum)');
                    ylabel(app.ICF2D,'\xi (\mum)');
                case 'Displacement XT'
%                     plot(app.ICF2D,0:app.Opt.timeFrame:app.Opt.timeFrame*size(app.VelMap{app.k}.coeffGtime{app.vix,app.viyj},1)-1,...
%                         app.VelMap{app.k}.coeffGtime{app.vix,app.viy}(:,5), "o-"); title('Displacement in X');
%                     xlabel(app.ICF2D,'Time delay (s)'); ylabel(app.ICF2D,'Distance in X (um)'); axis(app.ICF2D,"square");
                case 'Displacement YT'
                case 'Data'
%                     imagesc(app.TOIFOV(:,:,ceil(app.Opt.TOIsize/2)), "Parent", app.ICF2D);
%                     xlabel(app.ICF2D,'X (pixels)');
%                     ylabel(app.ICF2D,'Y (pixels)');                      
%                     axis(app.ICF2D, 'image');
            end
            
            c = colorbar(app.ICF2D);
            c.Color = [0.9 0.9 0.9];
            
            if app.VectormapButton.Value
                TableData = zeros(1, 2);
    %             TableData(app.tau+1).Px = app.VelMap{app.k}.Px;
    %             TableData(app.tau+1).Py = app.VelMap{app.k}.Py;
                TableData(1, 1) = app.VelMap{app.k}.vx(app.vix, app.viy);
                TableData(1, 2) = app.VelMap{app.k}.vy(app.vix, app.viy);
                app.VelTable.Data = TableData;
            end

        end

        % Button pushed function: Play3D
        function Play3DButtonPushed(app, event)
            app.Play3D.Enable = 'off';
            
            for t = 1:app.Opt.TOIsize
                app.tau = t-1;
                app.TauSpinner.Value = app.tau;
                TauValueChanged(app,event);
                drawnow;    
            end
            app.tau = 0;
            app.TauSpinner.Value = app.tau;
            TauValueChanged(app,event);
            drawnow;

            app.Play3D.Enable = 'on';            
        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'On'
                    app.VectormapButton.Enable = 1;
                case 'Off'
                    app.VectormapButton.Enable = 0;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ICSExplorerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {705, 705, 705};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {705, 705};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {264, '1x', 552};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ICSExplorerUIFigure = uifigure('Visible', 'off');
            app.ICSExplorerUIFigure.Name = 'ICS Explorer';
            app.ICSExplorerUIFigure.CloseRequestFcn = createCallbackFcn(app, @ICSExplorerUIFigureCloseRequest, true);
            app.ICSExplorerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ICSExplorerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FrameSliderLabel = uilabel(app.LeftPanel);
            app.FrameSliderLabel.Enable = 'off';
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.LeftPanel);
            app.FrameSlider.Enable = 'off';
            app.XIndexSliderLabel = uilabel(app.LeftPanel);
            app.XIndexSliderLabel.Enable = 'off';
            app.XIndexSliderLabel.Text = 'X Index';
            app.XIndexSlider = uislider(app.LeftPanel);
            app.XIndexSlider.ValueChangedFcn = createCallbackFcn(app, @XIndexSliderValueChanged, true);
            app.XIndexSlider.ValueChangingFcn = createCallbackFcn(app, @XIndexSliderValueChanging, true);
            app.XIndexSlider.Enable = 'off';
            app.YIndexSliderLabel = uilabel(app.LeftPanel);
            app.YIndexSliderLabel.Enable = 'off';
            app.YIndexSliderLabel.Text = 'Y index';
            app.YIndexSlider = uislider(app.LeftPanel);
            app.YIndexSlider.ValueChangedFcn = createCallbackFcn(app, @YIndexSliderValueChanged, true);
            app.YIndexSlider.ValueChangingFcn = createCallbackFcn(app, @YIndexSliderValueChanging, true);
            app.YIndexSlider.Enable = 'off';
            app.UIndexSliderLabel = uilabel(app.LeftPanel);
            app.UIndexSliderLabel.Enable = 'off';
            app.UIndexSliderLabel.Text = 'U index';
            app.UIndexSlider = uislider(app.LeftPanel);
            app.UIndexSlider.ValueChangingFcn = createCallbackFcn(app, @UIndexSliderValueChanging, true);
            app.UIndexSlider.Enable = 'off';
            app.SelectPanel = uipanel(app.LeftPanel);
            app.SelectPanel.Title = 'Select';
            app.StackButton = uibutton(app.SelectPanel, 'push');
            app.StackButton.ButtonPushedFcn = createCallbackFcn(app, @StackButtonPushed, true);
            app.StackButton.Text = 'Stack';
            app.StackLamp = uilamp(app.SelectPanel);
            app.VecMapLamp = uilamp(app.SelectPanel);
            app.CheckBox = uicheckbox(app.SelectPanel);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.SelectPanel);
            app.CheckBox_2.Enable = 'off';
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.SelectPanel);
            app.CheckBox_3.Enable = 'off';
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.SelectPanel);
            app.CheckBox_4.Enable = 'off';
            app.CheckBox_4.Text = '';
            app.Ch1Label = uilabel(app.SelectPanel);
            app.Ch1Label.Text = 'Ch 1';
            app.Label_2 = uilabel(app.SelectPanel);
            app.Label_2.Text = '1';
            app.Label_3 = uilabel(app.SelectPanel);
            app.Label_3.Text = '2';
            app.Label_4 = uilabel(app.SelectPanel);
            app.Label_4.Text = '2';
            app.BackgroundButton = uibutton(app.SelectPanel, 'push');
            app.BackgroundButton.Text = 'Background';
            app.VectormapButton = uibutton(app.SelectPanel, 'state');
            app.VectormapButton.ValueChangedFcn = createCallbackFcn(app, @VectormapButtonPushed, true);
            app.VectormapButton.Text = 'Vector map';
            app.LiveSTICSButton = uibutton(app.SelectPanel, 'state');
            app.LiveSTICSButton.ValueChangedFcn = createCallbackFcn(app, @LiveSTICSButtonValueChanged, true);
            app.LiveSTICSButton.Text = 'Live STICS';
            app.NavigatorPanel = uipanel(app.LeftPanel);
            app.NavigatorPanel.Title = 'Navigator';
            app.Preview = uiaxes(app.NavigatorPanel);
            zlabel(app.Preview, 'Z')
            app.Preview.XLimitMethod = 'tight';
            app.Preview.YLimitMethod = 'tight';
            app.Preview.XTick = [];
            app.Preview.YTick = [];
            app.Zoom = uiaxes(app.NavigatorPanel);
            zlabel(app.Zoom, 'Z')
            app.Zoom.XTick = [];
            app.Zoom.YTick = [];
            app.UpdateButton = uibutton(app.NavigatorPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateNavigator, true);
            app.UpdateButton.Text = 'Update';
            app.PlayNav = uibutton(app.NavigatorPanel, 'push');
            app.PlayNav.ButtonPushedFcn = createCallbackFcn(app, @PlayNavPushed, true);
            app.PlayNav.Text = 'Play';
            app.ZoomSpinnerLabel = uilabel(app.NavigatorPanel);
            app.ZoomSpinnerLabel.Text = 'Zoom';
            app.ZoomSpinner = uispinner(app.NavigatorPanel);
            app.ZoomSpinner.Limits = [1 100];
            app.ZoomSpinner.Value = 1;
            app.FrameSpinnerLabel = uilabel(app.NavigatorPanel);
            app.FrameSpinnerLabel.Text = 'Frame';
            app.FrameSpinner = uispinner(app.NavigatorPanel);
            app.FrameSpinner.Limits = [1 100];
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameValueChanged, true);
            app.FrameSpinner.Value = 1;
            app.XindexSpinnerLabel = uilabel(app.NavigatorPanel);
            app.XindexSpinnerLabel.Text = 'X index';
            app.XindexSpinner = uispinner(app.NavigatorPanel);
            app.XindexSpinner.Limits = [1 100];
            app.XindexSpinner.ValueChangedFcn = createCallbackFcn(app, @coordinatesChanged, true);
            app.XindexSpinner.Value = 1;
            app.YindexSpinnerLabel = uilabel(app.NavigatorPanel);
            app.YindexSpinnerLabel.Text = 'Y index';
            app.YindexSpinner = uispinner(app.NavigatorPanel);
            app.YindexSpinner.Limits = [1 100];
            app.YindexSpinner.ValueChangedFcn = createCallbackFcn(app, @coordinatesChanged, true);
            app.YindexSpinner.Value = 1;
            app.InfoButton = uibutton(app.NavigatorPanel, 'push');
            app.InfoButton.Text = 'Info';
            app.NavSwitch = uiswitch(app.NavigatorPanel, 'slider');
            app.NavSwitch.Items = {'TOI', 'All'};
            app.NavSwitch.Value = 'TOI';
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.ButtonDownFcn = createCallbackFcn(app, @CenterPanelButtonDown, true);
            app.ICF3D = uiaxes(app.CenterPanel);
            title(app.ICF3D, '3D Plot')
            xlabel(app.ICF3D, 'X')
            ylabel(app.ICF3D, 'Y')
            zlabel(app.ICF3D, 'Z')
            app.ICF3D.XLimitMethod = 'tight';
            app.ICF3D.YLimitMethod = 'tight';
            app.ICF3D.ZLimitMethod = 'tight';
            app.TauSpinnerLabel = uilabel(app.CenterPanel);
            app.TauSpinnerLabel.Text = 'Tau';
            app.TauSpinner = uispinner(app.CenterPanel);
            app.TauSpinner.Limits = [0 Inf];
            app.TauSpinner.ValueChangedFcn = createCallbackFcn(app, @TauValueChanged, true);
            app.FitTable = uitable(app.CenterPanel);
            app.FitTable.ColumnName = {'Amp'; 'W X'; 'W Y'; 'Offs'; 'C X'; 'C Y'; 'Ang'; 'R N'; 'Exc'; 'GOF'};
            app.FitTable.RowName = {};
            app.Plot3DButton = uibutton(app.CenterPanel, 'push');
            app.Plot3DButton.ButtonPushedFcn = createCallbackFcn(app, @Plot3DButtonPushed, true);
            app.Plot3DButton.Text = 'Plot 3D';
            app.Play3D = uibutton(app.CenterPanel, 'push');
            app.Play3D.ButtonPushedFcn = createCallbackFcn(app, @Play3DButtonPushed, true);
            app.Play3D.Text = 'Play';
            app.Show3DButtonGroup = uibuttongroup(app.CenterPanel);
            app.Show3DButtonGroup.Title = 'Show 3D';
            app.Show3DButtonGroup.Visible = 'off';
            app.CorrelationButton = uitogglebutton(app.Show3DButtonGroup);
            app.CorrelationButton.Text = 'Correlation';
            app.CorrelationButton.Value = true;
            app.FitButton = uitogglebutton(app.Show3DButtonGroup);
            app.FitButton.Text = 'Fit';
            app.ContourButton = uitogglebutton(app.Show3DButtonGroup);
            app.ContourButton.Text = 'Contour';
            app.ViewPanelsButton = uitogglebutton(app.Show3DButtonGroup);
            app.ViewPanelsButton.Text = 'View Panels';
            app.ResidualsButton = uitogglebutton(app.Show3DButtonGroup);
            app.ResidualsButton.Text = 'Residuals';
            app.asDropDownLabel = uilabel(app.Show3DButtonGroup);
            app.asDropDownLabel.Text = 'as:';
            app.asDropDown = uidropdown(app.Show3DButtonGroup);
            app.asDropDown.Items = {'Surface', 'Mesh'};
            app.asDropDown.Value = 'Surface';
            app.asDropDown_2 = uidropdown(app.Show3DButtonGroup);
            app.asDropDown_2.Items = {'Surface', 'Mesh'};
            app.asDropDown_2.Value = 'Mesh';
            app.asDropDown_2Label = uilabel(app.Show3DButtonGroup);
            app.asDropDown_2Label.Text = 'as:';
            app.FitmodeDropDownLabel = uilabel(app.CenterPanel);
            app.FitmodeDropDownLabel.Text = 'Fit mode';
            app.FitmodeDropDown = uidropdown(app.CenterPanel);
            app.FitmodeDropDown.Items = {'Symmetrical Gaussian', 'Asymmetrical Gaussian', 'Rotated Elliptical Gaussian'};
            app.FitmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @TauValueChanged, true);
            app.FitmodeDropDown.Value = 'Symmetrical Gaussian';
            app.Button = uibutton(app.CenterPanel, 'push');
            app.Button.Icon = 'artist-palette_1f3a8.png';
            app.Button.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.ICF2D = uiaxes(app.RightPanel);
            title(app.ICF2D, '2D Plot')
            xlabel(app.ICF2D, 'X')
            ylabel(app.ICF2D, 'Y')
            zlabel(app.ICF2D, 'Z')
            app.Plot2DButton = uibutton(app.RightPanel, 'push');
            app.Plot2DButton.ButtonPushedFcn = createCallbackFcn(app, @Plot2DButtonPushed, true);
            app.Plot2DButton.Text = 'Plot 2D';
            app.AxisViewDropDownLabel = uilabel(app.RightPanel);
            app.AxisViewDropDownLabel.Text = 'Axis View:';
            app.ShowDropDownLabel = uilabel(app.RightPanel);
            app.ShowDropDownLabel.Text = 'Show:';
            app.Show2D = uidropdown(app.RightPanel);
            app.Show2D.Items = {'Correlation Function', 'Fit', 'Residuals', 'Cross-Sections', 'Projections', 'Trajectory XY', 'Displacement XT', 'Displacement YT', 'Data'};
            app.Show2D.ValueChangedFcn = createCallbackFcn(app, @Plot2DButtonPushed, true);
            app.Show2D.Value = 'Correlation Function';
            app.AxisViewDropDown = uidropdown(app.RightPanel);
            app.AxisViewDropDown.Items = {'Z axis (Top)', 'XY axis (Side)', 'Major-Minor axis'};
            app.AxisViewDropDown.Value = 'Z axis (Top)';
            app.Button_2 = uibutton(app.RightPanel, 'push');
            app.Button_2.Icon = 'artist-palette_1f3a8.png';
            app.Button_2.Text = '';
            app.VelTable = uitable(app.RightPanel);
            app.VelTable.ColumnName = {'Vx'; 'Vy'};
            app.VelTable.RowName = {};
            % Show the figure after all components are created
            app.ICSExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICS_Explorer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ICSExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ICSExplorerUIFigure)
        end
    end
end