classdef ImageAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        GeneralTab                    matlab.ui.container.Tab
        BrowseRootButton              matlab.ui.control.Button
        RootDirectoryEditFieldLabel   matlab.ui.control.Label
        RootDirectoryEditField        matlab.ui.control.EditField
        CodeDirectoryEditFieldLabel   matlab.ui.control.Label
        CodeDirectoryEditField        matlab.ui.control.EditField
        BrowseCodeButton              matlab.ui.control.Button
        DataDirectoryLabel            matlab.ui.control.Label
        DataDirectoryEditField        matlab.ui.control.EditField
        BrowseDataButton              matlab.ui.control.Button
        CloudDirectoryEditFieldLabel  matlab.ui.control.Label
        CloudDirectoryEditField       matlab.ui.control.EditField
        BrowseCloudButton             matlab.ui.control.Button
        ExperimentIDEditFieldLabel    matlab.ui.control.Label
        ExperimentIDEditField         matlab.ui.control.EditField
        SetupTab                      matlab.ui.container.Tab
        FilesListBoxLabel             matlab.ui.control.Label
        FilesListBox                  matlab.ui.control.ListBox
        SitesListBoxLabel             matlab.ui.control.Label
        SitesListBox                  matlab.ui.control.ListBox
        ChannelsListBoxLabel          matlab.ui.control.Label
        ChannelsListBox               matlab.ui.control.ListBox
        UITable2                      matlab.ui.control.Table
        FramesListBoxLabel            matlab.ui.control.Label
        FramesListBox                 matlab.ui.control.ListBox
        PreprocessingTab              matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        ImageregistrationTab          matlab.ui.container.Tab
        PerformjittercorrectionCheckBox  matlab.ui.control.CheckBox
        BackgroundcorrectionTab       matlab.ui.container.Tab
        CellMaskingTab                matlab.ui.container.Tab
        Image                         matlab.ui.control.Image
        SegmentationMethodDropDownLabel  matlab.ui.control.Label
        SegmentationMethodDropDown    matlab.ui.control.DropDown
        CellMaskingCheckBox           matlab.ui.control.CheckBox
        SharpeningPanel               matlab.ui.container.Panel
        SliderLabel                   matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        AnalysisTab                   matlab.ui.container.Tab
        STICScalculationPanel         matlab.ui.container.Panel
        RuninParallelCheckBox         matlab.ui.control.CheckBox
        CoresSpinnerLabel             matlab.ui.control.Label
        CoresSpinner                  matlab.ui.control.Spinner
        STICS_sizes                   matlab.ui.control.Table
        NumberofRunsperFileSpinnerLabel  matlab.ui.control.Label
        NumberofRunsperFileSpinner    matlab.ui.control.Spinner
        ImmobilesubstractionalgorithmDropDownLabel  matlab.ui.control.Label
        ImmobilesubstractionalgorithmDropDown  matlab.ui.control.DropDown
        MovingaveragewindowsizepixelsEditFieldLabel  matlab.ui.control.Label
        MovingaveragewindowsizepixelsEditField  matlab.ui.control.NumericEditField
        DoneButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        Options = struct(); % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.STICS_sizes.Data = cell(1,length(app.STICS_sizes.ColumnName));
        end

        % Callback function
        function MultipleRunsCheckBoxValueChanged(app, event)
            value = app.MultipleRunsCheckBox.Value;
            if value
                app.STICS_sizes.Enable = 'on';
            end
        end

        % Button pushed function: BrowseRootButton
        function BrowseRootButtonPushed(app, event)
            [root] = strcat(uigetdir("*", ...
                "Please select the master folder that contains all the data..." + ...
                " and will contain the heavy results."), ...
                filesep);
            app.RootDirectoryEditField.Value = root;
            addpath(genpath(root));            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            assignin('base', 'Exp_ID', app.ExperimentIDEditField.Value);
            assignin('base', 'root', app.RootDirectoryEditField.Value);
            assignin('base', 'OneDrive', app.CloudDirectoryEditField.Value);
            assignin('base', 'code_path', app.CodeDirectoryEditField.Value);
            assignin('base', 'nd2_path', app.DataDirectoryEditField.Value);
            assignin('base', 'options', app.Options);
            assignin('base', 'stics_options', app.STICS_sizes.Data);
        end

        % Value changed function: NumberofRunsperFileSpinner
        function NumberofRunsperFileSpinnerValueChanged(app, event)
            value = app.NumberofRunsperFileSpinner.Value;
            app.STICS_sizes.Data = cell(value, length(app.STICS_sizes.ColumnName));

        end

        % Value changed function: 
        % ImmobilesubstractionalgorithmDropDown
        function ImmobilesubstractionalgorithmDropDownValueChanged(app, event)
            value = app.ImmobilesubstractionalgorithmDropDown.Value;
            if strcmp(value, "Moving Average")
                app.MovingaveragewindowsizepixelsEditField.Enable = 1;
                app.MovingaveragewindowsizepixelsEditFieldLabel.Enable = 1;
            else
                app.MovingaveragewindowsizepixelsEditField.Enable = 0;
                app.MovingaveragewindowsizepixelsEditFieldLabel.Enable = 0;                
            end
        end

        % Button pushed function: BrowseCodeButton
        function BrowseCodeButtonPushed(app, event)
            [code_path] = strcat(uigetdir("*", ...
                "Select the folder that contains the code to add it to the path"), filesep);
            app.CodeDirectoryEditField.Value = code_path;
            addpath(genpath(code_path));            
        end

        % Button pushed function: BrowseDataButton
        function BrowseDataButtonPushed(app, event)
            [data_path] = strcat(uigetdir("*", ...
                "Please select the folder that contains the ND2 file(s) to analyse."), ...
                filesep);
            app.DataDirectoryEditField.Value = data_path;
            addpath(genpath(data_path));              
        end

        % Button pushed function: BrowseCloudButton
        function BrowseCloudButtonPushed(app, event)
            OneDrive = strcat(uigetdir("*", ...
                "Please select the cloud folder that will contain the results and lightweight files (ex. OneDrive)."), ...
                filesep);
            app.CloudDirectoryEditField.Value = OneDrive;
            addpath(genpath(OneDrive));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.Text = 'About';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.BrowseRootButton = uibutton(app.GeneralTab, 'push');
            app.BrowseRootButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseRootButtonPushed, true);
            app.BrowseRootButton.Text = 'Browse Root';
            app.RootDirectoryEditFieldLabel = uilabel(app.GeneralTab);
            app.RootDirectoryEditFieldLabel.Text = 'Root Directory';
            app.RootDirectoryEditField = uieditfield(app.GeneralTab, 'text');
            app.RootDirectoryEditField.Editable = 'off';
            app.CodeDirectoryEditFieldLabel = uilabel(app.GeneralTab);
            app.CodeDirectoryEditFieldLabel.Text = 'Code Directory';
            app.CodeDirectoryEditField = uieditfield(app.GeneralTab, 'text');
            app.CodeDirectoryEditField.Editable = 'off';
            app.BrowseCodeButton = uibutton(app.GeneralTab, 'push');
            app.BrowseCodeButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseCodeButtonPushed, true);
            app.BrowseCodeButton.Text = 'Browse Code';
            app.DataDirectoryLabel = uilabel(app.GeneralTab);
            app.DataDirectoryLabel.Text = 'Data Directory';
            app.DataDirectoryEditField = uieditfield(app.GeneralTab, 'text');
            app.DataDirectoryEditField.Editable = 'off';
            app.BrowseDataButton = uibutton(app.GeneralTab, 'push');
            app.BrowseDataButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseDataButtonPushed, true);
            app.BrowseDataButton.Text = 'Browse Data';
            app.CloudDirectoryEditFieldLabel = uilabel(app.GeneralTab);
            app.CloudDirectoryEditFieldLabel.Text = 'Cloud Directory';
            app.CloudDirectoryEditField = uieditfield(app.GeneralTab, 'text');
            app.CloudDirectoryEditField.Editable = 'off';
            app.BrowseCloudButton = uibutton(app.GeneralTab, 'push');
            app.BrowseCloudButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseCloudButtonPushed, true);
            app.BrowseCloudButton.Text = 'Browse Cloud';
            app.ExperimentIDEditFieldLabel = uilabel(app.GeneralTab);
            app.ExperimentIDEditFieldLabel.Text = 'Experiment ID';
            app.ExperimentIDEditField = uieditfield(app.GeneralTab, 'text');
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.FilesListBoxLabel = uilabel(app.SetupTab);
            app.FilesListBoxLabel.Text = 'Files';
            app.FilesListBox = uilistbox(app.SetupTab);
            app.FilesListBox.Multiselect = 'on';
            app.FilesListBox.Value = {'Item 1'};
            app.SitesListBoxLabel = uilabel(app.SetupTab);
            app.SitesListBoxLabel.Text = 'Sites';
            app.SitesListBox = uilistbox(app.SetupTab);
            app.SitesListBox.Multiselect = 'on';
            app.SitesListBox.Value = {'Item 1'};
            app.ChannelsListBoxLabel = uilabel(app.SetupTab);
            app.ChannelsListBoxLabel.Text = 'Channels';
            app.ChannelsListBox = uilistbox(app.SetupTab);
            app.ChannelsListBox.Multiselect = 'on';
            app.ChannelsListBox.Value = {'Item 1'};
            app.UITable2 = uitable(app.SetupTab);
            app.UITable2.ColumnName = {'File'; 'Site'; 'Channel'; 'Frame'};
            app.UITable2.RowName = {};
            app.FramesListBoxLabel = uilabel(app.SetupTab);
            app.FramesListBoxLabel.Text = 'Frames';
            app.FramesListBox = uilistbox(app.SetupTab);
            app.FramesListBox.Multiselect = 'on';
            app.FramesListBox.Value = {'Item 1'};
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.TabGroup2 = uitabgroup(app.PreprocessingTab);
            app.ImageregistrationTab = uitab(app.TabGroup2);
            app.ImageregistrationTab.Title = 'Image registration';
            app.PerformjittercorrectionCheckBox = uicheckbox(app.ImageregistrationTab);
            app.PerformjittercorrectionCheckBox.Text = 'Perform jitter correction.';
            app.BackgroundcorrectionTab = uitab(app.TabGroup2);
            app.BackgroundcorrectionTab.Title = 'Background correction';
            app.CellMaskingTab = uitab(app.TabGroup2);
            app.CellMaskingTab.Title = 'Cell Masking';
            app.Image = uiimage(app.CellMaskingTab);
            app.SegmentationMethodDropDownLabel = uilabel(app.CellMaskingTab);
            app.SegmentationMethodDropDownLabel.Text = 'Segmentation Method';
            app.SegmentationMethodDropDown = uidropdown(app.CellMaskingTab);
            app.SegmentationMethodDropDown.Items = {'Peak Jump', 'Minima', 'Otsu'};
            app.SegmentationMethodDropDown.Value = 'Peak Jump';
            app.CellMaskingCheckBox = uicheckbox(app.CellMaskingTab);
            app.CellMaskingCheckBox.Text = 'Cell Masking';
            app.SharpeningPanel = uipanel(app.CellMaskingTab);
            app.SharpeningPanel.Title = 'Sharpening';
            app.SliderLabel = uilabel(app.CellMaskingTab);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.CellMaskingTab);
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.STICScalculationPanel = uipanel(app.AnalysisTab);
            app.STICScalculationPanel.Title = 'STICS calculation';
            app.RuninParallelCheckBox = uicheckbox(app.STICScalculationPanel);
            app.RuninParallelCheckBox.Text = 'Run in Parallel';
            app.CoresSpinnerLabel = uilabel(app.STICScalculationPanel);
            app.CoresSpinnerLabel.Text = 'Cores';
            app.CoresSpinner = uispinner(app.STICScalculationPanel);
            app.CoresSpinner.Limits = [0 Inf];
            app.STICS_sizes = uitable(app.STICScalculationPanel);
            app.STICS_sizes.ColumnName = {'ROI size'; 'ROI shift'; 'TOI size'; 'TOI shift'};
            app.STICS_sizes.ColumnEditable = true;
            app.NumberofRunsperFileSpinnerLabel = uilabel(app.STICScalculationPanel);
            app.NumberofRunsperFileSpinnerLabel.Text = 'Number of Runs per File';
            app.NumberofRunsperFileSpinner = uispinner(app.STICScalculationPanel);
            app.NumberofRunsperFileSpinner.Limits = [1 Inf];
            app.NumberofRunsperFileSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofRunsperFileSpinnerValueChanged, true);
            app.NumberofRunsperFileSpinner.Value = 1;
            app.ImmobilesubstractionalgorithmDropDownLabel = uilabel(app.STICScalculationPanel);
            app.ImmobilesubstractionalgorithmDropDownLabel.Text = 'Immobile substraction algorithm';
            app.ImmobilesubstractionalgorithmDropDown = uidropdown(app.STICScalculationPanel);
            app.ImmobilesubstractionalgorithmDropDown.Items = {'Whole Series Average Substraction', 'Moving Average', 'Butterworth IIR', 'None'};
            app.ImmobilesubstractionalgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @ImmobilesubstractionalgorithmDropDownValueChanged, true);
            app.ImmobilesubstractionalgorithmDropDown.Value = 'Whole Series Average Substraction';
            app.MovingaveragewindowsizepixelsEditFieldLabel = uilabel(app.STICScalculationPanel);
            app.MovingaveragewindowsizepixelsEditFieldLabel.Enable = 'off';
            app.MovingaveragewindowsizepixelsEditFieldLabel.Text = 'Moving average window size (pixels)';
            app.MovingaveragewindowsizepixelsEditField = uieditfield(app.STICScalculationPanel, 'numeric');
            app.MovingaveragewindowsizepixelsEditField.Limits = [0 Inf];
            app.MovingaveragewindowsizepixelsEditField.Enable = 'off';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end