classdef STICS_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Menu                       matlab.ui.container.Menu
        Menu2                      matlab.ui.container.Menu
        TabGroup                   matlab.ui.container.TabGroup
        FilesMasksTab              matlab.ui.container.Tab
        SelectfilestoanalyzePanel  matlab.ui.container.Panel
        FileIDListBoxLabel         matlab.ui.control.Label
        FileIDListBox              matlab.ui.control.ListBox
        AddButton                  matlab.ui.control.Button
        RemoveButton               matlab.ui.control.Button
        PreviewPanel               matlab.ui.container.Panel
        Image                      matlab.ui.control.Image
        FrameSliderLabel           matlab.ui.control.Label
        FrameSlider                matlab.ui.control.Slider
        SaveMaskButton             matlab.ui.control.Button
        PolygonMaskCheckBox        matlab.ui.control.CheckBox
        MetadataTab                matlab.ui.container.Tab
        UITable                    matlab.ui.control.Table
        SavemetadataButton         matlab.ui.control.Button
        GetmetadataButton          matlab.ui.control.Button
        ImportmetadataButton       matlab.ui.control.Button
        STICCSTab                  matlab.ui.container.Tab
        STICScalculationPanel      matlab.ui.container.Panel
        RuninParallelCheckBox      matlab.ui.control.CheckBox
        CoresSpinnerLabel          matlab.ui.control.Label
        CoresSpinner               matlab.ui.control.Spinner
        STICS_table                matlab.ui.control.Table
        ChooseanimmobilesubstractionalgorithmDropDownLabel  matlab.ui.control.Label
        ChooseanimmobilesubstractionalgorithmDropDown  matlab.ui.control.DropDown
        MovingaveragewindowsizepixelsEditFieldLabel  matlab.ui.control.Label
        MovingaveragewindowsizepixelsEditField  matlab.ui.control.NumericEditField
        AddanotherrunButton        matlab.ui.control.Button
        DoneButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        metadata = struct(); % Description
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
           [file_id, file_path] = uigetfile("*","MultiSelect","on");
           app.FileIDListBox.Items = [app.FileIDListBox.Items, file_id];
           file = length(app.FileIDListBox.Items);
           app.metadata(file).file_id = file_id;
           app.metadata(file).file_path = file_path;
        end

        % Value changed function: FileIDListBox
        function FileIDListBoxValueChanged(app, event)
            value = app.FileIDListBox.Value;
%             file = [app.FileIDListBox.Items] == value;
            app.Image.ImageSource = [app.metadata.file_path, value];
%             app.FrameSlider.Limits = [0, n_frames];
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            value = app.FrameSlider.Value;
            if value == 0
                % Display average
            else
                % Display corresponding frame
            end
        end

        % Cell edit callback: STICS_table
        function STICS_tableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FilesMasksTab = uitab(app.TabGroup);
            app.FilesMasksTab.Title = 'Files & Masks';
            app.SelectfilestoanalyzePanel = uipanel(app.FilesMasksTab);
            app.SelectfilestoanalyzePanel.Title = 'Select files to analyze';
            app.FileIDListBoxLabel = uilabel(app.SelectfilestoanalyzePanel);
            app.FileIDListBoxLabel.Text = 'File ID';
            app.FileIDListBox = uilistbox(app.SelectfilestoanalyzePanel);
            app.FileIDListBox.Items = {};
            app.FileIDListBox.ValueChangedFcn = createCallbackFcn(app, @FileIDListBoxValueChanged, true);
            app.FileIDListBox.Value = {};
            app.AddButton = uibutton(app.SelectfilestoanalyzePanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.SelectfilestoanalyzePanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.PreviewPanel = uipanel(app.FilesMasksTab);
            app.PreviewPanel.Title = 'Preview';
            app.Image = uiimage(app.PreviewPanel);
            app.FrameSliderLabel = uilabel(app.PreviewPanel);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.PreviewPanel);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.SaveMaskButton = uibutton(app.PreviewPanel, 'push');
            app.SaveMaskButton.Text = 'Save Mask';
            app.PolygonMaskCheckBox = uicheckbox(app.PreviewPanel);
            app.PolygonMaskCheckBox.Text = 'Polygon Mask';
            app.MetadataTab = uitab(app.TabGroup);
            app.MetadataTab.Title = 'Metadata';
            app.UITable = uitable(app.MetadataTab);
            app.UITable.ColumnName = {'File ID'; 'Pixel size'; 'Frame interval'; 'Size (x)'; 'Size(y)'; 'Size (t)'};
            app.UITable.RowName = {};
            app.SavemetadataButton = uibutton(app.MetadataTab, 'push');
            app.SavemetadataButton.Text = 'Save metadata';
            app.GetmetadataButton = uibutton(app.MetadataTab, 'push');
            app.GetmetadataButton.Text = 'Get metadata';
            app.ImportmetadataButton = uibutton(app.MetadataTab, 'push');
            app.ImportmetadataButton.Text = 'Import metadata';
            app.STICCSTab = uitab(app.TabGroup);
            app.STICCSTab.Title = 'STIC(C)S';
            app.STICScalculationPanel = uipanel(app.STICCSTab);
            app.STICScalculationPanel.Title = 'STICS calculation';
            app.RuninParallelCheckBox = uicheckbox(app.STICScalculationPanel);
            app.RuninParallelCheckBox.Text = 'Run in Parallel';
            app.CoresSpinnerLabel = uilabel(app.STICScalculationPanel);
            app.CoresSpinnerLabel.Text = 'Cores';
            app.CoresSpinner = uispinner(app.STICScalculationPanel);
            app.CoresSpinner.Limits = [0 Inf];
            app.STICS_table = uitable(app.STICScalculationPanel);
            app.STICS_table.ColumnName = {'STICCS'; 'File 1'; 'File 2'; 'Immobile filter'; 'ROI size'; 'ROI shift'; 'TOI size'; 'TOI shift'; 'Tau limit'};
            app.STICS_table.ColumnEditable = true;
            app.STICS_table.CellEditCallback = createCallbackFcn(app, @STICS_tableCellEdit, true);
            app.ChooseanimmobilesubstractionalgorithmDropDownLabel = uilabel(app.STICScalculationPanel);
            app.ChooseanimmobilesubstractionalgorithmDropDownLabel.Text = 'Choose an immobile substraction algorithm';
            app.ChooseanimmobilesubstractionalgorithmDropDown = uidropdown(app.STICScalculationPanel);
            app.ChooseanimmobilesubstractionalgorithmDropDown.Items = {'None', 'Whole Series Average Substraction', 'Moving Average', 'Butterworth IIR'};
            app.ChooseanimmobilesubstractionalgorithmDropDown.Value = 'None';
            app.MovingaveragewindowsizepixelsEditFieldLabel = uilabel(app.STICScalculationPanel);
            app.MovingaveragewindowsizepixelsEditFieldLabel.Enable = 'off';
            app.MovingaveragewindowsizepixelsEditFieldLabel.Text = 'Moving average window size (pixels)';
            app.MovingaveragewindowsizepixelsEditField = uieditfield(app.STICScalculationPanel, 'numeric');
            app.MovingaveragewindowsizepixelsEditField.Limits = [0 Inf];
            app.MovingaveragewindowsizepixelsEditField.Enable = 'off';
            app.AddanotherrunButton = uibutton(app.STICScalculationPanel, 'push');
            app.AddanotherrunButton.Text = 'Add another run';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STICS_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end