classdef STICSfield_selection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        DoneButton           matlab.ui.control.Button
        decisionButtonGroup  matlab.ui.container.ButtonGroup
        YesButton            matlab.ui.control.RadioButton
        NoButton             matlab.ui.control.RadioButton
        questionLabel        matlab.ui.control.Label
    end

    properties (Access = public)
        CallingApp % Main app object
        decision % Yes or No
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, STICSgui1)
            app.CallingApp = STICSgui1;
            app.decision = 'N'; % Initialize decision value
        end

        % Selection changed function: decisionButtonGroup
        function decisionButtonGroupSelectionChanged(app, event)
            if (app.YesButton.Value)
                app.decision = 'Y';
            else
                app.decision = 'N';
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            crop_decision(app.CallingApp, app.decision);
            %app.CallingApp.RunSTICSButton.Enable = 'On';
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            crop_decision(app.CallingApp, app.decision);
            %app.CallingApp.RunSTICSButton.Enable = 'On';
            delete(app)  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.questionLabel = uilabel(app.UIFigure);
            app.questionLabel.Text = 'Do you want to select a polygon region?';
            app.decisionButtonGroup = uibuttongroup(app.UIFigure);
            app.decisionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @decisionButtonGroupSelectionChanged, true);
            app.NoButton = uiradiobutton(app.decisionButtonGroup);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.YesButton = uiradiobutton(app.decisionButtonGroup);
            app.YesButton.Text = 'Yes';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STICSfield_selection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end