classdef STICSgui1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        File                            matlab.ui.container.Menu
        LoadDataMenu                    matlab.ui.container.Menu
        ExitProgramMenu                 matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        ProfileMenu                     matlab.ui.container.Menu
        WorkspaceMenu                   matlab.ui.container.Menu
        ThemeMenu                       matlab.ui.container.Menu
        OriginalMenu                    matlab.ui.container.Menu
        LightModeMenu                   matlab.ui.container.Menu
        DarkModeMenu                    matlab.ui.container.Menu
        NightShiftMenu                  matlab.ui.container.Menu
        RandomMenu                      matlab.ui.container.Menu
        About                           matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        TutorialMenu                    matlab.ui.container.Menu
        CreditsMenu                     matlab.ui.container.Menu
        LicenseMenu                     matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        Toolbar                         matlab.ui.container.Toolbar
        UpdatePushTool                  matlab.ui.container.toolbar.PushTool
        SyncPushTool                    matlab.ui.container.toolbar.PushTool
        RestartPushTool                 matlab.ui.container.toolbar.PushTool
        mainTabGroup                    matlab.ui.container.TabGroup
        WelcomeTab                      matlab.ui.container.Tab
        welcomeNextButton               matlab.ui.control.Button
        ICSExplorerButton               matlab.ui.control.Button
        StackViewerButton               matlab.ui.control.Button
        JumpLabel                       matlab.ui.control.Label
        STICSProButton                  matlab.ui.control.Button
        EachcorrelationspectroscopyalgorithmcontainsLabel  matlab.ui.control.Label
        UsageLabel                      matlab.ui.control.Label
        instructionsInfo                matlab.ui.control.Label
        welcomeTitle                    matlab.ui.control.Label
        StartTab                        matlab.ui.container.Tab
        SaveButton                      matlab.ui.control.Button
        NewButton_2                     matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        CurrentWorkflowListBoxLabel     matlab.ui.control.Label
        CurrentWorkflowListBox          matlab.ui.control.ListBox
        Tree                            matlab.ui.container.Tree
        PresetsNode                     matlab.ui.container.TreeNode
        OneChannelNode                  matlab.ui.container.TreeNode
        ICSNode                         matlab.ui.container.TreeNode
        TICSNode                        matlab.ui.container.TreeNode
        STICSNode                       matlab.ui.container.TreeNode
        TwoChannelsNode                 matlab.ui.container.TreeNode
        ICCSNode                        matlab.ui.container.TreeNode
        TICCSNode                       matlab.ui.container.TreeNode
        STICCSNode                      matlab.ui.container.TreeNode
        ThreeChannelsNode               matlab.ui.container.TreeNode
        FRETSTICSNode                   matlab.ui.container.TreeNode
        CustomNode                      matlab.ui.container.TreeNode
        RadialSTICSNode                 matlab.ui.container.TreeNode
        Tree2                           matlab.ui.container.CheckBoxTree
        LoadingNode                     matlab.ui.container.TreeNode
        Node2                           matlab.ui.container.TreeNode
        Node3                           matlab.ui.container.TreeNode
        Node4                           matlab.ui.container.TreeNode
        PreprocessingNode               matlab.ui.container.TreeNode
        SetupNode                       matlab.ui.container.TreeNode
        RunNode                         matlab.ui.container.TreeNode
        AnalysisNode                    matlab.ui.container.TreeNode
        ExportNode                      matlab.ui.container.TreeNode
        AvailableStepsLabel             matlab.ui.control.Label
        AvailableWorkflowsLabel         matlab.ui.control.Label
        DeleteButton                    matlab.ui.control.Button
        SelectButton                    matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        DoneButton                      matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        startBackButton                 matlab.ui.control.Button
        startNextButton                 matlab.ui.control.Button
        PapersButton                    matlab.ui.control.Button
        DocsButton                      matlab.ui.control.Button
        DataTab                         matlab.ui.container.Tab
        dataRemoveSelectedButton        matlab.ui.control.Button
        dataNextButton                  matlab.ui.control.Button
        dataBackButton                  matlab.ui.control.Button
        dataLaunchStackViewerButton     matlab.ui.control.Button
        dataClearMetadataButton         matlab.ui.control.Button
        dataMetadataLabel               matlab.ui.control.Label
        dataMetadataUITable             matlab.ui.control.Table
        dataClearTableButton            matlab.ui.control.Button
        dataClearCurrentButton          matlab.ui.control.Button
        dataAddtoTableButton            matlab.ui.control.Button
        dataSelectedFilesLabel          matlab.ui.control.Label
        dataFileUITable                 matlab.ui.control.Table
        dataSelectFileLamp              matlab.ui.control.Lamp
        dataSelectFileButton            matlab.ui.control.Button
        PrepTab                         matlab.ui.container.Tab
        prepNextButton                  matlab.ui.control.Button
        prepBackButton                  matlab.ui.control.Button
        prepTabGroup                    matlab.ui.container.TabGroup
        ImmobileTab                     matlab.ui.container.Tab
        immobileThreshVectorEditField   matlab.ui.control.NumericEditField
        ThreshVectorEditFieldLabel      matlab.ui.control.Label
        immobileTauLimitEditField       matlab.ui.control.NumericEditField
        TauLimitEditFieldLabel          matlab.ui.control.Label
        immobileOutlierCriteriaLabel    matlab.ui.control.Label
        immobileMovingAverageEditField  matlab.ui.control.NumericEditField
        immobileDropDown                matlab.ui.control.DropDown
        immobileRemovalSettingsLabel    matlab.ui.control.Label
        CropTab                         matlab.ui.container.Tab
        YPositionSlider                 matlab.ui.control.Slider
        YPositionSliderLabel            matlab.ui.control.Label
        XPositionSlider                 matlab.ui.control.Slider
        XPositionSliderLabel            matlab.ui.control.Label
        FOVSizeSlider                   matlab.ui.control.Slider
        FOVSizeSliderLabel              matlab.ui.control.Label
        YPositionEditField              matlab.ui.control.NumericEditField
        XPositionEditField              matlab.ui.control.NumericEditField
        FOVSizeEditField                matlab.ui.control.NumericEditField
        cropFileDropDown                matlab.ui.control.DropDown
        FileLabel                       matlab.ui.control.Label
        cropNextButton                  matlab.ui.control.Button
        Ch1FOVUIAxes                    matlab.ui.control.UIAxes
        Ch1UIAxes                       matlab.ui.control.UIAxes
        SetupTab                        matlab.ui.container.Tab
        setupOmegaThresholdEditField    matlab.ui.control.NumericEditField
        OmegaThresholdLabel             matlab.ui.control.Label
        setupBatchCheckBox              matlab.ui.control.CheckBox
        setupLaunchStackViewerButton    matlab.ui.control.Button
        setupAddtoTableButton           matlab.ui.control.Button
        setupMessageLabel               matlab.ui.control.Label
        setupChannel2DropDown           matlab.ui.control.DropDown
        Channel2Label                   matlab.ui.control.Label
        setupChannel1DropDown           matlab.ui.control.DropDown
        Channel1Label                   matlab.ui.control.Label
        setupRemoveSelectedButton       matlab.ui.control.Button
        setupNextButton                 matlab.ui.control.Button
        setupBackButton                 matlab.ui.control.Button
        setupClearTableButton           matlab.ui.control.Button
        setupFitRadiusEditField         matlab.ui.control.NumericEditField
        FitRadiusEditFieldLabel         matlab.ui.control.Label
        setupTOIShiftEditField          matlab.ui.control.NumericEditField
        TOIShiftEditFieldLabel          matlab.ui.control.Label
        setupTOISizeEditField           matlab.ui.control.NumericEditField
        TOISizeEditFieldLabel           matlab.ui.control.Label
        setupROIShiftEditField          matlab.ui.control.NumericEditField
        ROIShiftEditFieldLabel          matlab.ui.control.Label
        setupROISizeEditField           matlab.ui.control.NumericEditField
        ROISizeEditFieldLabel           matlab.ui.control.Label
        setupUITable                    matlab.ui.control.Table
        RunTab                          matlab.ui.container.Tab
        runFinishedLabel                matlab.ui.control.Label
        runFinishedUITable              matlab.ui.control.Table
        runCurrentLabel                 matlab.ui.control.Label
        runCurrentUITable               matlab.ui.control.Table
        runQueueLabel                   matlab.ui.control.Label
        runQueueUITable                 matlab.ui.control.Table
        runNextButton                   matlab.ui.control.Button
        ExportTab                       matlab.ui.container.Tab
        exportTableLabel                matlab.ui.control.Label
        exportLaunchICSExplorerButton   matlab.ui.control.Button
        exportUITable                   matlab.ui.control.Table
        exportSaveButton                matlab.ui.control.Button
        exportRootLamp                  matlab.ui.control.Lamp
        exportMovieFormatDropDown       matlab.ui.control.DropDown
        MovieFormatDropDownLabel        matlab.ui.control.Label
        exportImageFormatDropDown       matlab.ui.control.DropDown
        ImageFormatDropDownLabel        matlab.ui.control.Label
        exportExportOptionsLabel        matlab.ui.control.Label
        exportOutputNameEditField       matlab.ui.control.EditField
        OutputNameEditFieldLabel        matlab.ui.control.Label
        exportOutputFolderEditField     matlab.ui.control.EditField
        OutputFolderEditFieldLabel      matlab.ui.control.Label
        exportAxisTitleEditField        matlab.ui.control.EditField
        AxisTitleEditFieldLabel         matlab.ui.control.Label
        exportBrowseRootButton          matlab.ui.control.Button
        exportDoneButton                matlab.ui.control.Button
    end

    properties (Access = public)
        % Init & Other Vars
        currTab;
        
        %~~~~~~~~~~~~~~~~~~~~%
        % Data Tab variables
        dataFileStack;
        dataFilePath;
        dataFileName;
        dataFileArray;
        dataFilePathArray;
        
        dimX;
        dimY;
        dimT;
        
        currLength;
        currLine;
        
        dataFOV;
        
        %~~~~~~~~~~~~~~~~~~~~%
        % Prep Tab Variables


        %~~~~~~~~~~~~~~~~~~~~%
        % Setup Tab Variables
        setupCurrLength; % = Run var
        setupCurrLine;
        setupVars; % 2 = Batch, 3 = Protocol, 4 = C1Pos, 5 = C1, 6 = C2Pos, 7 = C2, 8 = ROIsize, 
                   % 9 = ROIshift, 10 = TOIsize, 11 = TOIshift, 12 = FitRadius, 13 = OmegaThreshold, 
                   % 14 = FileSelected
        setupBatch;
        setupStack1;
        setupStack2;
        
        %~~~~~~~~~~~~~~~~~~~~%
        % Run Tab Variables
        runOpt; % Options class
        runPosition_x;
        runPosition_y;
        runPosition_t;
        runVelocityMap;
        varsStruct;
        
        %~~~~~~~~~~~~~~~~~~~~%
        % Export Tab Variables
        axisTitle;
        outputName;
        exportPath;
        imageFormat;
        movieFormat;
        
        exportCurrLength;
        exportCurrLine;
        exportRun;
        exportBatch;
        exportDoneArray;
        
        
    end
    
    properties (Access = private)
        STICS_Pro
        ICSExplorerApp; % ICS explorer app
        FileViewer; % File previewer app
    end
    
    methods (Access = public) 
        function resetMetaTable(app)
            app.dataMetadataUITable.Data = {'FileID', 0; 'Size(x)', 0; 'Size(y)', 0; 'Size(t)', 0;
                'Size(c)', 0; 'PixelSize', 0; 'Frame Rate', 0};
        end
        
        function clearVars(app)
            app.dataFileName = [];
            app.dataFilePath = [];
            app.dataSelectFileLamp.Color = [1.0 0.0 0.0];
        end
        
        function warnDim(~)
            % Error message
            msg = ['Error: File selected does not have the same dimensions as the ' ...
                'file(s) in the table. Choose a different file or' ...
                'launch another window of the app.'];
            msgtitle = 'Error: File Wrong Dimensions';
            [icondata, iconcmap] = imread('Error_Icon.png');
            msgbox(msg, msgtitle, 'custom', icondata, iconcmap);
        end
        
        function immobileFilter = getFiltering(app)
            val = app.immobileDropDown.Value;
            
            if (strcmp(val, 'None'))
                immobileFilter = 'none';
            elseif (strcmp(val, 'FourierWhole'))
                immobileFilter = 'FourierWhole';
            elseif (strcmp(val, 'MovingAverage'))
                immobileFilter = 'MovingAverage';
            else
                immobileFilter = 'butterIIR';
            end
        end
        
        function add2queue(app)
            data = get(app.setupUITable, 'Data');
            
            % Loop through setup table
            for i = 1:size(data(:, 1)', 2)
                queueData = get(app.runQueueUITable, 'Data');
                
                % First entry: get run, batch
                if (i == 1)
                    app.runQueueUITable.Data = {data{i, 2}, data{i, 3}};
                % Additional entries: same as above
                else
                    newRow = {data{i, 2}, data{i, 3}};
                    queueData(end + 1, :) = newRow;
                    set(app.runQueueUITable, 'Data', queueData);
                end
            end
        end
        
        
        function add2export(app)
            data = get(app.setupUITable, 'Data');
            
            % Loop through setup table
            for i = 1:size(data(:, 1)', 2)
                exportData = get(app.exportUITable, 'Data');
                
                % First entry: get run, batch
                if (i == 1)
                    app.exportUITable.Data = {data{i, 2}, data{i, 3}, false};
                % Additional entries: same as above
                else
                    newRow = {data{i, 2}, data{i, 3}, false};
                    exportData(end + 1, :) = newRow;
                    set(app.exportUITable, 'Data', exportData);
                end
            end
        end
        
        function doneRow(app, row)
            style = uistyle('BackgroundColor', [0.0 1.0 0.0]);
            addStyle(app.exportUITable, style, 'row', row);
            
            app.exportCurrLine = [];
        end
       
        function storeVars(app, Run, Batch)
            % Use run/batch info as key in struct
            run = string(Run);
            batch = string(Batch);
            index = strcat("results", run, batch);
            
            % Store all outputs as a cell array
            outputs = {app.runVelocityMap, app.runPosition_x, app.runPosition_y,...
                       app.runPosition_t, app.runOpt};
            
            % Store cell array inside varsStruct
            app.varsStruct.(index) = outputs;
           
            % Clear vars for next iteration
            app.runVelocityMap = [];
            app.runPosition_x = [];
            app.runPosition_y = [];
            app.runPosition_t = [];
            app.runOpt = [];       
        end
        
        % Function in progress
        % Currently working on changing each individual object
        % Looking for a more efficient method...
        function changeTheme(app, theme)
            if (strcmp(theme, 'DarkMode'))
                color1Array = [0.0 0.0 0.0];
                color2Array = [1.0 1.0 1.0];
            end
            
            % Instructions Tab
            app.WelcomeTab.BackgroundColor = color1Array;
            app.welcomeTitle.FontColor = color2Array;
            app.instructionsInfo.FontColor = color2Array;
            app.UsageLabel.FontColor = color2Array;
            app.EachcorrelationspectroscopyalgorithmcontainsLabel.FontColor = color2Array;
            app.JumpLabel.FontColor = color2Array;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            curr_dir = pwd;
            
            if (exist([curr_dir filesep 'current' filesep 'STICS'], 'dir') == 7)
                addpath(['current' filesep 'STICS']);
            end
            
            if (exist([curr_dir filesep 'Icons'], 'dir') == 7)
                addpath('Icons');
            end
            
            %{
            if (exist([curr_dir '/parfor_progressbar'], 'dir') == 7)
                addpath('parfor_progressbar');
            end
            %}
            
            % Push Icon by mithun on freeicons.io
            % Sync Icon by Free Preloaders on freeicons.io
            % Restart Icon by anumithun on freeicons.io
            % Error Icon by anumithun on freeicons.io
            % Warning Icon by mithun on freeicons.io
            
            
            app.currTab = app.WelcomeTab;
            
            % Data Tab Init
            resetMetaTable(app);
            app.dataFileUITable.ColumnWidth = {400, 'auto'};
            app.dataMetadataUITable.ColumnWidth = {75, 'auto'};
            set(app.dataMetadataUITable, 'ColumnEditable', [false, true]);
            set(app.dataFileUITable, 'ColumnEditable', [false, true]);
            
            % Setup Tab Init
            app.setupUITable.ColumnWidth = {'auto', 40, 55, 70, 60, 230, 60, 230, 60, 65, 60, 60, 70, 'auto'};
            set(app.setupUITable, 'ColumnEditable', [true, false, false, false, false, false, false, false, false, ...
                                                     false, false, false, false, false]);
            
            % Export Tab Init
            set(app.exportUITable, 'ColumnEditable', [false, false, true]);
            
        end

        % Selection change function: mainTabGroup
        function mainTabGroupSelectionChanged(app, event)
            app.mainTabGroup.SelectedTab = app.currTab;
        end

        % Menu selected function: DarkModeMenu
        function DarkModeMenuSelected(app, event)
           changeTheme(app, 'DarkMode');
        end

        % Button pushed function: STICSProButton
        function STICSProButtonPushed(app, event)
            app.STICS_Pro = STICS_pro();
        end

        % Button pushed function: StackViewerButton
        function StackViewerButtonPushed(app, event)
            app.FileViewer = FilePreviewer(app, [], [], []);
        end

        % Button pushed function: ICSExplorerButton
        function ICSExplorerButtonPushed(app, event)
            app.ICSExplorerApp = ICS_Explorer(app, [], [], [], [], [], []);
        end

        % Menu selected function: ExitProgramMenu
        function ExitProgramMenuSelected(app, event)
            delete(app.STICS_Pro);
            delete(app.ICSExplorerApp);
            delete(app.FileViewer);
            delete(app);
        end

        % Menu selected function: ProfileMenu
        function ProfileMenuSelected(app, event)
            
        end

        % Menu selected function: LoadDataMenu
        function LoadDataMenuSelected(app, event)
            
        end

        % Button pushed function: welcomeNextButton
        function welcomeNextButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.DataTab;
            app.currTab = app.DataTab;
        end

        % Button pushed function: startNextButton
        function startNextButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.DataTab;
            app.currTab = app.DataTab;
        end

        % Button pushed function: startBackButton
        function startBackButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.WelcomeTab;
            app.currTab = app.WelcomeTab;
        end

        % Button pushed function: dataSelectFileButton
        function dataSelectFileButtonPushed(app, event)
            app.dataSelectFileLamp.Color = [1.0 1.0 0.0];
            
            [fileName, pathName] = uigetfile({'*.tif'; '*.tiff'},'Please select your data set');
            
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            % Check if user clicked cancel
            if (fileName == 0)
                if (isempty(app.dataFilePath))
                    app.dataSelectFileLamp.Color = [1.0 0.0 0.0];
                else
                    app.dataSelectFileLamp.Color = [0.0 1.0 0.0];
                end
                
                return
            end   
            
            app.dataFilePath = pathName;
            app.dataFileName = fileName(1:end-4);
            data = [app.dataFilePath app.dataFileName '.tif'];
            
            % Check if data is in MultiTiff files
            if (strcmp(fileName(end-2:end),'tif'))
                try
                    app.dataFileStack = readFileToStack(data);

                catch Error
                    % Select another file
                    [StackFileName, StackPath] = uigetfile({'*.tif'; '*.tiff'}, 'Select Stack File');
                    app.UIFigure.Visible = 'off';
                    app.UIFigure.Visible = 'on';
                    app.dataFileStack = readFileToStack([StackPath, StackFileName]);
                end
                
                isValid = 1;
            else
                isValid = 0;
            end
            
            % Check if file valid
            if (isValid)
                app.dataSelectFileLamp.Color = [0.0 1.0 0.0];    
            % File invalid
            else
                app.dataSelectFileLamp.Color = [1.0 0.0 0.0];
            end
        end

        % Button pushed function: dataAddtoTableButton
        function dataAddtoTableButtonPushed(app, event)
            % Add row if at least one valid file loaded
            if (~isempty(app.dataFileName))
                % Get proper index value in array
                if (isempty(app.currLength))
                    app.currLength = 1;
                else
                    app.currLength = app.currLength + 1;
                end
                
                % Check if Stack not empty
                if (~isempty(app.dataFileStack))
                    if (~isempty(app.dimX) && ~isempty(app.dimY) && ~isempty(app.dimT))
                        if (size(app.dataFileStack, 1) ~= app.dimY)
                            clearVars(app);
                            app.dataFileStack = [];
                            warnDim(app);
                            return;
                        elseif (size(app.dataFileStack, 2) ~= app.dimX)
                            clearVars(app);
                            app.dataFileStack = [];
                            warnDim(app);
                            return;
                        elseif (size(app.dataFileStack, 3) ~= app.dimT)
                            clearVars(app);
                            app.dataFileStack = [];
                            warnDim(app);
                            return;
                        end
                    else
                        app.dimY = size(app.dataFileStack, 1);
                        app.dimX = size(app.dataFileStack, 2);
                        app.dimT = size(app.dataFileStack, 3);
                    end
                    
                    app.dataFileArray(:,:,:,app.currLength) = app.dataFileStack;
                    app.dataFilePathArray(:,:,:,app.currLength) = app.dataFilePath;
                end
                
                data = get(app.dataFileUITable, 'Data');
                
                if (isempty(data))
                    app.dataFileUITable.Data = {app.dataFileName, false};
                else
                    newRow = {app.dataFileName, false};
                    data(end + 1, :) = newRow;
                    set(app.dataFileUITable, 'Data', data);
                end
                
                clearVars(app);
            end
        end

        % Cell edit callback: dataFileUITable
        function dataFileUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            % Check if file line not selected
            if (isempty(app.currLine))
                if (newData)
                    app.currLine = indices(1);
                    
                    data = get(app.dataFileUITable, 'Data');
                    
                    metaData = get(app.dataMetadataUITable, 'Data');
                    metaData(1, 2) = data(app.currLine, 1);
                    metaData(2, 2) = {size(app.dataFileArray(:,:,:,app.currLine), 2)};
                    metaData(3, 2) = {size(app.dataFileArray(:,:,:,app.currLine), 1)};
                    metaData(4, 2) = {size(app.dataFileArray(:,:,:,app.currLine), 3)};
                    set(app.dataMetadataUITable, 'Data', metaData);
                end
            else
                if (newData)
                    data = get(app.dataFileUITable, 'Data');
                    data(app.currLine, 2) = {false};
                    set(app.dataFileUITable, 'Data', data);
                
                    app.currLine = indices(1);
                    
                    data = get(app.dataFileUITable, 'Data');
                    
                    metaData = get(app.dataMetadataUITable, 'Data');
                    metaData(1, 2) = data(app.currLine, 1);
                    metaData(2, 2) = {size(app.dataFileArray(:,:,:,app.currLine), 2)};
                    metaData(3, 2) = {size(app.dataFileArray(:,:,:,app.currLine), 1)};
                    metaData(4, 2) = {size(app.dataFileArray(:,:,:,app.currLine), 3)};
                    set(app.dataMetadataUITable, 'Data', metaData);
                else
                    app.currLine = [];
                    resetMetaTable(app);
                end
            end
        end

        % Cell edit callback: dataMetadataUITable
        function dataMetadataUITableCellEdit(app, event)
            indices = event.Indices;
            
            if (indices(1) == 1 || indices(1) == 2 || indices(1) == 3 || indices(1) == 4)
                event.Source.Data(indices(1), indices(2)) = {event.PreviousData};
            end
            
        end

        % Button pushed function: dataClearCurrentButton
        function dataClearCurrentButtonPushed(app, event)
            clearVars(app);
            
            app.dataFileStack = [];
            app.currLength = [];
        end

        % Button pushed function: dataRemoveSelectedButton
        function dataRemoveSelectedButtonPushed(app, event)
            if (~isempty(app.currLine))
                data = get(app.dataFileUITable, 'Data');
                data(app.currLine, :) = [];
                set(app.dataFileUITable, 'Data', data);
                
                app.dataFileArray(:,:,:,[1:app.currLine-1 app.currLine+1:end]);
                app.currLine = [];
                app.currLength = app.currLength - 1;
                
                resetMetaTable(app);
                
                app.currLength
                
            end
        end

        % Button pushed function: dataClearTableButton
        function dataClearTableButtonPushed(app, event)
            set(app.dataFileUITable, 'Data', {})
            
            clearVars(app);
            resetMetaTable(app);
            
            app.currLength = [];
            app.currLine = [];
            app.dataFileArray = [];
            app.dataFilePathArray = [];
            app.dataFileStack = [];
        end

        % Button pushed function: dataClearMetadataButton
        function dataClearMetadataButtonPushed(app, event)
            resetMetaTable(app);
        end

        % Button pushed function: dataLaunchStackViewerButton
        function dataLaunchStackViewerButtonPushed(app, event)
            if (~isempty(app.dataFileArray))
                if (~isempty(app.currLine))
                    app.dataFileStack = app.dataFileArray(:,:,:,app.currLine);
                    
                    if (~all(app.dataFileStack))
                        app.dataFileStack = [];
                    end
                else
                    app.dataFileStack = [];
                end
            end
            
            app.FileViewer = FilePreviewer(app, app.dataFileStack, [], app.currLine);
        end

        % Button pushed function: dataBackButton
        function dataBackButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.WelcomeTab;
            app.currTab = app.WelcomeTab;
        end

        % Button pushed function: dataNextButton
        function dataNextButtonPushed(app, event)
            data = get(app.dataFileUITable, 'Data');
            
            if (isempty(data))
                % Error message
                msg = 'Error: Please add at least one file to the table.';
                msgtitle = 'Error: No File';
                [icondata, iconcmap] = imread('Error_Icon.png');
                msgbox(msg, msgtitle, 'custom', icondata, iconcmap);
            else
                app.mainTabGroup.SelectedTab = app.PrepTab;
                app.currTab = app.PrepTab;
                
                % Init Prep -crop- Tab options
                app.cropFileDropDown.Items = [{'None'}, data(:, 1)'];
                app.cropFileDropDown.ItemsData = 0:size(data(:, 1)', 2);
                
                % Init Setup Tab options
                app.setupChannel1DropDown.Items =  data(:, 1)';
                app.setupChannel1DropDown.ItemsData = 1:size(data(:, 1)', 2);
                app.setupChannel2DropDown.Items =  [{'None'}, data(:, 1)'];
                app.setupChannel2DropDown.ItemsData = 0:size(data(:, 1)', 2);
            end
        end

        % Value changed function: immobileDropDown
        function immobileDropDownValueChanged(app, event)
            value = app.immobileDropDown.Value;
            
            if (strcmp(value, 'MovingAverage'))
                app.immobileMovingAverageEditField.Enable = 'On';
            else
                app.immobileMovingAverageEditField.Enable = 'Off';
            end
        end

        % Button pushed function: prepBackButton
        function prepBackButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.DataTab;
            app.currTab = app.DataTab;
        end

        % Button pushed function: prepNextButton
        function prepNextButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.SetupTab;
            app.currTab = app.SetupTab;
            
            
        end

        % Button pushed function: setupAddtoTableButton
        function setupAddtoTableButtonPushed(app, event)
            % Get index value in cell array
            if (isempty(app.setupCurrLength))
               app.setupCurrLength = 1;
            else
               app.setupCurrLength = app.setupCurrLength + 1;
            end
            
            data = get(app.setupUITable, 'Data');
            
            C1 = app.setupChannel1DropDown.Value;
            C2 = app.setupChannel2DropDown.Value;
            
            if (C2 == 0)
                protocol = 'STICS';
            else
                protocol = 'STICCS';
            end
            
            batch = app.setupBatch;
            len = app.setupCurrLength;
            roiSize = app.setupROISizeEditField.Value;
            roiShift = app.setupROIShiftEditField.Value;
            toiSize = app.setupTOISizeEditField.Value;
            toiShift = app.setupTOIShiftEditField.Value;
            fitRad = app.setupFitRadiusEditField.Value;
            omegaT = num2str(app.setupOmegaThresholdEditField.Value);
                
            if (isempty(data))
                app.setupBatch = 1;
                batch = 1;
                
                app.setupUITable.Data = {false, len, batch, protocol,...
                   C1, app.setupChannel1DropDown.Items{C1}, C2, app.setupChannel2DropDown.Items{C2 + 1},...
                   roiSize, roiShift, toiSize, toiShift, fitRad, omegaT};
            else
                if (app.setupBatchCheckBox.Value == 0)
                    app.setupBatch = app.setupBatch + 1;
                end
                
                newRow = {false, len, batch, protocol,...
                   C1, app.setupChannel1DropDown.Items{C1}, C2, app.setupChannel2DropDown.Items{C2 + 1},...
                   roiSize, roiShift, toiSize, toiShift, fitRad, omegaT};
                data(end + 1, :) = newRow;
                set(app.setupUITable, 'Data', data);
            end
        end

        % Value changed function: setupOmegaThresholdEditField
        function setupOmegaThresholdEditFieldValueChanged(app, event)
            value = app.setupOmegaThresholdEditField.Value;
            
            if (value > (app.setupROISizeEditField.Value / 2))
                app.setupOmegaThresholdEditField.Value = app.setupROISizeEditField.Value / 2;
            end
        end

        % Value changed function: setupROISizeEditField
        function setupROISizeEditFieldValueChanged(app, event)
            value = app.setupROISizeEditField.Value;
            
            if ((value / 2) < app.setupOmegaThresholdEditField.Value)
                app.setupOmegaThresholdEditField.Value = app.setupROISizeEditField.Value / 2;
            end
        end

        % Cell edit callback: setupUITable
        function setupUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            % Check if file line not selected
            if (isempty(app.setupCurrLine))
                if (newData)
                    app.setupCurrLine = indices(1); 
                end
            else
                if (newData)
                    data = get(app.setupUITable, 'Data');
                    data(app.setupCurrLine, 1) = {false};
                    set(app.setupUITable, 'Data', data);
                
                    app.setupCurrLine = indices(1);
                else
                    app.setupCurrLine = [];
                end
            end
        end

        % Button pushed function: setupRemoveSelectedButton
        function setupRemoveSelectedButtonPushed(app, event)
            if (~isempty(app.setupCurrLine))
                data = get(app.setupUITable, 'Data');
                data(app.setupCurrLine, :) = [];
                set(app.setupUITable, 'Data', data);
                
                app.setupCurrLine = [];
            end
        end

        % Button pushed function: setupClearTableButton
        function setupClearTableButtonPushed(app, event)
            set(app.setupUITable, 'Data', {})
            
            app.setupCurrLength = [];          
            app.setupCurrLine = [];
        end

        % Button pushed function: setupLaunchStackViewerButton
        function setupLaunchStackViewerButtonPushed(app, event)
            if (~isempty(app.dataFileArray))
                if (~isempty(app.setupCurrLine))
                    data = get(app.setupUITable, 'Data');
                    ch1Pos = data{app.setupCurrLine, 5};
                    ch2Pos = data{app.setupCurrLine, 7};
                   
                    app.setupStack1 = app.dataFileArray(:,:,:,ch1Pos);
                    
                    if (~all(app.setupStack1))
                        app.setupStack1 = [];
                    end
                    
                    if (ch2Pos ~= 0)
                        app.setupStack2 = app.dataFileArray(:,:,:,ch2Pos);
                        
                        if (~all(app.setupStack2))
                            app.setupStack2 = [];
                        end
                    else
                        app.setupStack2 = [];
                    end
                else
                    app.setupStack1 = [];
                    app.setupStack2 = [];
                end
            end
            
            app.FileViewer = FilePreviewer(app, app.setupStack1, app.setupStack2, []);
        end

        % Button pushed function: setupBackButton
        function setupBackButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.PrepTab;
            app.currTab = app.PrepTab;
        end

        % Button pushed function: setupNextButton
        function setupNextButtonPushed(app, event)
            data = get(app.setupUITable, 'Data');
            
            if (isempty(data))
                % Error message
                msg = 'Error: Please add at least one run to the table.';
                msgtitle = 'Error: No Run';
                [icondata, iconcmap] = imread('Error_Icon.png');
                msgbox(msg, msgtitle, 'custom', icondata, iconcmap);
                return;
            end
                
            % Prompt user to see if they want to begin running STICS/STICCS
            prompt = 'Are you sure you want to continue? You cannot return to this page once STICS/STICCS running has begun.';
            dlgtitle = 'Confirm Continue';
            response = questdlg(prompt, dlgtitle, 'Yes', 'No', 'Yes');
            
            % Check if user wants to stay
            if (strcmp(response, 'No'))
                return;
            end
            
            app.mainTabGroup.SelectedTab = app.RunTab;
            app.currTab = app.RunTab;
            
            app.runNextButton.Enable = 'Off';
            
            add2queue(app);
            
            % BEGIN RUNNING PROCESS
            % Need to extract lines in cell array one by one
            % Loop through lines running process with values provided
            % 
            numFiles = size(data(:, 1)', 2);
            
            for index = 1:numFiles
                % Send file to table currently running of STICS
                app.runCurrentUITable.Data = {data{index, 2}, data{index, 3}};
                
                % Remove file from queue (since it is currently running)
                queueData = get(app.runQueueUITable, 'Data');
                queueData(1,:) = [];
                set(app.runQueueUITable, 'Data', queueData);
                
                
                app.runOpt.fileName{1, 1} = data{index,6}; 
                if ~strcmpi(data{index,8}, 'None')
                    app.runOpt.fileName{1, 2} = data{index,8}; 
                else
                    app.runOpt.fileName{1, 2} = data{index,6}; 
                end
                
                
                Ch1Pos = data{index, 5};
                Ch2Pos = data{index, 7};
                
                app.runOpt.filePath{1, 1} = app.dataFilePathArray(:,:,:,Ch1Pos);
                if Ch2Pos > 0
                    app.runOpt.filePath{1, 2} = app.dataFilePathArray(:,:,:,Ch2Pos);
                else
                    app.runOpt.filePath{1, 2} = app.dataFilePathArray(:,:,:,Ch1Pos);
                end
                
                app.runOpt.ROIsize = data{index, 9};
                app.runOpt.ROIshift = data{index, 10};
                app.runOpt.TOIsize = data{index, 11};
                app.runOpt.TOIshift = data{index, 12};
                app.runOpt.fitRadius = data{index, 13};
                app.runOpt.omegaThreshold = str2double(data{index, 14});
                
                app.runOpt.tauLimit = app.immobileTauLimitEditField.Value;
                app.runOpt.threshVector = app.immobileThreshVectorEditField.Value;
                
                metaData = get(app.dataMetadataUITable, 'Data');
                
                if (metaData{6, 2} ~= 0)
                    app.runOpt.pixelSize = metaData{6, 2};
                end
                
                if (metaData{7, 2} ~= 0)
                    app.runOpt.timeFrame = metaData{7, 2};
                end
                
                immobileFilter = getFiltering(app);
                app.runOpt.filtering = immobileFilter;
                
                if (strcmp(immobileFilter, 'MovingAverage'))
                    app.runOpt.MoveAverage = app.immobileMovingAverageEditField.Value;
                end

                Run = data{index, 2};
                Batch = data{index, 3};
                
                Stack1 = app.dataFileArray(:,:,:,Ch1Pos);
                
                % Check for STICCS
                if (Ch2Pos ~= 0)
                    Stack2 = app.dataFileArray(:,:,:,Ch2Pos);
                    
                    [app.runVelocityMap, app.runPosition_x, app.runPosition_y, app.runPosition_t, app.runOpt] = sticcs_vectormapping(Stack1, Stack2, app.runOpt);
                    storeVars(app, index, Run, Batch);
                    
                % STICS   
                else
                    [app.runVelocityMap, app.runPosition_x, app.runPosition_y, app.runPosition_t, app.runOpt] = stics_vectormapping(Stack1, app.runOpt);
                    storeVars(app, Run, Batch);
                end
                
                % Send finished file to finished table
                currentData = get(app.runCurrentUITable, 'Data');
                finishedData = get(app.runFinishedUITable, 'Data');
                
                if (isempty(finishedData))
                    app.runFinishedUITable.Data = currentData;
                else
                    newRow = currentData;
                    finishedData(end + 1, :) = newRow;
                    set(app.runFinishedUITable, 'Data', finishedData);
                end
            end
            
            app.runCurrentUITable.Data = [];
            
            app.runNextButton.Enable = 'On';
        end

        % Button pushed function: runNextButton
        function runNextButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.ExportTab;
            app.currTab = app.ExportTab;
            
            add2export(app);
        end

        % Button pushed function: exportBrowseRootButton
        function exportBrowseRootButtonPushed(app, event)
            app.exportRootLamp.Color = [1.0 1.0 0.0];
            
            [rootPath] = uigetdir();
            
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            % Check if user clicked cancel
            if (rootPath == 0)
                if (isempty(app.exportPath))
                    app.exportRootLamp.Color = [1.0 0.0 0.0];
                else
                    app.exportRootLamp.Color = [0.0 1.0 0.0];
                end
                
                return
            end
            
            app.exportPath = rootPath;
            app.exportOutputFolderEditField.Value = rootPath;
            app.exportRootLamp.Color = [0.0 1.0 0.0];
        end

        % Cell edit callback: exportUITable
        function exportUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            % Check if file line not selected
            if (isempty(app.exportCurrLine))
                if (newData)  
                    app.exportCurrLine = indices(1);
                    
                    if (~isempty(app.exportDoneArray) && app.exportDoneArray(app.exportCurrLine) == 1)
                        return;
                    end 
                    
                    data = get(app.exportUITable, 'Data');
                    
                    app.exportRun = data(app.exportCurrLine, 1);
                    app.exportBatch = data(app.exportCurrLine, 2);
                end
            else
                if (newData)
                    data = get(app.exportUITable, 'Data');
                    data(app.exportCurrLine, app.exportCurrCol) = {false};
                    set(app.exportUITable, 'Data', data);
                
                    app.exportCurrLine = indices(1);
                    
                    if (~isempty(app.exportDoneArray) && app.exportDoneArray(app.exportCurrLine) == 1)
                        return;
                    end
                    
                    data = get(app.exportUITable, 'Data');
                    
                    app.exportRun = data(app.exportCurrLine, 1);
                    app.exportBatch = data(app.exportCurrLine, 2);
                else
                    app.exportCurrLine = [];
                end
            end
            
        end

        % Button pushed function: exportLaunchICSExplorerButton
        function exportLaunchICSExplorerButtonPushed(app, event)
            setupData = get(app.setupUITable, 'Data');
            ch1Pos = setupData{app.exportCurrLine, 5};
            ch2Pos = setupData{app.exportCurrLine, 7};
            
            % Check for only 1 stack
            if (ch2Pos == 0)
                stack = app.dataFileArray(:,:,:,ch1Pos);
            else
                stack = [];
            end
            
            data = get(app.exportUITable, 'Data');
            run = string(data{app.exportCurrLine, 1});
            batch = string(data{app.exportCurrLine, 2});
            index = strcat("results", run, batch);
            
            velocityMap = app.varsStruct.(index){1};
            position_x = app.varsStruct.(index){2};
            position_y = app.varsStruct.(index){3};
            position_t = app.varsStruct.(index){4};
            opt = app.varsStruct.(index){5};
            
            app.ICSExplorerApp = ICS_Explorer(app, stack, velocityMap, ...
                position_x, position_y, position_t, opt);
        end

        % Button pushed function: exportSaveButton
        function exportSaveButtonPushed(app, event)
            % Check if line selected
            if (isempty(app.exportCurrLine))
                % Print error message
                msg = 'Error: Please select a row before saving.';
                msgtitle = 'Error: No file selected';
                [icondata, iconcmap] = imread('Error_Icon.png');
                msgbox(msg, msgtitle, 'custom', icondata, iconcmap);
                return;
            end
            
            % Check if file already saved
            if (~isempty(app.exportDoneArray))
                if (app.exportDoneArray(app.exportCurrLine) == 1)
                    return;
                end
            end
            
            % When save clicked, update row as DONE
            app.exportDoneArray(app.exportCurrLine) = 1;
            
            % Get index in struct to locate data
            data = get(app.exportUITable, 'Data');
            run = string(data{app.exportCurrLine, 1});
            batch = string(data{app.exportCurrLine, 2});
            index = strcat("results", run, batch);
            
            % Extract individual components from cell array
            velocityMap = app.varsStruct.(index){1};
            position_x = app.varsStruct.(index){2};
            position_y = app.varsStruct.(index){3};
            position_t = app.varsStruct.(index){4};
            opt = app.varsStruct.(index){5};
            
            % Check for no Axis Title
            if (isempty(app.exportAxisTitleEditField.Value))
                % Print error message
                msg = 'Error: Please add an axis title name.';
                msgtitle = 'Error: No axis title';
                [icondata, iconcmap] = imread('Error_Icon.png');
                msgbox(msg, msgtitle, 'custom', icondata, iconcmap);
                return;
            else
                opt.axisTitle = app.exportAxisTitleEditField.Value;
            end
            
            % Check for no Output Name
            if (isempty(app.exportOutputNameEditField.Value))
                % Print error message
                msg = 'Error: Please add an output file name.';
                msgtitle = 'Error: No output file name';
                [icondata, iconcmap] = imread('Error_Icon.png');
                msgbox(msg, msgtitle, 'custom', icondata, iconcmap);
                return;
            else
                opt.outputName = app.exportOutputNameEditField.Value;
            end
            
            % Check for no Output Folder
            if (isempty(app.exportOutputFolderEditField.Value))
                % Print error message
                msg = 'Error: Please choose an output folder.';
                msgtitle = 'Error: No output folder';
                [icondata, iconcmap] = imread('Error_Icon.png');
                msgbox(msg, msgtitle, 'custom', icondata, iconcmap);
                return;
            else
                opt.path = app.exportOutputFolderEditField.Value;
            end
            
            
            % TODO: FIGURE OUT HOW THE IMAGE SERIES AND MOVIE ARE MADE
            %
            %
            
            if (strcmp(app.exportImageFormatDropDown.Value, 'None'))
                opt.exportimages = 'n';
            else
                opt.imagesformat = lower(app.exportImageFormatDropDown.Value);
            end
            
            if (strcmp(app.exportMovieFormatDropDown.Value, 'None'))
                opt.exportimages = 'n';
            else
                opt.movieformat = lower(app.exportMovieFormatDropDown.Value);
            end

            if ~isfield(opt, 'axisTitle'), opt.axisTitle = '200926-01'; end 
            if ~isfield(opt, 'outputName'), opt.outputName = '200926-01_201005'; end 
            if ~isfield(opt, 'path'), opt.path ='D:\Data\200926'; end
            if ~isfield(opt, 'exportimages'), opt.exportimages ='y'; end %'y' if you want export a pdf of every vector map, 'n' if you do not
            if ~isfield(opt, 'imagesformat'), opt.imagesformat ='png'; end % 'png' or 'pdf' images...can add other option (formats) in plotSingleVectorMap code
            if ~isfield(opt, 'movieformat'), opt.movieformat ='mp4'; end % movie format can be avi, jpeg or mp4
            
            save([opt.path filesep 'VelocityMap' opt.outputName, '.mat'], 'velocityMap');
            save([opt.path filesep 'VelocityMap' opt.outputName '.mat'], 'position_x','-append');
            save([opt.path filesep 'VelocityMap' opt.outputName '.mat'], 'position_y','-append');
            save([opt.path filesep 'VelocityMap' opt.outputName '.mat'], 'position_t','-append');
            save([opt.path filesep 'VelocityMap' opt.outputName '.mat'], 'opt','-append');
            
            data(app.exportCurrLine, 3) = {false};
            set(app.exportUITable, 'Data', data);
            
            doneRow(app, app.exportCurrLine);
            
            % Reset vars for next file saved
            app.exportAxisTitleEditField.Value = "";
            app.exportOutputNameEditField.Value = "";
            app.exportOutputFolderEditField.Value = "";
            app.exportRootLamp.Color = [1.0 0.0 0.0];
            app.exportImageFormatDropDown.Value = 'None';
            app.exportMovieFormatDropDown.Value = 'None';
        end

        % Button pushed function: exportDoneButton
        function exportDoneButtonPushed(app, event)
            % Check for no files saved (safety measure)
            if (isempty(app.exportDoneArray))
                % Print error message
                msg = 'Warning: You have not saved any output files.';
                msgtitle = 'Warning: No files saved';
                [icondata, iconcmap] = imread('Warning_Icon.png');
                msgbox(msg, msgtitle, 'custom', icondata, iconcmap);
                return; 
            end
            
            delete(app.STICS_Pro);
            delete(app.ICSExplorerApp);
            delete(app.FileViewer);
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.STICS_Pro);
            delete(app.ICSExplorerApp);
            delete(app.FileViewer);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.LoadDataMenu = uimenu(app.File);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenuSelected, true);
            app.LoadDataMenu.Separator = 'on';
            app.LoadDataMenu.Text = 'Load Data';
            app.ExitProgramMenu = uimenu(app.File);
            app.ExitProgramMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitProgramMenuSelected, true);
            app.ExitProgramMenu.Separator = 'on';
            app.ExitProgramMenu.Text = 'Exit Program';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.ProfileMenu = uimenu(app.SettingsMenu);
            app.ProfileMenu.MenuSelectedFcn = createCallbackFcn(app, @ProfileMenuSelected, true);
            app.ProfileMenu.Separator = 'on';
            app.ProfileMenu.Text = 'Profile';
            app.WorkspaceMenu = uimenu(app.SettingsMenu);
            app.WorkspaceMenu.Separator = 'on';
            app.WorkspaceMenu.Text = 'Workspace';
            app.ThemeMenu = uimenu(app.SettingsMenu);
            app.ThemeMenu.Separator = 'on';
            app.ThemeMenu.Text = 'Theme';
            app.OriginalMenu = uimenu(app.ThemeMenu);
            app.OriginalMenu.Separator = 'on';
            app.OriginalMenu.Text = 'Original';
            app.LightModeMenu = uimenu(app.ThemeMenu);
            app.LightModeMenu.Separator = 'on';
            app.LightModeMenu.Text = 'Light Mode';
            app.DarkModeMenu = uimenu(app.ThemeMenu);
            app.DarkModeMenu.MenuSelectedFcn = createCallbackFcn(app, @DarkModeMenuSelected, true);
            app.DarkModeMenu.Separator = 'on';
            app.DarkModeMenu.Text = 'Dark Mode';
            app.NightShiftMenu = uimenu(app.ThemeMenu);
            app.NightShiftMenu.Separator = 'on';
            app.NightShiftMenu.Text = 'Night Shift';
            app.RandomMenu = uimenu(app.ThemeMenu);
            app.RandomMenu.Separator = 'on';
            app.RandomMenu.Text = 'Random';
            app.About = uimenu(app.UIFigure);
            app.About.Text = 'About';
            app.HelpMenu = uimenu(app.About);
            app.HelpMenu.Separator = 'on';
            app.HelpMenu.Text = 'Help';
            app.TutorialMenu = uimenu(app.About);
            app.TutorialMenu.Separator = 'on';
            app.TutorialMenu.Text = 'Tutorial';
            app.CreditsMenu = uimenu(app.About);
            app.CreditsMenu.Separator = 'on';
            app.CreditsMenu.Text = 'Credits';
            app.LicenseMenu = uimenu(app.About);
            app.LicenseMenu.Separator = 'on';
            app.LicenseMenu.Text = 'License';
            app.AboutMenu = uimenu(app.About);
            app.AboutMenu.Separator = 'on';
            app.AboutMenu.Text = 'About';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.UpdatePushTool = uipushtool(app.Toolbar);
            app.UpdatePushTool.Icon = 'Push_Icon.png';
            app.SyncPushTool = uipushtool(app.Toolbar);
            app.SyncPushTool.Icon = 'Sync_Icon.png';
            app.RestartPushTool = uipushtool(app.Toolbar);
            app.RestartPushTool.Icon = 'Restart_Icon.png';
            app.mainTabGroup = uitabgroup(app.UIFigure);
            app.mainTabGroup.SelectionChangedFcn = createCallbackFcn(app, @mainTabGroupSelectionChanged, true);
            app.WelcomeTab = uitab(app.mainTabGroup);
            app.WelcomeTab.Title = 'Welcome';
            app.welcomeTitle = uilabel(app.WelcomeTab);
            app.welcomeTitle.Text = 'Welcome!';
            app.instructionsInfo = uilabel(app.WelcomeTab);
            app.instructionsInfo.Text = {'This application implements the Spatio-Temporal Image Correlation Spectroscopy (STICS),  '; 'Spatio-Temporal Image Cross-Correlation Spectroscopy (STICCS), Temporal Image Correlation '; 'Spectroscopy (TICS), and Image Correlation Spectroscopy (ICS) algorithms.'; ''};
            app.UsageLabel = uilabel(app.WelcomeTab);
            app.UsageLabel.Text = 'Usage:';
            app.EachcorrelationspectroscopyalgorithmcontainsLabel = uilabel(app.WelcomeTab);
            app.EachcorrelationspectroscopyalgorithmcontainsLabel.Text = {'- Each correlation spectroscopy algorithms contain pre-set values for all the variables'; '- You can hover over the text/numeric field of each option to see a tooltip explaining how each variable'; 'works in the algorithm'};
            app.STICSProButton = uibutton(app.WelcomeTab, 'push');
            app.STICSProButton.ButtonPushedFcn = createCallbackFcn(app, @STICSProButtonPushed, true);
            app.STICSProButton.Text = 'STICS Pro';
            app.JumpLabel = uilabel(app.WelcomeTab);
            app.JumpLabel.Text = 'Quick Jump to Applications';
            app.StackViewerButton = uibutton(app.WelcomeTab, 'push');
            app.StackViewerButton.ButtonPushedFcn = createCallbackFcn(app, @StackViewerButtonPushed, true);
            app.StackViewerButton.Text = 'Stack Viewer';
            app.ICSExplorerButton = uibutton(app.WelcomeTab, 'push');
            app.ICSExplorerButton.ButtonPushedFcn = createCallbackFcn(app, @ICSExplorerButtonPushed, true);
            app.ICSExplorerButton.Text = 'ICS Explorer';
            app.welcomeNextButton = uibutton(app.WelcomeTab, 'push');
            app.welcomeNextButton.ButtonPushedFcn = createCallbackFcn(app, @welcomeNextButtonPushed, true);
            app.welcomeNextButton.Text = 'Next';
            app.StartTab = uitab(app.mainTabGroup);
            app.StartTab.Title = 'Start';
            app.DocsButton = uibutton(app.StartTab, 'push');
            app.DocsButton.Text = 'Docs';
            app.PapersButton = uibutton(app.StartTab, 'push');
            app.PapersButton.Text = 'Papers';
            app.startNextButton = uibutton(app.StartTab, 'push');
            app.startNextButton.ButtonPushedFcn = createCallbackFcn(app, @startNextButtonPushed, true);
            app.startNextButton.Text = 'Next';
            app.startBackButton = uibutton(app.StartTab, 'push');
            app.startBackButton.ButtonPushedFcn = createCallbackFcn(app, @startBackButtonPushed, true);
            app.startBackButton.Text = 'Back';
            app.RemoveButton = uibutton(app.StartTab, 'push');
            app.RemoveButton.Text = '<< Remove';
            app.DoneButton = uibutton(app.StartTab, 'push');
            app.DoneButton.Text = 'Done';
            app.AddButton = uibutton(app.StartTab, 'push');
            app.AddButton.Text = 'Add >>';
            app.SelectButton = uibutton(app.StartTab, 'push');
            app.SelectButton.Text = 'Select';
            app.DeleteButton = uibutton(app.StartTab, 'push');
            app.DeleteButton.Text = 'Delete';
            app.AvailableWorkflowsLabel = uilabel(app.StartTab);
            app.AvailableWorkflowsLabel.Text = 'Available Workflows';
            app.AvailableStepsLabel = uilabel(app.StartTab);
            app.AvailableStepsLabel.Text = 'Available Steps';
            app.Tree2 = uitree(app.StartTab, 'checkbox');
            app.LoadingNode = uitreenode(app.Tree2);
            app.LoadingNode.Text = 'Loading';
            app.Node2 = uitreenode(app.LoadingNode);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.LoadingNode);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.LoadingNode);
            app.Node4.Text = 'Node4';
            app.PreprocessingNode = uitreenode(app.Tree2);
            app.PreprocessingNode.Text = 'Preprocessing';
            app.SetupNode = uitreenode(app.Tree2);
            app.SetupNode.Text = 'Setup';
            app.RunNode = uitreenode(app.Tree2);
            app.RunNode.Text = 'Run';
            app.AnalysisNode = uitreenode(app.Tree2);
            app.AnalysisNode.Text = 'Analysis';
            app.ExportNode = uitreenode(app.Tree2);
            app.ExportNode.Text = 'Export';
            app.Tree = uitree(app.StartTab);
            app.PresetsNode = uitreenode(app.Tree);
            app.PresetsNode.Text = 'Presets';
            app.OneChannelNode = uitreenode(app.PresetsNode);
            app.OneChannelNode.Text = 'One Channel';
            app.ICSNode = uitreenode(app.OneChannelNode);
            app.ICSNode.Text = 'ICS';
            app.TICSNode = uitreenode(app.OneChannelNode);
            app.TICSNode.Text = 'TICS';
            app.STICSNode = uitreenode(app.OneChannelNode);
            app.STICSNode.Text = 'STICS';
            app.TwoChannelsNode = uitreenode(app.PresetsNode);
            app.TwoChannelsNode.Text = 'Two Channels';
            app.ICCSNode = uitreenode(app.TwoChannelsNode);
            app.ICCSNode.Text = 'ICCS';
            app.TICCSNode = uitreenode(app.TwoChannelsNode);
            app.TICCSNode.Text = 'TICCS';
            app.STICCSNode = uitreenode(app.TwoChannelsNode);
            app.STICCSNode.Text = 'STICCS';
            app.ThreeChannelsNode = uitreenode(app.PresetsNode);
            app.ThreeChannelsNode.Text = 'Three Channels';
            app.FRETSTICSNode = uitreenode(app.ThreeChannelsNode);
            app.FRETSTICSNode.Text = 'FRET-STICS';
            app.CustomNode = uitreenode(app.Tree);
            app.CustomNode.Text = 'Custom';
            app.RadialSTICSNode = uitreenode(app.CustomNode);
            app.RadialSTICSNode.Text = 'Radial STICS';
            app.CurrentWorkflowListBox = uilistbox(app.StartTab);
            app.CurrentWorkflowListBoxLabel = uilabel(app.StartTab);
            app.CurrentWorkflowListBoxLabel.Text = 'Current Workflow';
            app.LoadButton = uibutton(app.StartTab, 'push');
            app.LoadButton.Text = 'Load';
            app.NewButton_2 = uibutton(app.StartTab, 'push');
            app.NewButton_2.Text = 'New';
            app.SaveButton = uibutton(app.StartTab, 'push');
            app.SaveButton.Text = 'Save';
            app.DataTab = uitab(app.mainTabGroup);
            app.DataTab.Title = 'Data';
            app.dataSelectFileButton = uibutton(app.DataTab, 'push');
            app.dataSelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @dataSelectFileButtonPushed, true);
            app.dataSelectFileButton.Text = 'Select File';
            app.dataSelectFileLamp = uilamp(app.DataTab);
            app.dataFileUITable = uitable(app.DataTab);
            app.dataFileUITable.ColumnName = {'File'; 'Select File'};
            app.dataFileUITable.RowName = {};
            app.dataFileUITable.CellEditCallback = createCallbackFcn(app, @dataFileUITableCellEdit, true);
            app.dataSelectedFilesLabel = uilabel(app.DataTab);
            app.dataSelectedFilesLabel.Text = {'Selected Files'; ''};
            app.dataAddtoTableButton = uibutton(app.DataTab, 'push');
            app.dataAddtoTableButton.ButtonPushedFcn = createCallbackFcn(app, @dataAddtoTableButtonPushed, true);
            app.dataAddtoTableButton.Text = 'Add to Table';
            app.dataClearCurrentButton = uibutton(app.DataTab, 'push');
            app.dataClearCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @dataClearCurrentButtonPushed, true);
            app.dataClearCurrentButton.Text = 'Clear Current';
            app.dataClearTableButton = uibutton(app.DataTab, 'push');
            app.dataClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @dataClearTableButtonPushed, true);
            app.dataClearTableButton.Text = 'Clear Table';
            app.dataMetadataUITable = uitable(app.DataTab);
            app.dataMetadataUITable.ColumnName = {'Data Type'; 'Value'};
            app.dataMetadataUITable.RowName = {};
            app.dataMetadataUITable.CellEditCallback = createCallbackFcn(app, @dataMetadataUITableCellEdit, true);
            app.dataMetadataLabel = uilabel(app.DataTab);
            app.dataMetadataLabel.Text = 'Metadata';
            app.dataClearMetadataButton = uibutton(app.DataTab, 'push');
            app.dataClearMetadataButton.ButtonPushedFcn = createCallbackFcn(app, @dataClearMetadataButtonPushed, true);
            app.dataClearMetadataButton.Text = 'Clear Metadata';
            app.dataLaunchStackViewerButton = uibutton(app.DataTab, 'push');
            app.dataLaunchStackViewerButton.ButtonPushedFcn = createCallbackFcn(app, @dataLaunchStackViewerButtonPushed, true);
            app.dataLaunchStackViewerButton.Text = 'Launch Stack Viewer';
            app.dataBackButton = uibutton(app.DataTab, 'push');
            app.dataBackButton.ButtonPushedFcn = createCallbackFcn(app, @dataBackButtonPushed, true);
            app.dataBackButton.Text = 'Back';
            app.dataNextButton = uibutton(app.DataTab, 'push');
            app.dataNextButton.ButtonPushedFcn = createCallbackFcn(app, @dataNextButtonPushed, true);
            app.dataNextButton.Text = 'Next';
            app.dataRemoveSelectedButton = uibutton(app.DataTab, 'push');
            app.dataRemoveSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @dataRemoveSelectedButtonPushed, true);
            app.dataRemoveSelectedButton.Text = 'Remove Selected';
            app.PrepTab = uitab(app.mainTabGroup);
            app.PrepTab.Title = 'Prep';
            app.prepTabGroup = uitabgroup(app.PrepTab);
            app.ImmobileTab = uitab(app.prepTabGroup);
            app.ImmobileTab.Title = 'Immobile';
            app.immobileRemovalSettingsLabel = uilabel(app.ImmobileTab);
            app.immobileRemovalSettingsLabel.Tooltip = {'Immobile filtering permits the measurement of the flowing population by removing slowly moving or imobile structures from the image series prior to STICS analysis.'};
            app.immobileRemovalSettingsLabel.Text = 'Immobile Removal Settings';
            app.immobileDropDown = uidropdown(app.ImmobileTab);
            app.immobileDropDown.Items = {'None', 'FourierWhole', 'MovingAverage', 'butterIIR'};
            app.immobileDropDown.ValueChangedFcn = createCallbackFcn(app, @immobileDropDownValueChanged, true);
            app.immobileDropDown.Value = 'None';
            app.immobileMovingAverageEditField = uieditfield(app.ImmobileTab, 'numeric');
            app.immobileMovingAverageEditField.Enable = 'off';
            app.immobileOutlierCriteriaLabel = uilabel(app.ImmobileTab);
            app.immobileOutlierCriteriaLabel.Text = 'Outlier Criteria';
            app.TauLimitEditFieldLabel = uilabel(app.ImmobileTab);
            app.TauLimitEditFieldLabel.Text = 'Tau Limit';
            app.immobileTauLimitEditField = uieditfield(app.ImmobileTab, 'numeric');
            app.immobileTauLimitEditField.Limits = [0 Inf];
            app.immobileTauLimitEditField.Tooltip = {''};
            app.immobileTauLimitEditField.Value = 5;
            app.ThreshVectorEditFieldLabel = uilabel(app.ImmobileTab);
            app.ThreshVectorEditFieldLabel.Text = 'Thresh Vector';
            app.immobileThreshVectorEditField = uieditfield(app.ImmobileTab, 'numeric');
            app.immobileThreshVectorEditField.Limits = [0 Inf];
            app.immobileThreshVectorEditField.Tooltip = {'This threshold marks some vectors as outliiers if they deviate by a certain amount from their neighbours. It is used to assign a ''good'' vector according to its neighborhood.'};
            app.immobileThreshVectorEditField.Value = 2;
            app.CropTab = uitab(app.prepTabGroup);
            app.CropTab.Title = 'Crop';
            app.Ch1UIAxes = uiaxes(app.CropTab);
            app.Ch1UIAxes.XTick = [];
            app.Ch1UIAxes.YTick = [];
            app.Ch1FOVUIAxes = uiaxes(app.CropTab);
            app.Ch1FOVUIAxes.XTick = [];
            app.Ch1FOVUIAxes.YTick = [];
            app.cropNextButton = uibutton(app.CropTab, 'push');
            app.cropNextButton.Text = 'Next';
            app.FileLabel = uilabel(app.CropTab);
            app.FileLabel.Text = 'File';
            app.cropFileDropDown = uidropdown(app.CropTab);
            app.cropFileDropDown.Items = {};
            app.cropFileDropDown.Value = {};
            app.FOVSizeEditField = uieditfield(app.CropTab, 'numeric');
            app.XPositionEditField = uieditfield(app.CropTab, 'numeric');
            app.YPositionEditField = uieditfield(app.CropTab, 'numeric');
            app.FOVSizeSliderLabel = uilabel(app.CropTab);
            app.FOVSizeSliderLabel.Text = 'FOV Size';
            app.FOVSizeSlider = uislider(app.CropTab);
            app.XPositionSliderLabel = uilabel(app.CropTab);
            app.XPositionSliderLabel.Text = 'X Position';
            app.XPositionSlider = uislider(app.CropTab);
            app.YPositionSliderLabel = uilabel(app.CropTab);
            app.YPositionSliderLabel.Text = 'Y Position';
            app.YPositionSlider = uislider(app.CropTab);
            app.prepBackButton = uibutton(app.PrepTab, 'push');
            app.prepBackButton.ButtonPushedFcn = createCallbackFcn(app, @prepBackButtonPushed, true);
            app.prepBackButton.Text = 'Back';
            app.prepNextButton = uibutton(app.PrepTab, 'push');
            app.prepNextButton.ButtonPushedFcn = createCallbackFcn(app, @prepNextButtonPushed, true);
            app.prepNextButton.Text = 'Next';
            app.SetupTab = uitab(app.mainTabGroup);
            app.SetupTab.Title = 'Setup';
            app.setupUITable = uitable(app.SetupTab);
            app.setupUITable.ColumnName = {'FileSelected'; 'Run'; 'Batch'; 'Protocol'; 'Ch1Pos'; 'Channel 1'; 'Ch2Pos'; 'Channel 2'; 'ROIsize'; 'ROIshift'; 'TOIsize'; 'TOIshift'; 'FitRadius'; 'OmegaThreshold'};
            app.setupUITable.RowName = {};
            app.setupUITable.CellEditCallback = createCallbackFcn(app, @setupUITableCellEdit, true);
            app.ROISizeEditFieldLabel = uilabel(app.SetupTab);
            app.ROISizeEditFieldLabel.Text = 'ROI Size';
            app.setupROISizeEditField = uieditfield(app.SetupTab, 'numeric');
            app.setupROISizeEditField.Limits = [1 Inf];
            app.setupROISizeEditField.ValueChangedFcn = createCallbackFcn(app, @setupROISizeEditFieldValueChanged, true);
            app.setupROISizeEditField.Value = 1;
            app.ROIShiftEditFieldLabel = uilabel(app.SetupTab);
            app.ROIShiftEditFieldLabel.Text = 'ROI Shift';
            app.setupROIShiftEditField = uieditfield(app.SetupTab, 'numeric');
            app.setupROIShiftEditField.Limits = [1 Inf];
            app.setupROIShiftEditField.Value = 1;
            app.TOISizeEditFieldLabel = uilabel(app.SetupTab);
            app.TOISizeEditFieldLabel.Text = 'TOI Size';
            app.setupTOISizeEditField = uieditfield(app.SetupTab, 'numeric');
            app.setupTOISizeEditField.Limits = [1 Inf];
            app.setupTOISizeEditField.Value = 1;
            app.TOIShiftEditFieldLabel = uilabel(app.SetupTab);
            app.TOIShiftEditFieldLabel.Text = 'TOI Shift';
            app.setupTOIShiftEditField = uieditfield(app.SetupTab, 'numeric');
            app.setupTOIShiftEditField.Limits = [0 Inf];
            app.setupTOIShiftEditField.Value = 1;
            app.FitRadiusEditFieldLabel = uilabel(app.SetupTab);
            app.FitRadiusEditFieldLabel.Text = 'Fit Radius';
            app.setupFitRadiusEditField = uieditfield(app.SetupTab, 'numeric');
            app.setupFitRadiusEditField.Limits = [1 Inf];
            app.setupFitRadiusEditField.Value = 1;
            app.setupClearTableButton = uibutton(app.SetupTab, 'push');
            app.setupClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @setupClearTableButtonPushed, true);
            app.setupClearTableButton.Text = 'Clear Table';
            app.setupBackButton = uibutton(app.SetupTab, 'push');
            app.setupBackButton.ButtonPushedFcn = createCallbackFcn(app, @setupBackButtonPushed, true);
            app.setupBackButton.Text = 'Back';
            app.setupNextButton = uibutton(app.SetupTab, 'push');
            app.setupNextButton.ButtonPushedFcn = createCallbackFcn(app, @setupNextButtonPushed, true);
            app.setupNextButton.Text = 'Next';
            app.setupRemoveSelectedButton = uibutton(app.SetupTab, 'push');
            app.setupRemoveSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @setupRemoveSelectedButtonPushed, true);
            app.setupRemoveSelectedButton.Text = 'Remove Selected';
            app.Channel1Label = uilabel(app.SetupTab);
            app.Channel1Label.Text = 'Channel 1';
            app.setupChannel1DropDown = uidropdown(app.SetupTab);
            app.setupChannel1DropDown.Items = {};
            app.setupChannel1DropDown.Value = {};
            app.Channel2Label = uilabel(app.SetupTab);
            app.Channel2Label.Text = 'Channel 2';
            app.setupChannel2DropDown = uidropdown(app.SetupTab);
            app.setupChannel2DropDown.Items = {};
            app.setupChannel2DropDown.Value = {};
            app.setupMessageLabel = uilabel(app.SetupTab);
            app.setupMessageLabel.Text = '';
            app.setupAddtoTableButton = uibutton(app.SetupTab, 'push');
            app.setupAddtoTableButton.ButtonPushedFcn = createCallbackFcn(app, @setupAddtoTableButtonPushed, true);
            app.setupAddtoTableButton.Text = 'Add to Table';
            app.setupLaunchStackViewerButton = uibutton(app.SetupTab, 'push');
            app.setupLaunchStackViewerButton.ButtonPushedFcn = createCallbackFcn(app, @setupLaunchStackViewerButtonPushed, true);
            app.setupLaunchStackViewerButton.Text = 'Launch Stack Viewer';
            app.setupBatchCheckBox = uicheckbox(app.SetupTab);
            app.setupBatchCheckBox.Text = 'Batch';
            app.OmegaThresholdLabel = uilabel(app.SetupTab);
            app.OmegaThresholdLabel.Text = 'Omega Threshold';
            app.setupOmegaThresholdEditField = uieditfield(app.SetupTab, 'numeric');
            app.setupOmegaThresholdEditField.Limits = [0 Inf];
            app.setupOmegaThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @setupOmegaThresholdEditFieldValueChanged, true);
            app.setupOmegaThresholdEditField.Value = 0.5;
            app.RunTab = uitab(app.mainTabGroup);
            app.RunTab.Title = 'Run';
            app.runNextButton = uibutton(app.RunTab, 'push');
            app.runNextButton.ButtonPushedFcn = createCallbackFcn(app, @runNextButtonPushed, true);
            app.runNextButton.Text = 'Next';
            app.runQueueUITable = uitable(app.RunTab);
            app.runQueueUITable.ColumnName = {'Run'; 'Batch'};
            app.runQueueUITable.RowName = {};
            app.runQueueLabel = uilabel(app.RunTab);
            app.runQueueLabel.Text = 'Queue';
            app.runCurrentUITable = uitable(app.RunTab);
            app.runCurrentUITable.ColumnName = {'Run'; 'Batch'};
            app.runCurrentUITable.RowName = {};
            app.runCurrentLabel = uilabel(app.RunTab);
            app.runCurrentLabel.Text = 'Currently Running';
            app.runFinishedUITable = uitable(app.RunTab);
            app.runFinishedUITable.ColumnName = {'Run'; 'Batch'};
            app.runFinishedUITable.RowName = {};
            app.runFinishedLabel = uilabel(app.RunTab);
            app.runFinishedLabel.Text = 'Finished';
            app.ExportTab = uitab(app.mainTabGroup);
            app.ExportTab.Title = 'Export';
            app.exportDoneButton = uibutton(app.ExportTab, 'push');
            app.exportDoneButton.ButtonPushedFcn = createCallbackFcn(app, @exportDoneButtonPushed, true);
            app.exportDoneButton.Text = 'Done';
            app.exportBrowseRootButton = uibutton(app.ExportTab, 'push');
            app.exportBrowseRootButton.ButtonPushedFcn = createCallbackFcn(app, @exportBrowseRootButtonPushed, true);
            app.exportBrowseRootButton.Tooltip = {'This button allows you to select the folder where you want to save the results. It will automatically set the working directory.'};
            app.exportBrowseRootButton.Text = 'Browse Root';
            app.AxisTitleEditFieldLabel = uilabel(app.ExportTab);
            app.AxisTitleEditFieldLabel.Text = 'Axis Title';
            app.exportAxisTitleEditField = uieditfield(app.ExportTab, 'text');
            app.exportAxisTitleEditField.Tooltip = {'A string defining the title displayed in the vector map images and movies. '};
            app.exportAxisTitleEditField.Placeholder = '(e.x Vector Map of ____)';
            app.OutputFolderEditFieldLabel = uilabel(app.ExportTab);
            app.OutputFolderEditFieldLabel.Text = 'Output Folder';
            app.exportOutputFolderEditField = uieditfield(app.ExportTab, 'text');
            app.exportOutputFolderEditField.Editable = 'off';
            app.exportOutputFolderEditField.Tooltip = {'A string defining the output directory.'};
            app.exportOutputFolderEditField.Placeholder = '(e.x /Users/michael/Desktop/)';
            app.OutputNameEditFieldLabel = uilabel(app.ExportTab);
            app.OutputNameEditFieldLabel.Text = 'Output Name';
            app.exportOutputNameEditField = uieditfield(app.ExportTab, 'text');
            app.exportOutputNameEditField.Tooltip = {'A string used in naming the STICS output files.'};
            app.exportOutputNameEditField.Placeholder = '(e.x Lateral Diffusion of CFTR)';
            app.exportExportOptionsLabel = uilabel(app.ExportTab);
            app.exportExportOptionsLabel.Text = 'Export Options';
            app.ImageFormatDropDownLabel = uilabel(app.ExportTab);
            app.ImageFormatDropDownLabel.Text = 'Image Format';
            app.exportImageFormatDropDown = uidropdown(app.ExportTab);
            app.exportImageFormatDropDown.Items = {'None', 'PNG', 'PDF', 'JPEG'};
            app.exportImageFormatDropDown.Value = 'None';
            app.MovieFormatDropDownLabel = uilabel(app.ExportTab);
            app.MovieFormatDropDownLabel.Text = 'Movie Format';
            app.exportMovieFormatDropDown = uidropdown(app.ExportTab);
            app.exportMovieFormatDropDown.Items = {'None', 'MP4', 'AVI', 'JPEG', ''};
            app.exportMovieFormatDropDown.Value = 'None';
            app.exportRootLamp = uilamp(app.ExportTab);
            app.exportSaveButton = uibutton(app.ExportTab, 'push');
            app.exportSaveButton.ButtonPushedFcn = createCallbackFcn(app, @exportSaveButtonPushed, true);
            app.exportSaveButton.Text = 'Save';
            app.exportUITable = uitable(app.ExportTab);
            app.exportUITable.ColumnName = {'Run'; 'Batch'; 'FileSelected'};
            app.exportUITable.RowName = {};
            app.exportUITable.CellEditCallback = createCallbackFcn(app, @exportUITableCellEdit, true);
            app.exportLaunchICSExplorerButton = uibutton(app.ExportTab, 'push');
            app.exportLaunchICSExplorerButton.ButtonPushedFcn = createCallbackFcn(app, @exportLaunchICSExplorerButtonPushed, true);
            app.exportLaunchICSExplorerButton.Text = 'Launch ICS Explorer';
            app.exportTableLabel = uilabel(app.ExportTab);
            app.exportTableLabel.Text = 'Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STICSgui1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end