classdef STICSgui1_RM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        File                          matlab.ui.container.Menu
        LoadDataMenu                  matlab.ui.container.Menu
        ExitProgramMenu               matlab.ui.container.Menu
        ToolsMenu                     matlab.ui.container.Menu
        About                         matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        TutorialMenu                  matlab.ui.container.Menu
        CreditsMenu                   matlab.ui.container.Menu
        LicenseMenu                   matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        Toolbar                       matlab.ui.container.Toolbar
        UpdatePushTool                matlab.ui.container.toolbar.PushTool
        LinkToggleTool                matlab.ui.container.toolbar.ToggleTool
        TabGroup                      matlab.ui.container.TabGroup
        WelcomeTab                    matlab.ui.container.Tab
        ICSExplorerButton             matlab.ui.control.Button
        DataViewerButton              matlab.ui.control.Button
        JumpLabel                     matlab.ui.control.Label
        STICSButton                   matlab.ui.control.Button
        EachcorrelationspectroscopyalgorithmcontainsLabel  matlab.ui.control.Label
        UsageLabel                    matlab.ui.control.Label
        instructionsInfo              matlab.ui.control.Label
        instructionsTitle             matlab.ui.control.Label
        StartTab                      matlab.ui.container.Tab
        SaveButton                    matlab.ui.control.Button
        NewButton                     matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
        CurrentWorkflowListBoxLabel   matlab.ui.control.Label
        CurrentWorkflowListBox        matlab.ui.control.ListBox
        Tree                          matlab.ui.container.Tree
        PresetsNode                   matlab.ui.container.TreeNode
        OneChannelNode                matlab.ui.container.TreeNode
        ICSNode                       matlab.ui.container.TreeNode
        TICSNode                      matlab.ui.container.TreeNode
        STICSNode                     matlab.ui.container.TreeNode
        TwoChannelsNode               matlab.ui.container.TreeNode
        ICCSNode                      matlab.ui.container.TreeNode
        TICCSNode                     matlab.ui.container.TreeNode
        STICCSNode                    matlab.ui.container.TreeNode
        ThreeChannelsNode             matlab.ui.container.TreeNode
        FRETSTICSNode                 matlab.ui.container.TreeNode
        CustomNode                    matlab.ui.container.TreeNode
        RadialSTICSNode               matlab.ui.container.TreeNode
        Tree2                         matlab.ui.container.CheckBoxTree
        LoadingNode                   matlab.ui.container.TreeNode
        Node2_2                       matlab.ui.container.TreeNode
        Node3_2                       matlab.ui.container.TreeNode
        Node4_2                       matlab.ui.container.TreeNode
        PreprocessingNode             matlab.ui.container.TreeNode
        SetupNode                     matlab.ui.container.TreeNode
        RunNode                       matlab.ui.container.TreeNode
        AnalysisNode                  matlab.ui.container.TreeNode
        ExportNode                    matlab.ui.container.TreeNode
        AvailableStepsLabel           matlab.ui.control.Label
        AvailableWorkflowsLabel       matlab.ui.control.Label
        DeleteButton                  matlab.ui.control.Button
        SelectButton                  matlab.ui.control.Button
        AddButton                     matlab.ui.control.Button
        DoneButton                    matlab.ui.control.Button
        RemoveButton                  matlab.ui.control.Button
        DataTab                       matlab.ui.container.Tab
        MetadataLabel                 matlab.ui.control.Label
        Tree3                         matlab.ui.container.Tree
        AcquisitionNode               matlab.ui.container.TreeNode
        Node2_3                       matlab.ui.container.TreeNode
        Node3_3                       matlab.ui.container.TreeNode
        Node4_3                       matlab.ui.container.TreeNode
        DimensionsNode                matlab.ui.container.TreeNode
        SizexNode                     matlab.ui.container.TreeNode
        PixelSizeumNode               matlab.ui.container.TreeNode
        SelectfilesListBox            matlab.ui.control.ListBox
        SelectfilesListBoxLabel       matlab.ui.control.Label
        QuickPreviewLabel             matlab.ui.control.Label
        OpeninStackViewerButton       matlab.ui.control.Button
        FrameSlider                   matlab.ui.control.Slider
        FrameSliderLabel              matlab.ui.control.Label
        PlayButton                    matlab.ui.control.Button
        ClearAllButton                matlab.ui.control.Button
        ClearCurrentButton            matlab.ui.control.Button
        AddtoBatchButton              matlab.ui.control.Button
        BatchProcessingLabel          matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        Ch2Lamp                       matlab.ui.control.Lamp
        SelectCh2Button               matlab.ui.control.Button
        Ch1Lamp                       matlab.ui.control.Lamp
        SelectCh1Button               matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        PreprocessingTab              matlab.ui.container.Tab
        SetupTab                      matlab.ui.container.Tab
        AddtoBatchButton_2            matlab.ui.control.Button
        UITable_2                     matlab.ui.control.Table
        RunTab                        matlab.ui.container.Tab
        ResultsTab                    matlab.ui.container.Tab
        AnalysisTab                   matlab.ui.container.Tab
        ExportTab                     matlab.ui.container.Tab
        STICSTab                      matlab.ui.container.Tab
        Switch                        matlab.ui.control.Switch
        movieFormatButtonGroup        matlab.ui.container.ButtonGroup
        jpegButton_2                  matlab.ui.control.RadioButton
        aviButton                     matlab.ui.control.RadioButton
        mp4Button                     matlab.ui.control.RadioButton
        imageFormatButtonGroup        matlab.ui.container.ButtonGroup
        jpegButton                    matlab.ui.control.RadioButton
        pdfButton                     matlab.ui.control.RadioButton
        pngButton                     matlab.ui.control.RadioButton
        outputNameEditField           matlab.ui.control.EditField
        outputNameEditFieldLabel      matlab.ui.control.Label
        rootpathEditField             matlab.ui.control.EditField
        rootpathEditFieldLabel        matlab.ui.control.Label
        axisTitleEditField            matlab.ui.control.EditField
        axisTitleEditFieldLabel       matlab.ui.control.Label
        threshVectorEditField         matlab.ui.control.NumericEditField
        threshVectorEditFieldLabel    matlab.ui.control.Label
        fitRadiusEditField            matlab.ui.control.NumericEditField
        fitRadiusEditFieldLabel       matlab.ui.control.Label
        omegaThresholdEditField       matlab.ui.control.NumericEditField
        omegaThresholdEditFieldLabel  matlab.ui.control.Label
        TOIshiftEditField             matlab.ui.control.NumericEditField
        TOIshiftEditFieldLabel        matlab.ui.control.Label
        TOIsizeEditField              matlab.ui.control.NumericEditField
        TOIsizeEditFieldLabel         matlab.ui.control.Label
        ROIshiftEditField             matlab.ui.control.NumericEditField
        ROIshiftEditFieldLabel        matlab.ui.control.Label
        ROIsizeEditField              matlab.ui.control.NumericEditField
        ROIsizeEditFieldLabel         matlab.ui.control.Label
        tauLimitEditField             matlab.ui.control.NumericEditField
        tauLimitEditFieldLabel        matlab.ui.control.Label
        timeFrameEditField            matlab.ui.control.NumericEditField
        timeFrameEditFieldLabel       matlab.ui.control.Label
        pixelSizeEditField            matlab.ui.control.NumericEditField
        pixelSizeEditFieldLabel       matlab.ui.control.Label
        ErrorLabel                    matlab.ui.control.Label
        MovingAverageEditField        matlab.ui.control.NumericEditField
        butterIIRCheckBox             matlab.ui.control.CheckBox
        MovingAverageCheckBox         matlab.ui.control.CheckBox
        FourierWholeCheckBox          matlab.ui.control.CheckBox
        BrowseRootButton              matlab.ui.control.Button
        SpatioTemporalImageCorrelationSpectroscopySTICSLabel  matlab.ui.control.Label
        RunSTICSButton                matlab.ui.control.Button
        SelectDataSetButton           matlab.ui.control.Button
        exportImagesYNCheckBox        matlab.ui.control.CheckBox
        OutlierCriteriaLabel          matlab.ui.control.Label
        ImmobileRemovalSettingsLabel  matlab.ui.control.Label
        STICCSTab                     matlab.ui.container.Tab
        tauLimit_2Slider              matlab.ui.control.Slider
        tauLimit_2SliderLabel         matlab.ui.control.Label
        TOIsize_2Slider               matlab.ui.control.Slider
        TOIsize_2SliderLabel          matlab.ui.control.Label
    end

    properties (Access = public)
        pixelSize; % Size of pixels
        timeFrame; % Time delay
        tauLimit; % Max # of time lags
        FourierWhole; % Sub. avg img from each img
        MovingAverage; % Sub. average (n-1)/2 frames before & after
        butterIIR; % Immobile pop. removal
        omegaThreshold; % Largest beam radius accepted
        fitRadius; % Radius of circular mask
        threshVector; % Deviation amount
        ROIsize; % Region of interest
        ROIshift; % Space between ROIs
        TOIsize; % Temporal (window) of interest
        TOIshift; % Time between TOIs
        axisTitle; % Title of graph
        outputName; % Output file name
        path; % Stores path to store output
        exportImages; % Export images Y/N
        imageFormat = -1; % Result image format
        movieFormat = -1; % Result movie format
        filePath; % Path of data set file
        croped1; % Cropped image
        decision % Cropping decision


        %dataStack;
        channel1Array;
        channel1PathArray;
        channel2Array;
        channel2PathArray;
        
        Ch1Stack;
        Ch1Path;
        Ch1Name;
        
        Ch2Stack;
        Ch2Path;
        Ch2Name;
        
        currLength;
        
    end
    
    properties (Access = private)
        STICSfield_selectionApp; % STICS field selection app
        ICSExplorerApp; % ICS explorer app
        FileViewer; % File previewer app
    end
    
    methods (Access = public)
        % Reset error label to nothing
        function resetErrorLabel(app)
            app.ErrorLabel.Text = '';
            app.ErrorLabel.FontColor = [0.0 0.0 0.0];
        end
        
        % Show file choosen
        function displaySelectedFile(app, fileName)
            app.ErrorLabel.Text = ['File Selected: ' fileName];
            app.ErrorLabel.FontColor = [0.0 0.0 0.0];
        end
        
        % Check if croped1 & path have a value
        function checkRunValid(app)
            if (~isempty(app.croped1) && ~isempty(app.path))
                app.RunSTICSButton.Enable = 'On';
            end
        end
        
        % Retrieve decision from cropping
        function crop_decision(app, decision)
            app.decision = decision;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectCh1Button
        function SelectCh1ButtonPushed(app, event)
            app.Ch1Lamp.Color = [1.0 1.0 0.0];
            
            [fileName, pathName] = uigetfile('*.tif','Please select your data set');
            
            % Check if user clicked cancel
            if (fileName == 0)
                if (~app.filePath)
                    app.Ch1Lamp.Color = [1.0 0.0 0.0];
                else
                    app.Ch1Lamp.Color = [0.0 1.0 0.0];
                end
                
                return
            end   
            
            app.Ch1Path = pathName;
            app.Ch1Name=fileName(1:end-4);
            data = [app.filePath app.Ch1Name '.tif'];
            
            % Add path to code from parent folder
            addpath('current/STICS/');
            
            % Check if data is in MultiTiff files
            if (strcmp(fileName(end-2:end),'tif'))
                try
                    app.Ch1Stack = readFileToStack(data);
                catch Error
                    % Select another file
                    [StackFileName, StackPath] = uigetfile('*.tif', 'Select Stack File');
                    app.Ch1Stack = readFileToStack([StackPath, StackFileName]);
                end
                
                isValid = 1;
            else
                isValid = 0;
            end
            
            % Check if file valid
            if (isValid)
                app.Ch1Lamp.Color = [0.0 1.0 0.0];    
            % File invalid
            else
                app.Ch1Lamp.Color = [1.0 0.0 0.0];
            end
        end

        % Button pushed function: AddtoBatchButton
        function AddtoBatchButtonPushed(app, event)
            % Get proper index value in array
            if (isempty(app.currLength))
                app.currLength = 1;
            else
                app.currLength = app.currLength + 1;
            end
            
            app.currLength
            
            %{
            % Check if Ch1Stack not empty
            if (~isempty(app.Ch1Stack))
                app.channel1Array(app.currLength) = app.Ch1Stack;
                app.channel1PathArray(app.currLength) = app.Ch1Path;
            end
            
            % Check if Ch2Stack not empty
            if (~isempty(app.Ch2Stack))
                app.channel2Array(app.currLength) = app.Ch2Stack;
                app.channel2PathArray(app.currLength) = app.Ch2Path;
            end
            %}
            
            app.Ch1Name
            app.Ch1Path
            app.Ch2Name
            app.Ch2Path
            
            data = get(app.UITable, 'Data');
            %newRow = [cellstr(app.Ch1Name), cellstr(app.Ch1Path), cellstr(app.Ch2Name), cellstr(app.Ch2Path)];
            newRow = [1, 2, 3, 4];
            data(end + 1, :) = newRow;
            
            set(app.UITable, 'Data', data);
            
        end

        % Value changed function: tauLimitEditField
        function tauLimitEditFieldValueChanged(app, event)
            value = app.tauLimitEditField.Value;
            
            if (value > app.TOIsizeEditField.Value)
                app.ErrorLabel.Text = ['Error: Please select a tauLimit less than or equal to ' num2str(app.TOIsizeEditField.Value)];
                app.ErrorLabel.FontColor = [1.0 0.0 0.0];
            else
                resetErrorLabel(app);
            end
        end

        % Button pushed function: BrowseRootButton
        function BrowseRootButtonPushed(app, event)
            [rootPath] = uigetdir(pwd);
            
            % Check if user clicked cancel
            if (rootPath == 0)
                return
            end
            
            app.path = rootPath;
            
            %fprintf(path);
            app.rootpathEditField.Value = rootPath;
            app.BrowseRootButton.BackgroundColor = [0.33 0.77 0.01];
            
            checkRunValid(app);
        end

        % Value changed function: FourierWholeCheckBox
        function FourierWholeCheckBoxValueChanged(app, event)
            value = app.FourierWholeCheckBox.Value;
            
            if (value)
                app.MovingAverageCheckBox.Enable = 'Off';
                app.butterIIRCheckBox.Enable = 'Off';
            else
                app.MovingAverageCheckBox.Enable = 'On';
                app.butterIIRCheckBox.Enable = 'On';
            end
        end

        % Value changed function: MovingAverageCheckBox
        function MovingAverageCheckBoxValueChanged(app, event)
            value = app.MovingAverageCheckBox.Value;
            
            if (value == 0)
                app.MovingAverageEditField.Enable = 'Off';
            else
                app.MovingAverageEditField.Enable = 'On';
            end
            
            if (value)
                app.FourierWholeCheckBox.Enable = 'Off';
                app.butterIIRCheckBox.Enable = 'Off';
            else
                app.FourierWholeCheckBox.Enable = 'On';
                app.butterIIRCheckBox.Enable = 'On';
            end
        end

        % Value changed function: butterIIRCheckBox
        function butterIIRCheckBoxValueChanged(app, event)
            value = app.butterIIRCheckBox.Value;
            
            if (value)
                app.MovingAverageCheckBox.Enable = 'Off';
                app.FourierWholeCheckBox.Enable = 'Off';
            else
                app.MovingAverageCheckBox.Enable = 'On';
                app.FourierWholeCheckBox.Enable = 'On';
            end
        end

        % Button pushed function: SelectDataSetButton
        function SelectDataSetButtonPushed(app, event)
            [fileName, pathName] = uigetfile('*','Please select your data set');
            
            isValid = 0;
            
            % Check if user clicked cancel
            if (fileName == 0)
                return
            end
            
            app.filePath = pathName;
            ch1name=fileName(1:end-4);
            
            addpath('current/STICS/');
            
            % Check if data is in MultiTiff files
            if (strcmp(fileName(end-2:end),'tif'))
                image_data1=rd_img16([app.filePath ch1name '.tif']);
                app.croped1=image_data1; 
                displaySelectedFile(app, fileName);
                isValid = 1;
            % if data is in microscope formats such as Zeiss .lsm files, 'imreadBF' to
            % load each channel data. Please open the 'imreadBF.m' and see how the
            % input parameters are defined in order to load your data. 
            elseif (strcmp(fileName(end-2:end),'lsm'))
                % Check if loci.tools.jar is in same folder
                if (isfile([app.filePath, '/loci_tools.jar']))
                    app.ErrorLabel.Text = 'Error: File loci_tools.jar is not in the same directory ';
                    app.ErrorLabel.FontColor = [1.0 0.0 0.0];
                end
                
                % loci_tools.jar in same folder as function imreadBFmeta.m
                
                % Check version of MATLAB valid
                v = ver('MATLAB');
                vCheck = v.Version < 7.1;
                if (vCheck(1, 1))
                    app.ErrorLabel.Text = 'Error: This version of MATLAB is not compatible with the algorithm. Please upgrade to Version 7.1+ ';
                    app.ErrorLabel.FontColor = [1.0 0.0 0.0];
                    return;
                end
                
                % MATLAB version valid
                
                %=================================%
                % NOTE: can't find imreadBF.m file
                %=================================%
                
                %image_data1=imreadBF([app.path ch1name '.lsm'],1,1:100,1);
                %croped1=image_data1;
                displaySelectedFile(app, fileName);
            % if data is stored in a .mat variable
            elseif (strcmp(fileName(end-2:end),'mat'))
                image_data1=load([app.filePath ch1name '.mat']);
                app.croped1=image_data1;
                displaySelectedFile(app, fileName);
                isValid = 1;
            else 
                app.ErrorLabel.Text = 'Error: Your input data set should be either ''.tif'' ,''.lsm'' or ''.mat'' ';
                app.ErrorLabel.FontColor = [1.0 0.0 0.0];
                isValid = 0;
            end
            
            if (isValid)
                app.SelectDataSetButton.BackgroundColor = [0.33 0.77 0.01];
            else
                app.SelectDataSetButton.BackgroundColor = [1.0 0.0 0.0];
            end
            
            checkRunValid(app);
        end

        % Button pushed function: RunSTICSButton
        function RunSTICSButtonPushed(app, event)
            % Assign values to defined properties
            app.pixelSize = app.pixelSizeEditField.Value; 
            app.timeFrame = app.timeFrameEditField.Value; 
            app.tauLimit = app.tauLimitEditField.Value; 
            app.FourierWhole = app.FourierWholeCheckBox.Value;
            
            if (app.MovingAverageCheckBox.Value == 1)
                app.MovingAverage = app.MovingAverageEditField.Value;
            else
                app.MovingAverage = 0;
            end

            app.butterIIR = app.butterIIRCheckBox.Value;

            app.omegaThreshold = app.omegaThresholdEditField.Value; 
            app.fitRadius = app.fitRadiusEditField.Value; 
            app.threshVector = app.threshVectorEditField.Value;
            app.ROIsize = app.ROIsizeEditField.Value;
            app.ROIshift = app.ROIshiftEditField.Value;
            app.TOIsize = app.TOIsizeEditField.Value; 
            app.TOIshift = app.TOIshiftEditField.Value; 
            app.axisTitle = app.axisTitleEditField.Value; % str 
            app.outputName = app.outputNameEditField.Value; % str
            app.exportImages = app.exportImagesYNCheckBox.Value; 
            
            % Find which imageFormat button selected
            if (app.pngButton.Value)
                app.imageFormat = 'png';
            elseif (app.pdfButton.Value)
                app.imageFormat = 'pdf';
            else
                app.imageFormat = 'jpeg';
            end
            
            % Find which movieFormat button selected
            if (app.mp4Button.Value)
                app.movieFormat = 'mp4';
            elseif (app.aviButton.Value)
                app.movieFormat = 'avi';
            else
                app.movieFormat = 'jpeg';
            end
            
            app.RunSTICSButton.Enable = 'Off';
            app.STICSfield_selectionApp = STICSfield_selection(app);
            
            imagesc(mean(app.croped1, 3));
            
            if (app.decision == 'Y')
                maskCell=[];
                while (isempty(maskCell))
                    [x,y,maskCell,poly1.x,poly1.y]=roipoly;
                end
            else
                maskCell=ones(size(app.croped1,1),size(app.croped1,2));
            end


            [velocityMap, position_x, position_y, position_t, opt] = stics_vectormapping(app.croped1, app.pixelSize, app.timeFrame, app.tauLimit, app.FourierWhole, app.MovingAverage,... 
                                                                                         app.butterIIR, app.omegaThreshold, app.fitRadius, app.threshVector, app.ROIsize, app.ROIshift,...
                                                                                         app.TOIsize, app.TOIshift, app.axisTitle, app.outputName, app.path, app.exportImages, app.imageFormat,...
                                                                                         app.movieFormat, maskCell);
            
            app.RunSTICSButton.Enable = 'On';
        end

        % Value changed function: TOIsize_2Slider
        function TOIsize_2SliderValueChanged(app, event)
            value = app.TOIsize_2Slider.Value;
            
            % Check if TOIsize < tauLimit_2
            if (value < app.tauLimit_2Slider.Value)
                app.tauLimit_2Slider.Value = value;
            end
        end

        % Value changed function: tauLimit_2Slider
        function tauLimit_2SliderValueChanged(app, event)
            value = app.tauLimit_2Slider.Value;
            
            % Check if tauLiit > TOIsize_2
            if (value > app.TOIsize_2Slider.Value)
                app.tauLimit_2Slider.Value = app.TOIsize_2Slider.Value;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.STICSfield_selectionApp);
            delete(app.ICSExplorerApp);
            delete(app.FileViewer);
            delete(app);
        end

        % Button pushed function: STICSButton
        function STICSButtonPushed(app, event)
            % Go to STICS tab
            app.TabGroup.SelectedTab = app.STICSTab;
        end

        % Button pushed function: ICSExplorerButton
        function ICSExplorerButtonPushed(app, event)
            app.ICSExplorerApp = ICS_Explorer(app);
        end

        % Button pushed function: DataViewerButton
        function DataViewerButtonPushed(app, event)
            app.FileViewer = FilePreviewer(app, "", "");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.LoadDataMenu = uimenu(app.File);
            app.LoadDataMenu.Text = 'Load Data';
            app.ExitProgramMenu = uimenu(app.File);
            app.ExitProgramMenu.Text = 'Exit Program';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.About = uimenu(app.UIFigure);
            app.About.Text = 'About';
            app.HelpMenu = uimenu(app.About);
            app.HelpMenu.Text = 'Help';
            app.TutorialMenu = uimenu(app.About);
            app.TutorialMenu.Text = 'Tutorial';
            app.CreditsMenu = uimenu(app.About);
            app.CreditsMenu.Text = 'Credits';
            app.LicenseMenu = uimenu(app.About);
            app.LicenseMenu.Text = 'License';
            app.AboutMenu = uimenu(app.About);
            app.AboutMenu.Text = 'About';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.UpdatePushTool = uipushtool(app.Toolbar);
            app.LinkToggleTool = uitoggletool(app.Toolbar);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WelcomeTab = uitab(app.TabGroup);
            app.WelcomeTab.Title = 'Welcome';
            app.instructionsTitle = uilabel(app.WelcomeTab);
            app.instructionsTitle.Text = 'Instructions';
            app.instructionsInfo = uilabel(app.WelcomeTab);
            app.instructionsInfo.Text = {'This application implements the Spatio-Temporal Image Correlation Spectroscopy (STICS),  '; 'Spatio-Temporal Image Cross-Correlation Spectroscopy (STICCS), Temporal Image Correlation '; 'Spectroscopy (TICS), and Image Correlation Spectroscopy (ICS) algorithms.'; ''};
            app.UsageLabel = uilabel(app.WelcomeTab);
            app.UsageLabel.Text = 'Usage:';
            app.EachcorrelationspectroscopyalgorithmcontainsLabel = uilabel(app.WelcomeTab);
            app.EachcorrelationspectroscopyalgorithmcontainsLabel.Text = {'- Each correlation spectroscopy algorithms contain pre-set values for all the variables'; '- You can hover over the text/numeric field of each option to see a tooltip explaining how each variable'; 'works in the algorithm'};
            app.STICSButton = uibutton(app.WelcomeTab, 'push');
            app.STICSButton.ButtonPushedFcn = createCallbackFcn(app, @STICSButtonPushed, true);
            app.STICSButton.Text = 'STICS';
            app.JumpLabel = uilabel(app.WelcomeTab);
            app.JumpLabel.Text = 'Quick Jump to Applications';
            app.DataViewerButton = uibutton(app.WelcomeTab, 'push');
            app.DataViewerButton.ButtonPushedFcn = createCallbackFcn(app, @DataViewerButtonPushed, true);
            app.DataViewerButton.Text = 'Data Viewer';
            app.ICSExplorerButton = uibutton(app.WelcomeTab, 'push');
            app.ICSExplorerButton.ButtonPushedFcn = createCallbackFcn(app, @ICSExplorerButtonPushed, true);
            app.ICSExplorerButton.Text = 'ICS Explorer';
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.RemoveButton = uibutton(app.StartTab, 'push');
            app.RemoveButton.Text = '<< Remove';
            app.DoneButton = uibutton(app.StartTab, 'push');
            app.DoneButton.Text = 'Done';
            app.AddButton = uibutton(app.StartTab, 'push');
            app.AddButton.Text = 'Add >>';
            app.SelectButton = uibutton(app.StartTab, 'push');
            app.SelectButton.Text = 'Select';
            app.DeleteButton = uibutton(app.StartTab, 'push');
            app.DeleteButton.Text = 'Delete';
            app.AvailableWorkflowsLabel = uilabel(app.StartTab);
            app.AvailableWorkflowsLabel.Text = 'Available Workflows';
            app.AvailableStepsLabel = uilabel(app.StartTab);
            app.AvailableStepsLabel.Text = 'Available Steps';
            app.Tree2 = uitree(app.StartTab, 'checkbox');
            app.LoadingNode = uitreenode(app.Tree2);
            app.LoadingNode.Text = 'Loading';
            app.Node2_2 = uitreenode(app.LoadingNode);
            app.Node2_2.Text = 'Node2';
            app.Node3_2 = uitreenode(app.LoadingNode);
            app.Node3_2.Text = 'Node3';
            app.Node4_2 = uitreenode(app.LoadingNode);
            app.Node4_2.Text = 'Node4';
            app.PreprocessingNode = uitreenode(app.Tree2);
            app.PreprocessingNode.Text = 'Preprocessing';
            app.SetupNode = uitreenode(app.Tree2);
            app.SetupNode.Text = 'Setup';
            app.RunNode = uitreenode(app.Tree2);
            app.RunNode.Text = 'Run';
            app.AnalysisNode = uitreenode(app.Tree2);
            app.AnalysisNode.Text = 'Analysis';
            app.ExportNode = uitreenode(app.Tree2);
            app.ExportNode.Text = 'Export';
            app.Tree = uitree(app.StartTab);
            app.PresetsNode = uitreenode(app.Tree);
            app.PresetsNode.Text = 'Presets';
            app.OneChannelNode = uitreenode(app.PresetsNode);
            app.OneChannelNode.Text = 'One Channel';
            app.ICSNode = uitreenode(app.OneChannelNode);
            app.ICSNode.Text = 'ICS';
            app.TICSNode = uitreenode(app.OneChannelNode);
            app.TICSNode.Text = 'TICS';
            app.STICSNode = uitreenode(app.OneChannelNode);
            app.STICSNode.Text = 'STICS';
            app.TwoChannelsNode = uitreenode(app.PresetsNode);
            app.TwoChannelsNode.Text = 'Two Channels';
            app.ICCSNode = uitreenode(app.TwoChannelsNode);
            app.ICCSNode.Text = 'ICCS';
            app.TICCSNode = uitreenode(app.TwoChannelsNode);
            app.TICCSNode.Text = 'TICCS';
            app.STICCSNode = uitreenode(app.TwoChannelsNode);
            app.STICCSNode.Text = 'STICCS';
            app.ThreeChannelsNode = uitreenode(app.PresetsNode);
            app.ThreeChannelsNode.Text = 'Three Channels';
            app.FRETSTICSNode = uitreenode(app.ThreeChannelsNode);
            app.FRETSTICSNode.Text = 'FRET-STICS';
            app.CustomNode = uitreenode(app.Tree);
            app.CustomNode.Text = 'Custom';
            app.RadialSTICSNode = uitreenode(app.CustomNode);
            app.RadialSTICSNode.Text = 'Radial STICS';
            app.CurrentWorkflowListBox = uilistbox(app.StartTab);
            app.CurrentWorkflowListBoxLabel = uilabel(app.StartTab);
            app.CurrentWorkflowListBoxLabel.Text = 'Current Workflow';
            app.LoadButton = uibutton(app.StartTab, 'push');
            app.LoadButton.Text = 'Load';
            app.NewButton = uibutton(app.StartTab, 'push');
            app.NewButton.Text = 'New';
            app.SaveButton = uibutton(app.StartTab, 'push');
            app.SaveButton.Text = 'Save';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UIAxes = uiaxes(app.DataTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SelectCh1Button = uibutton(app.DataTab, 'push');
            app.SelectCh1Button.ButtonPushedFcn = createCallbackFcn(app, @SelectCh1ButtonPushed, true);
            app.SelectCh1Button.Text = 'Select Ch1';
            app.Ch1Lamp = uilamp(app.DataTab);
            app.SelectCh2Button = uibutton(app.DataTab, 'push');
            app.SelectCh2Button.Text = 'Select Ch2';
            app.Ch2Lamp = uilamp(app.DataTab);
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Channel 1'; 'Path to Ch1'; 'Channel 2'; 'Path to Ch2'};
            app.UITable.RowName = {};
            app.BatchProcessingLabel = uilabel(app.DataTab);
            app.BatchProcessingLabel.Text = 'Batch Processing';
            app.AddtoBatchButton = uibutton(app.DataTab, 'push');
            app.AddtoBatchButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoBatchButtonPushed, true);
            app.AddtoBatchButton.Text = 'Add to Batch';
            app.ClearCurrentButton = uibutton(app.DataTab, 'push');
            app.ClearCurrentButton.Text = 'Clear Current';
            app.ClearAllButton = uibutton(app.DataTab, 'push');
            app.ClearAllButton.Text = 'Clear All';
            app.PlayButton = uibutton(app.DataTab, 'push');
            app.PlayButton.Text = 'Play';
            app.FrameSliderLabel = uilabel(app.DataTab);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.DataTab);
            app.OpeninStackViewerButton = uibutton(app.DataTab, 'push');
            app.OpeninStackViewerButton.Text = 'Open in Stack Viewer';
            app.QuickPreviewLabel = uilabel(app.DataTab);
            app.QuickPreviewLabel.Text = 'Quick Preview';
            app.SelectfilesListBoxLabel = uilabel(app.DataTab);
            app.SelectfilesListBoxLabel.Text = 'Select files';
            app.SelectfilesListBox = uilistbox(app.DataTab);
            app.SelectfilesListBox.Items = {'File1', 'File 2', 'File 3'};
            app.SelectfilesListBox.Value = 'File1';
            app.Tree3 = uitree(app.DataTab);
            app.AcquisitionNode = uitreenode(app.Tree3);
            app.AcquisitionNode.Text = 'Acquisition';
            app.Node2_3 = uitreenode(app.AcquisitionNode);
            app.Node2_3.Text = 'Node2';
            app.Node3_3 = uitreenode(app.AcquisitionNode);
            app.Node3_3.Text = 'Node3';
            app.Node4_3 = uitreenode(app.AcquisitionNode);
            app.Node4_3.Text = 'Node4';
            app.DimensionsNode = uitreenode(app.Tree3);
            app.DimensionsNode.Text = 'Dimensions';
            app.SizexNode = uitreenode(app.DimensionsNode);
            app.SizexNode.Text = 'Size (x)';
            app.PixelSizeumNode = uitreenode(app.DimensionsNode);
            app.PixelSizeumNode.Text = 'Pixel Size (um)';
            app.MetadataLabel = uilabel(app.DataTab);
            app.MetadataLabel.Text = 'Metadata';
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.UITable_2 = uitable(app.SetupTab);
            app.UITable_2.ColumnName = {'Channel 1'; 'Path to Ch1'; 'Channel 2'; 'Path to Ch2'};
            app.UITable_2.RowName = {};
            app.AddtoBatchButton_2 = uibutton(app.SetupTab, 'push');
            app.AddtoBatchButton_2.Text = 'Add to Batch';
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.STICSTab = uitab(app.TabGroup);
            app.STICSTab.Title = 'STICS';
            app.ImmobileRemovalSettingsLabel = uilabel(app.STICSTab);
            app.ImmobileRemovalSettingsLabel.Tooltip = {'Immobile filtering permits the measurement of the flowing population by removing slowly moving or imobile structures from the image series prior to STICS analysis.'};
            app.ImmobileRemovalSettingsLabel.Text = 'Immobile Removal Settings';
            app.OutlierCriteriaLabel = uilabel(app.STICSTab);
            app.OutlierCriteriaLabel.Tooltip = {'Used to minimize the number of spurious vectors included in the results.'};
            app.OutlierCriteriaLabel.Text = 'Outlier Criteria';
            app.exportImagesYNCheckBox = uicheckbox(app.STICSTab);
            app.exportImagesYNCheckBox.Tooltip = {'If this box is checked, then the images of vector maps will be saved in the output directory.'};
            app.exportImagesYNCheckBox.Text = 'exportImages (Y/N)';
            app.SelectDataSetButton = uibutton(app.STICSTab, 'push');
            app.SelectDataSetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataSetButtonPushed, true);
            app.SelectDataSetButton.Tooltip = {'This button allows the user to upload a data set to be analysed using the STICS method.'};
            app.SelectDataSetButton.Text = 'Select Data Set';
            app.RunSTICSButton = uibutton(app.STICSTab, 'push');
            app.RunSTICSButton.ButtonPushedFcn = createCallbackFcn(app, @RunSTICSButtonPushed, true);
            app.RunSTICSButton.Enable = 'off';
            app.RunSTICSButton.Tooltip = {'This button allows the user to run a STICS analysis on the file that they uploaded.'};
            app.RunSTICSButton.Text = 'Run STICS';
            app.SpatioTemporalImageCorrelationSpectroscopySTICSLabel = uilabel(app.STICSTab);
            app.SpatioTemporalImageCorrelationSpectroscopySTICSLabel.Text = 'Spatio-Temporal Image Correlation Spectroscopy (STICS)';
            app.BrowseRootButton = uibutton(app.STICSTab, 'push');
            app.BrowseRootButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseRootButtonPushed, true);
            app.BrowseRootButton.Tooltip = {'This button allows you to select the folder where you want to save the results. It will automatically set the working directory.'};
            app.BrowseRootButton.Text = 'Browse Root';
            app.FourierWholeCheckBox = uicheckbox(app.STICSTab);
            app.FourierWholeCheckBox.ValueChangedFcn = createCallbackFcn(app, @FourierWholeCheckBoxValueChanged, true);
            app.FourierWholeCheckBox.Tooltip = {'This removal setting subtracts the average image of the series from each image in the series. It will effectively remove all fluorescent structures that are static for the duration of the image series.'};
            app.FourierWholeCheckBox.Text = 'FourierWhole';
            app.MovingAverageCheckBox = uicheckbox(app.STICSTab);
            app.MovingAverageCheckBox.ValueChangedFcn = createCallbackFcn(app, @MovingAverageCheckBoxValueChanged, true);
            app.MovingAverageCheckBox.Tooltip = {'The average of (n - 1)/2 frames before and (n-1)/2 frames after a given image are subtracted from it. This method of removal works better for large, slowly moving fluorescent entities (e.x large, sliding adhesions).'};
            app.MovingAverageCheckBox.Text = 'MovingAverage';
            app.butterIIRCheckBox = uicheckbox(app.STICSTab);
            app.butterIIRCheckBox.ValueChangedFcn = createCallbackFcn(app, @butterIIRCheckBoxValueChanged, true);
            app.butterIIRCheckBox.Tooltip = {'This removal setting is more appropriate for immobile population removal, independent of the length of the image time series.'};
            app.butterIIRCheckBox.Text = 'butterIIR';
            app.MovingAverageEditField = uieditfield(app.STICSTab, 'numeric');
            app.MovingAverageEditField.Enable = 'off';
            app.MovingAverageEditField.Value = 11;
            app.ErrorLabel = uilabel(app.STICSTab);
            app.ErrorLabel.Text = '';
            app.pixelSizeEditFieldLabel = uilabel(app.STICSTab);
            app.pixelSizeEditFieldLabel.Text = 'pixelSize';
            app.pixelSizeEditField = uieditfield(app.STICSTab, 'numeric');
            app.pixelSizeEditField.Tooltip = {'The size of a pixel in micrometers.'};
            app.pixelSizeEditField.Value = 0.14;
            app.timeFrameEditFieldLabel = uilabel(app.STICSTab);
            app.timeFrameEditFieldLabel.Text = 'timeFrame';
            app.timeFrameEditField = uieditfield(app.STICSTab, 'numeric');
            app.timeFrameEditField.Tooltip = {'The time delay between frames in s (seconds).'};
            app.timeFrameEditField.Value = 10;
            app.tauLimitEditFieldLabel = uilabel(app.STICSTab);
            app.tauLimitEditFieldLabel.Text = 'tauLimit';
            app.tauLimitEditField = uieditfield(app.STICSTab, 'numeric');
            app.tauLimitEditField.ValueChangedFcn = createCallbackFcn(app, @tauLimitEditFieldValueChanged, true);
            app.tauLimitEditField.Tooltip = {'The maximum number of time lags calculated in time correlation analysis. Note: this may end earlier if either the omegaThreshold or correlation local maximum threshold are exceeded. Typically for N frames, the value for tauLimit is ~N/5.'};
            app.tauLimitEditField.Value = 4;
            app.ROIsizeEditFieldLabel = uilabel(app.STICSTab);
            app.ROIsizeEditFieldLabel.Text = 'ROIsize';
            app.ROIsizeEditField = uieditfield(app.STICSTab, 'numeric');
            app.ROIsizeEditField.Tooltip = {'The size of the analysis region of interest (ROI), in pixels, used for velocity mapping analysis. Each arrow on the final velocity map is calculated from a region of pixels (ROIsize) x (ROIsize).'};
            app.ROIsizeEditField.Value = 16;
            app.ROIshiftEditFieldLabel = uilabel(app.STICSTab);
            app.ROIshiftEditFieldLabel.Text = 'ROIshift';
            app.ROIshiftEditField = uieditfield(app.STICSTab, 'numeric');
            app.ROIshiftEditField.Tooltip = {'The space between adjacent analysis regions, in pixels. Note: if ROIshift is set to a value lower than ROIsize, adjacent arrows in the resulting analysis will not contain all unique information.'};
            app.ROIshiftEditField.Value = 4;
            app.TOIsizeEditFieldLabel = uilabel(app.STICSTab);
            app.TOIsizeEditFieldLabel.Text = 'TOIsize';
            app.TOIsizeEditField = uieditfield(app.STICSTab, 'numeric');
            app.TOIsizeEditField.Tooltip = {'The size of the temporal window of interest (TOI), in frames, used to define the overall size of the block of data used to calculate the local correlation function. Note: this is the time equivalent to ROIsize.'};
            app.TOIsizeEditField.Value = 5;
            app.TOIshiftEditFieldLabel = uilabel(app.STICSTab);
            app.TOIshiftEditFieldLabel.Text = 'TOIshift';
            app.TOIshiftEditField = uieditfield(app.STICSTab, 'numeric');
            app.TOIshiftEditField.Tooltip = {'The number of frames by which the TOI is shifted in time. Note: this is the time equivalent of ROIshift. '};
            app.TOIshiftEditField.Value = 1;
            app.omegaThresholdEditFieldLabel = uilabel(app.STICSTab);
            app.omegaThresholdEditFieldLabel.Text = 'omegaThreshold';
            app.omegaThresholdEditField = uieditfield(app.STICSTab, 'numeric');
            app.omegaThresholdEditField.Tooltip = {'This parameter, in pixels, defines the largest beam waist acceptable from the correlation function fit. If the correlation function exceeds this radius for a given analysis subregion, the analysis will terminate for that subregion.'};
            app.omegaThresholdEditField.Value = 50;
            app.fitRadiusEditFieldLabel = uilabel(app.STICSTab);
            app.fitRadiusEditFieldLabel.Text = 'fitRadius';
            app.fitRadiusEditField = uieditfield(app.STICSTab, 'numeric');
            app.fitRadiusEditField.Tooltip = {'This parameter, in pixels, defines the radius of a circular mask centred at the local maximum within ROI, which is considered in the fitting of the 2D Gaussian. Note: for a noisy correlation function, it is preferable to fit the neighbourhood of the local maxima. '};
            app.fitRadiusEditField.Value = 8;
            app.threshVectorEditFieldLabel = uilabel(app.STICSTab);
            app.threshVectorEditFieldLabel.Text = 'threshVector';
            app.threshVectorEditField = uieditfield(app.STICSTab, 'numeric');
            app.threshVectorEditField.Tooltip = {'This threshold marks some vectors as outliiers if they deviate by a certain amount from their neighbours. It is used to assign a ''good'' vector according to its neighborhood.'};
            app.threshVectorEditField.Value = 2;
            app.axisTitleEditFieldLabel = uilabel(app.STICSTab);
            app.axisTitleEditFieldLabel.Text = 'axisTitle';
            app.axisTitleEditField = uieditfield(app.STICSTab, 'text');
            app.axisTitleEditField.Tooltip = {'A string defining the title displayed in the vector map images and movies. '};
            app.axisTitleEditField.Placeholder = '(e.x Vector Map of ____)';
            app.rootpathEditFieldLabel = uilabel(app.STICSTab);
            app.rootpathEditFieldLabel.Text = 'root/path';
            app.rootpathEditField = uieditfield(app.STICSTab, 'text');
            app.rootpathEditField.Editable = 'off';
            app.rootpathEditField.Tooltip = {'A string defining the output directory.'};
            app.rootpathEditField.Placeholder = '(e.x /Users/michael/Desktop/)';
            app.outputNameEditFieldLabel = uilabel(app.STICSTab);
            app.outputNameEditFieldLabel.Text = 'outputName';
            app.outputNameEditField = uieditfield(app.STICSTab, 'text');
            app.outputNameEditField.Tooltip = {'A string used in naming the STICS output files.'};
            app.outputNameEditField.Placeholder = '(e.x Lateral Diffusion of CFTR)';
            app.imageFormatButtonGroup = uibuttongroup(app.STICSTab);
            app.imageFormatButtonGroup.Title = 'imageFormat';
            app.pngButton = uiradiobutton(app.imageFormatButtonGroup);
            app.pngButton.Text = 'png';
            app.pngButton.Value = true;
            app.pdfButton = uiradiobutton(app.imageFormatButtonGroup);
            app.pdfButton.Text = 'pdf';
            app.jpegButton = uiradiobutton(app.imageFormatButtonGroup);
            app.jpegButton.Text = 'jpeg';
            app.movieFormatButtonGroup = uibuttongroup(app.STICSTab);
            app.movieFormatButtonGroup.Title = 'movieFormat';
            app.mp4Button = uiradiobutton(app.movieFormatButtonGroup);
            app.mp4Button.Text = 'mp4';
            app.mp4Button.Value = true;
            app.aviButton = uiradiobutton(app.movieFormatButtonGroup);
            app.aviButton.Text = 'avi';
            app.jpegButton_2 = uiradiobutton(app.movieFormatButtonGroup);
            app.jpegButton_2.Text = 'jpeg';
            app.Switch = uiswitch(app.STICSTab, 'slider');
            app.Switch.Items = {'STICS', 'STICCS'};
            app.Switch.Value = 'STICS';
            app.STICCSTab = uitab(app.TabGroup);
            app.STICCSTab.Title = 'STICCS';
            app.TOIsize_2SliderLabel = uilabel(app.STICCSTab);
            app.TOIsize_2SliderLabel.Text = {'TOIsize_2'; ''};
            app.TOIsize_2Slider = uislider(app.STICCSTab);
            app.TOIsize_2Slider.ValueChangedFcn = createCallbackFcn(app, @TOIsize_2SliderValueChanged, true);
            app.tauLimit_2SliderLabel = uilabel(app.STICCSTab);
            app.tauLimit_2SliderLabel.Text = 'tauLimit_2';
            app.tauLimit_2Slider = uislider(app.STICCSTab);
            app.tauLimit_2Slider.ValueChangedFcn = createCallbackFcn(app, @tauLimit_2SliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STICSgui1_RM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end