classdef ICS_Explorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ICS_ExplorerUIFigure     matlab.ui.Figure
        Menu                     matlab.ui.container.Menu
        Toolbar                  matlab.ui.container.Toolbar
        UpdateTool               matlab.ui.container.toolbar.PushTool
        StopTool                 matlab.ui.container.toolbar.PushTool
        ICSExplorerToggle        matlab.ui.container.toolbar.ToggleTool
        VectorFieldViewerToggle  matlab.ui.container.toolbar.ToggleTool
        GridLayout               matlab.ui.container.GridLayout
        Visualization2D          matlab.ui.container.Panel
        InterpolateMap           matlab.ui.control.CheckBox
        HistAbsVals              matlab.ui.control.CheckBox
        Visualization3DVF        matlab.ui.container.Panel
        TabGroup2                matlab.ui.container.TabGroup
        DPlotTab                 matlab.ui.container.Tab
        asDropDown_3Label        matlab.ui.control.Label
        asDropDown_3             matlab.ui.control.DropDown
        asDropDown_2Label        matlab.ui.control.Label
        asDropDown_2             matlab.ui.control.DropDown
        asDropDown               matlab.ui.control.DropDown
        asDropDownLabel          matlab.ui.control.Label
        ContourCheckBox          matlab.ui.control.CheckBox
        ResidualsCheckBox        matlab.ui.control.CheckBox
        FitCheckBox              matlab.ui.control.CheckBox
        CorrelationCheckBox      matlab.ui.control.CheckBox
        ZPanelCheckBox           matlab.ui.control.CheckBox
        YPanelCheckBox           matlab.ui.control.CheckBox
        XPanelCheckBox           matlab.ui.control.CheckBox
        VectorFieldTab           matlab.ui.container.Tab
        VFColorScaleCheckBox     matlab.ui.control.CheckBox
        PlayVF                   matlab.ui.control.Button
        VFSwitch                 matlab.ui.control.Switch
        Controls2D               matlab.ui.container.Panel
        Show2D                   matlab.ui.control.DropDown
        ShowDropDownLabel        matlab.ui.control.Label
        AxisViewDropDown         matlab.ui.control.DropDown
        AxisViewDropDownLabel    matlab.ui.control.Label
        Set2DVizButton           matlab.ui.control.Button
        Plot2DButton             matlab.ui.control.Button
        Controls3D               matlab.ui.container.Panel
        Set2DVizButton_2         matlab.ui.control.Button
        MaxVSpinner              matlab.ui.control.Spinner
        MaxVSpinnerLabel         matlab.ui.control.Label
        VelThresholdCheckBox     matlab.ui.control.CheckBox
        SDVSpinner               matlab.ui.control.Spinner
        SDVLabel                 matlab.ui.control.Label
        SDVCheckBox              matlab.ui.control.CheckBox
        MaxHWSpinner             matlab.ui.control.Spinner
        MaxHWSpinnerLabel        matlab.ui.control.Label
        MaxHWCheckBox            matlab.ui.control.CheckBox
        TauLimitSpinner          matlab.ui.control.Spinner
        TauLimitSpinnerLabel     matlab.ui.control.Label
        FitRadiuspxSpinner       matlab.ui.control.Spinner
        FitRadiuspxSpinnerLabel  matlab.ui.control.Label
        TauSpinner               matlab.ui.control.Spinner
        TauSpinnerLabel          matlab.ui.control.Label
        FitModeDropDown          matlab.ui.control.DropDown
        FitModeLabel             matlab.ui.control.Label
        Play3D                   matlab.ui.control.Button
        Plot3DButton             matlab.ui.control.Button
        TabGroup                 matlab.ui.container.TabGroup
        SelectTab                matlab.ui.container.Tab
        GridLayout2              matlab.ui.container.GridLayout
        AppModeButtonGroup       matlab.ui.container.ButtonGroup
        VectorFieldViewerButton  matlab.ui.control.ToggleButton
        ICSExplorerButton        matlab.ui.control.ToggleButton
        SourcePanel              matlab.ui.container.Panel
        VectormapLabel           matlab.ui.control.Label
        ImagestackLabel          matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        Ch1Label                 matlab.ui.control.Label
        CheckBox_4               matlab.ui.control.CheckBox
        CheckBox_3               matlab.ui.control.CheckBox
        CheckBox_2               matlab.ui.control.CheckBox
        CheckBox                 matlab.ui.control.CheckBox
        LiveSTICSButton          matlab.ui.control.StateButton
        LoadfileButton           matlab.ui.control.StateButton
        VecMapLamp               matlab.ui.control.Lamp
        StackLamp                matlab.ui.control.Lamp
        SelectfileButton         matlab.ui.control.Button
        OptionsPanel             matlab.ui.container.Panel
        StackButton              matlab.ui.control.Button
        FrameButton              matlab.ui.control.Button
        SetOptionsButton         matlab.ui.control.Button
        Formula                  matlab.ui.control.Label
        UsingLabel               matlab.ui.control.Label
        ROIsizeEditField         matlab.ui.control.NumericEditField
        ROIsizeLabel             matlab.ui.control.Label
        TOIshiftEditField        matlab.ui.control.NumericEditField
        TOIshiftEditFieldLabel   matlab.ui.control.Label
        TOIsizeEditField         matlab.ui.control.NumericEditField
        TOIsizeEditFieldLabel    matlab.ui.control.Label
        ROIshiftEditField        matlab.ui.control.NumericEditField
        ROIshiftEditFieldLabel   matlab.ui.control.Label
        CustomROISlider          matlab.ui.control.Slider
        CustomSliderLabel        matlab.ui.control.Label
        nSpinner                 matlab.ui.control.Spinner
        nSpinnerLabel            matlab.ui.control.Label
        NavigatorTab             matlab.ui.container.Tab
        GridLayout3              matlab.ui.container.GridLayout
        NavigatorPanel           matlab.ui.container.Panel
        CSpinner                 matlab.ui.control.Spinner
        CSpinnerLabel            matlab.ui.control.Label
        NavSwitch                matlab.ui.control.Switch
        YindexSpinner            matlab.ui.control.Spinner
        YSpinnerLabel            matlab.ui.control.Label
        XindexSpinner            matlab.ui.control.Spinner
        XSpinnerLabel            matlab.ui.control.Label
        FSpinner                 matlab.ui.control.Spinner
        FLabel                   matlab.ui.control.Label
        PlayNav                  matlab.ui.control.Button
        UpdateNavButton          matlab.ui.control.Button
        Preview                  matlab.ui.control.UIAxes
        Zoom                     matlab.ui.control.UIAxes
        InfoTab                  matlab.ui.container.Tab
        GridLayout4              matlab.ui.container.GridLayout
        Tree                     matlab.ui.container.Tree
        MetadataNode             matlab.ui.container.TreeNode
        PixelSizeNode            matlab.ui.container.TreeNode
        FramerateNode            matlab.ui.container.TreeNode
        OptionsNode              matlab.ui.container.TreeNode
        ROIsizeNode              matlab.ui.container.TreeNode
        ROIshiftNode             matlab.ui.container.TreeNode
        TOIsizeNode              matlab.ui.container.TreeNode
        TOIshiftNode             matlab.ui.container.TreeNode
        TaulimitNode             matlab.ui.container.TreeNode
        VectorMapNode            matlab.ui.container.TreeNode
        Node_2                   matlab.ui.container.TreeNode
        VectorNode               matlab.ui.container.TreeNode
        VNode                    matlab.ui.container.TreeNode
        VxNode                   matlab.ui.container.TreeNode
        VyNode                   matlab.ui.container.TreeNode
        PxNode                   matlab.ui.container.TreeNode
        PyNode                   matlab.ui.container.TreeNode
        Node_3                   matlab.ui.container.TreeNode
        GaussianNode             matlab.ui.container.TreeNode
        TypeNode                 matlab.ui.container.TreeNode
        SignificantNode          matlab.ui.container.TreeNode
        AmplitudeNode            matlab.ui.container.TreeNode
        WidthXNode               matlab.ui.container.TreeNode
        WidthYNode               matlab.ui.container.TreeNode
        OffsetNode               matlab.ui.container.TreeNode
        CenterNode               matlab.ui.container.TreeNode
        AngleNode                matlab.ui.container.TreeNode
        EccentricityNode         matlab.ui.container.TreeNode
        ResidualsNormNode        matlab.ui.container.TreeNode
        GOFNode                  matlab.ui.container.TreeNode
        Histogram                matlab.ui.control.UIAxes
        VectorField              matlab.ui.control.UIAxes
        ICF2D                    matlab.ui.control.UIAxes
        ICF3D                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Opt
        PosX
        PosY
        PosT
        Stack
        VelMap
        VelMapFileName
        VelMapPath % Path to the velocity map
        CallingApp;
        k
    end
    
    properties (Access = private)
        CurrentFrame
        CurrentPosX
        vix
        CurrentPosY
        viy
        MinFrame % Frame number of data that corresponds to the first vector field frame
        MaxFrame % Frame number of data that corresponds to the last vector field frame
        StackFileName
        StackPath
        ROIsquare % Description
        coordinatesNeedUpdate % Description
        CurrentROIcoors % Description
        tau % Description
        fracROIshift % Description
        fracTOIshift
        TOIFOV % Description
        corrfn
        meshX % Description
        meshY
        Z % Description
        minK
        coeffGfit
        maskedPosX
        maskedPosY
        DisplayLimits = [100, 2500];% Description
    end
    
    methods (Access = public)
        
        function setRanges(app)
            % Set Frame range
            app.MinFrame = ceil(app.Opt.TOIsize/2);ceil(app.Opt.TOIsize/2);
            app.MaxFrame = size(app.Stack, 3) - floor(app.Opt.TOIsize/2);size(app.Stack, 3) - floor(app.Opt.TOIsize/2);
            app.FSpinner.Limits = [app.MinFrame, app.MaxFrame];
            if app.FSpinner.Value == 1
                app.FSpinner.Value = app.MinFrame;
            end
            app.CurrentFrame = app.FSpinner.Value;
            
            % Extract vector field frame index (k)
            if mod(app.Opt.TOIsize, 2) ==  0 % TOI size is even
                app.minK = 1;
            else
                app.minK = 0;
            end
            app.k = app.CurrentFrame - floor(app.Opt.TOIsize/2) + app.minK; % Vector field frame

            % Set vector field index range
            size(app.PosX)
            app.XindexSpinner.Limits = [1, size(app.PosX, 1)];
            app.XindexSpinner.Value = 1;
            app.YindexSpinner.Limits = [1, size(app.PosY, 2)];
            app.YindexSpinner.Value = 1;
            
            % Set Tau range
            app.TauSpinner.Limits = [0, app.Opt.TOIsize-1]; % May change later
            app.tau = app.TauSpinner.Limits(1);
            
            % Get current position
            app.vix = app.XindexSpinner.Value;
            app.CurrentPosX = app.PosX(app.vix, 1);
            app.viy = app.YindexSpinner.Value;
            app.CurrentPosY = app.PosY(1, app.viy);
            app.CurrentROIcoors = [app.CurrentPosX-app.Opt.ROIsize/2,...
                app.CurrentPosY-app.Opt.ROIsize/2,...
                app.Opt.ROIsize, app.Opt.ROIsize];
        end
        
        function UpdateNavigator(app)
            
            % Refresh the background image and draw dashed rectangle
            imshow(app.Stack(:,:,app.FSpinner.Value), app.DisplayLimits, 'Parent', app.Preview);
%             imagesc(app.Preview,app.Stack(:,:,app.FSpinner.Value));
%             colormap(app.Preview,"gray");
            rectangle(app.Preview, 'Position',...
                [(1+app.Opt.ROIsize)/2, (1+app.Opt.ROIsize)/2,...
                size(app.Stack,1)-app.Opt.ROIsize,...
                size(app.Stack,1)-app.Opt.ROIsize],...
                'FaceColor','none', 'LineStyle','--');
            rectangle(app.Preview,'Position',...
                app.CurrentROIcoors);
            app.ROIsquare = drawrectangle(app.Preview,...
                "Position", app.CurrentROIcoors,...
                "AspectRatio",1,"FixedAspectRatio",true,...
                "InteractionsAllowed","translate");
%             app.CurrentROIcoors = app.ROIsquare.Position;
            hold(app.Preview, 'on');
            
            if isfield(app.VelMap{app.k}, 'goodVectors')
                if size(app.VelMap{app.k}.goodVectors, 1) == 1 % Is in line shape
                    app.VelMap{app.k}.goodVectors = reshape(app.VelMap{app.k}.goodVectors, size(app.PosX,1), size(app.PosY,2)); 
                end
            else
                app.VelMap{app.k}.goodVectors = ones(size(app.PosX));
            end
            
            
            if isfield(app.VelMap{app.k}, "vx")
                if size(app.PosX) ~= size(app.VelMap{app.k}.goodVectors) 
                    app.VelMap{app.k} = rmfield(app.VelMap{app.k}, "goodVectors");
                    app.VelMap{app.k}.goodVectors = (~isnan(app.VelMap{app.k}.vx) & ~isnan(app.VelMap{app.k}.vy));
                end
            end
            
            app.maskedPosX = app.PosX.* app.Opt.vectorPositions.* app.VelMap{app.k}.goodVectors;
            app.maskedPosX(app.maskedPosX == 0) = NaN;
    
            app.maskedPosY = app.PosY.* app.Opt.vectorPositions.* app.VelMap{app.k}.goodVectors;
            app.maskedPosY(app.maskedPosY == 0) = NaN;

    
            plot(app.Preview, app.maskedPosY, app.maskedPosX,'.r', 'MarkerSize',1); 
            plot(app.Preview, app.CurrentPosX, app.CurrentPosY,'+y', 'LineWidth', 1);           
            hold(app.Preview, 'off');
 
            
            app.Zoom.Visible = 'on';
%             imshow(app.Stack(app.CurrentPosX - app.Opt.ROIsize/2:app.CurrentPosX + app.Opt.ROIsize/2,...
%                 app.CurrentPosY - app.Opt.ROIsize/2:app.CurrentPosY + app.Opt.ROIsize/2,...
%                 app.CurrentFrame), app.DisplayLimits, 'Parent', app.Zoom);

            imshow(app.Stack(1+floor(app.CurrentROIcoors(2)):1+floor(app.CurrentROIcoors(2))+app.Opt.ROIsize,...
                1+floor(app.CurrentROIcoors(1)):1+floor(app.CurrentROIcoors(1))+app.Opt.ROIsize,...
                app.CurrentFrame), app.DisplayLimits, 'Parent', app.Zoom);
%             imagesc(app.Zoom, app.Stack(1+floor(app.CurrentROIcoors(2)):1+floor(app.CurrentROIcoors(2))+app.Opt.ROIsize,...
%                 1+floor(app.CurrentROIcoors(1)):1+floor(app.CurrentROIcoors(1))+app.Opt.ROIsize,...
%                 app.CurrentFrame));            
%             colormap(app.Zoom, "gray");
        end
        
        function UpdateVectorField(app)
            app.ICF3D.Visible = "off";
            app.VectorField.Visible = "on";
            axis(app.VectorField, "image");
%             axis(app.VectorField, "tight");
%             xticks(app.VectorField, []);
%             yticks(app.VectorField, []);
            xlim(app.VectorField, [min(app.PosX, [], "all") - app.Opt.ROIshift,...
                max(app.PosX, [], "all") + app.Opt.ROIshift]);
            ylim(app.VectorField, [min(app.PosY, [], "all") - app.Opt.ROIshift,...
                max(app.PosY, [], "all") + app.Opt.ROIshift]);
            
            himage = imshow(app.Stack(:,:,app.FSpinner.Value), app.DisplayLimits, 'Parent', app.VectorField);
            hold(app.VectorField, "on");
            r = drawrectangle(app.VectorField,...
                "Position", app.CurrentROIcoors,...
                "AspectRatio",1,"FixedAspectRatio",true,...
                "InteractionsAllowed","none", ...
                "FaceAlpha", 0.1); 
            if isfield(app.VelMap{app.k}, "VelMap")
                
%                 Q = quiver(app.CurrentPosX, app.CurrentPosY,...
%                     -app.VelMap{app.k}.vx(app.viy, app.vix),-app.VelMap{app.k}.vy(app.viy, app.vix), 1,...
%                     "Parent", app.VectorField);
%                 Q.Color = 'm';
%                 Q.LineWidth = 2;

                switch app.VFColorScaleCheckBox.Value
                    case 0
                        q = quiver(app.maskedPosY, app.maskedPosX,...
                            -app.VelMap{app.k}.vx,-app.VelMap{app.k}.vy, 1,...
                            "Parent",app.VectorField);
                        q.Color = 'm';
                    case 1    
                        quiverc(app.VectorField, app.maskedPosY, app.maskedPosX,...
                            -app.VelMap{app.k}.vx.*app.VelMap{app.k}.goodVectors,...
                            -app.VelMap{app.k}.vy.*app.VelMap{app.k}.goodVectors, 1);
                end
            else
                rp = scatter(app.VectorField, app.maskedPosY, app.maskedPosX,'.r'); 
                yp = scatter(app.VectorField, app.CurrentPosX, app.CurrentPosY,'+y', 'LineWidth', 1); 
            end


            hold(app.VectorField, "off");


            app.ICF3D.Visible = "off";
%             app.ICF2D.Visible = "off";
            
            
            if app.AppModeButtonGroup.SelectedObject == app.ICSExplorerButton
                himage.Visible = "off";
                q.Visible = "off";
                Q.Visible = "off";
                r.Visible = "off";
%                 app.Visualization3DVF.Visible = "off";
                app.VectorField.Title.Visible = "off";

                rp = scatter(app.VectorField, app.maskedPosY, app.maskedPosX, "Marker", "none");
                yp = scatter(app.VectorField, app.CurrentPosX, app.CurrentPosY, "Marker","none"); 
            end
            
        end
        
        function GenerateVectorMap(app)
            
            if isfield(app.Opt, "maxV")
                app.MaxVSpinner.Value = app.Opt.maxV;
            elseif isfield(app.Opt, "threshV")
                app.Opt.maxV = app.Opt.threshV;
                app.MaxVSpinner.Value = app.Opt.maxV;                        
            else
                app.Opt.maxV = Inf;
            end            
            
            app.Opt.axisTitle = [app.Opt.fileName{1}, ...
                '_',num2str(app.Opt.ROIsize), 'x', num2str(app.Opt.ROIshift), '_', ...
                num2str(app.Opt.TOIsize), 'x', num2str(app.Opt.TOIshift),...
                '_t', num2str(app.Opt.tauLimit),...
                'r', num2str(app.Opt.fitRadius),...
                'w', num2str(app.Opt.maxHalfWidth ), ...
                'sd', num2str(app.Opt.threshVector),...
                'v', num2str(app.Opt.maxV)];
            app.Opt.outputName = app.Opt.axisTitle;
            
            opt = app.Opt;
            [velocityMap, position_x, position_y, position_t, opt] = stics_vectormapping(opt);
            [velocityMap, opt] = convertVelocitiesFromPxPy(velocityMap, position_x, position_y,position_t, opt);
            
            
            % Import variables
            app.VelMap = velocityMap;
            app.PosX = position_x;
            app.PosY = position_y;
            app.PosT = position_t;
            app.Opt = opt;
            app.fracROIshift = app.Opt.ROIsize/app.Opt.ROIshift;
            app.fracTOIshift = app.Opt.TOIsize/app.Opt.TOIshift;            
        end
        
        function UpdateHistogram(app)
            if app.AppModeButtonGroup.SelectedObject == app.ICSExplorerButton
                app.Histogram.Visible = "off";
                h = histogram(app.Histogram, 1);
                h.Visible = 'off';
            elseif app.AppModeButtonGroup.SelectedObject == app.VectorFieldViewerButton
%                 switch app.Show2D.Value
%                     case 'Vx Histogram'
%                         app.ICF2D.Visible = "off";
%                         if app.HistAbsVals.Value
%                             h = histogram(app.Histogram, abs(app.VelMap{app.k}.vx), "BinWidth", 0.025);
%                         else
%                             h = histogram(app.Histogram, app.VelMap{app.k}.vx, "BinWidth", 0.025);  
%                         end
%                         h.Visible = "on";
%                         title(app.Histogram, 'Velocity Distribution in X (\mum/min)')                        
%                     case 'Vy Histogram'
%                         app.ICF2D.Visible = "off";
%                         if app.HistAbsVals.Value
%                             h = histogram(app.Histogram, abs(app.VelMap{app.k}.vy), "BinWidth", 0.025);
%                         else
%                             h = histogram(app.Histogram, app.VelMap{app.k}.vy, "BinWidth", 0.025);  
%                         end
%                         h.Visible = "on";
%                         title(app.Histogram, 'Velocity Distribution in Y (\mum/min)')                        
%                     case 'V Histogram'
%                         app.ICF2D.Visible = "off";
%                         if app.HistAbsVals.Value
%                             h = histogram(app.Histogram, abs(app.VelMap{app.k}.VelMap), "BinWidth", 0.025);
%                         else
%                             h = histogram(app.Histogram, app.VelMap{app.k}.VelMap, "BinWidth", 0.025);  
%                         end
%                         h.Visible = "on";
%                         title(app.Histogram, 'Velocity Distribution (\mum/min)')
%                 end
%                 xline(app.Histogram, app.MaxVSpinner.Value,"LineWidth", 2, "Color", "r")                
%                 app.Histogram.Visible = "on";
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %% Initialize app axes
%             h = @app.ICS_ExplorerUIFigure;
%             assignin("base","h");
            
            % Navigator Preview
            app.Preview.Visible = 'off';
            app.Preview.XTick = [];
            app.Preview.YTick = [];
            axis(app.Preview,'image');
            axis(app.Preview,'tight');
            
            % Navigator Zoom
            app.Zoom.Visible = 'off';
            app.Zoom.XTick = [];
            app.Zoom.YTick = [];
            axis(app.Zoom, 'image');
            axis(app.Zoom, 'tight');
            
            % 2D Plot 
            app.ICF2D.Visible = 'off';            
            set(app.ICF2D,'XColor',[0.9 0.9 0.9],'YColor',[0.9 0.9 0.9]);
            set(app.ICF2D,'Color','none','FontWeight','bold');
            app.ICF2D.Title.Color = [0.00,0.55,0.87];
            app.ICF2D.FontWeight = 'bold';
            app.ICF2D.Title.FontSize = 18;
            
            % 3D Plot
            app.ICF3D.Visible = 'off';
            xlabel(app.ICF3D,'\it\eta (\mum)');
            ylabel(app.ICF3D,'\it\xi (\mum)');
            zlabel(app.ICF3D,'\itG(\tau)');            
            set(app.ICF3D,'XColor',[0.9 0.9 0.9],'YColor',[0.9 0.9 0.9],'ZColor',[0.9 0.9 0.9]);
            set(app.ICF3D,'Color','none','FontWeight','bold');
            app.ICF3D.Title.Color = [0.00,0.55,0.87];
            app.ICF3D.FontWeight = 'bold';
            app.ICF3D.Title.FontSize = 18;
            
            % Disable buttons
            app.PlayNav.Enable = 0;
            app.UpdateNavButton.Enable = 0;
            
            % Draw ROI size formula
            app.Formula.Interpreter = 'latex';
            app.Formula.Text = '$2^{n}$';
            
            set(app.Histogram,'XColor',[0.9 0.9 0.9],'YColor',[0.9 0.9 0.9],'ZColor',[0.9 0.9 0.9]);
            set(app.Histogram,'Color','none','FontWeight','bold');
            app.Histogram.Title.Color = [0.00,0.55,0.87];
            app.Histogram.FontWeight = 'bold';
            app.Histogram.Title.FontSize = 18;
            
            app.VectorField.Title.Color = [0.00,0.55,0.87];
            app.VectorField.FontWeight = 'bold';
            app.VectorField.Title.FontSize = 18;
            
            app.VelMap = cell(1,1);
            app.VelMap{1,1} = struct();
            
            if nargin >= 6
                disp("Loading data from motherapp...");
                % If the app was launched from the motherapp,
                % load the data passed.
                % Inputs in varargin should be:
                % stack, velocityMap, position_x, position_y, position_t, opt
                
                % Retrieve data from varargin:
                disp("Assigning 6 variables");
                [stack, velocityMap, position_x, position_y, position_t, opt] = varargin{2:7};

%                 stack = varargin{1};
%                 velocityMap = varargin{2};
%                 position_x = varargin{3};
%                 position_y = varargin{4};
%                 position_t = varargin{5};
%                 opt = varargin{6};
                                
                % Load stack 
                app.Stack = stack;
                if (isempty(app.Stack))
                    app.StackLamp.Color = 'r';
                else
                    app.StackLamp.Color = 'g';
                    app.Preview.Visible = 'on';
                    imshow(app.Stack(:,:,app.FSpinner.Value), app.DisplayLimits, 'Parent', app.Preview);
%                     imagesc(app.Preview, app.Stack(:,:,app.FSpinner.Value));
%                     colormap(app.Preview, "gray");
                end    

                
                % Load vectorMap if passed in from motherapp & stack not empty
                if (~isempty(velocityMap) && ~isempty(position_x) && ~isempty(position_y) &&...
                    ~isempty(position_t) && ~isempty(opt) && ~isempty(stack))
                    app.LiveSTICSButton.Value = 0;
                    app.LoadfileButton.Value = 1;
                
                    app.VelMap = velocityMap;
                    app.PosX = position_x;
                    app.PosY = position_y;
                    app.PosT = position_t;
                    app.Opt = opt;
                    
                    app.fracROIshift = app.Opt.ROIsize/app.Opt.ROIshift;
                    app.fracTOIshift = app.Opt.TOIsize/app.Opt.TOIshift;
                    
                   % Extract and convert velocities:
                    if ~isfield(app.Opt, 'VelUnits')
                        opt.SaveData = 0;
                        [velocityMap, opt] = convertVelocitiesFromPxPy(velocityMap, position_x, position_y, position_t, opt);
                        app.VelMap = velocityMap;
                        app.Opt = opt;
                    end
                    
                    % Populate controls
                    app.ROIsizeEditField.Value = app.Opt.ROIsize;
                    app.ROIshiftEditField.Value = app.Opt.ROIshift;
                    app.TOIsizeEditField.Value = app.Opt.TOIsize;
                    app.TOIshiftEditField.Value = app.Opt.TOIshift;
                    app.CustomROISlider.Value = app.Opt.ROIsize;
                    app.FitRadiuspxSpinner.Value = app.Opt.fitRadius;
                    app.TauLimitSpinner.Value = app.Opt.tauLimit;
                    if isfield(app.Opt, "maxHalfWidth")
                       app.MaxHWSpinner.Value = app.Opt.maxHalfWidth;
                    elseif isfield(app.Opt, "omegaThreshold")
                        app.Opt.maxHalfWidth = app.Opt.omegaThreshold;
                        app.MaxHWSpinner.Value = app.Opt.maxHalfWidth;
                    end
                    app.SDVSpinner.Value = app.Opt.threshVector;
                    if isfield(app.Opt, "maxV")
                        app.MaxVSpinner.Value = app.Opt.maxV;
                    elseif isfield(app.Opt, "threshV")
                        app.Opt.maxV = app.Opt.threshV;
                        app.MaxVSpinner.Value = app.Opt.maxV;                        
    %                         app.Opt.maxV = Inf;
                    end
                                      
                    % Set Ranges and Update Navigator
                    setRanges(app);
                    UpdateNavigator(app);                                    
                    
                    % Disable Live STICS controls
                    app.ROIsizeEditField.Enable = 'off';
                    app.UsingLabel.Enable = 'off';
                    app.Formula.Enable = 'off';
                    app.CustomSliderLabel.Enable = 'off';
                    app.CustomROISlider.Enable = 'off';
                    app.nSpinner.Enable = 'off';
                    app.nSpinnerLabel.Enable = 'off';
                    app.ROIshiftEditField.Enable = 'off';
                    app.TOIsizeEditField.Enable = 'off';
                    app.TOIshiftEditField.Enable = 'off';    
                    app.FitRadiuspxSpinner.Enable = "off";
                    app.TauLimitSpinner.Enable = "off";
                    app.MaxHWSpinner.Enable = "off";
                    app.SDVSpinner.Enable = "off";
                    app.MaxVSpinner.Enable = "off";
                    
                    app.coordinatesNeedUpdate = 0;
                    
                    app.PlayNav.Enable = 1;
                    app.UpdateNavButton.Enable = 1;
                    
                    app.VecMapLamp.Color = 'g';
                    app.TabGroup.SelectedTab = app.NavigatorTab;                
                    
                end
            end
            
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            app.StackLamp.Color = [0.93,0.69,0.13];
            
            [app.StackFileName, app.StackPath] = uigetfile("*.tif");
%             
%             if isempty(app.Opt.CropArea)
                app.Stack = readFileToStack([app.StackPath, app.StackFileName]);
%             else
%                 app.Stack = readFileToStack([app.StackPath, app.StackFileName]);
%                 for i = 1:size(app.Stack,3)
%                     app.Stack(:,:,i) = imcrop(app.Stack(:,:,i), app.Opt.CropArea);
%                 end
%             end
            app.ICS_ExplorerUIFigure.Visible = 'off';
            app.ICS_ExplorerUIFigure.Visible = 'on';            
            if isempty(app.Stack)
                app.StackLamp.Color = 'r';
                return
            else
                app.StackLamp.Color = 'g';
                app.Preview.Visible = 'on';
                imshow(app.Stack(:,:,app.FSpinner.Value), app.DisplayLimits, 'Parent', app.Preview);
%                 imagesc(app.Preview, app.Stack(:,:,app.FSpinner.Value));                
%                 colormap(app.Preview, "gray");
                app.CustomROISlider.Limits = [2, min(size(app.Stack,1), size(app.Stack,2))];
                app.nSpinner.Limits = [1, 6]; % Need to replace by formula later
            end
            app.VecMapLamp.Color = 'r';            
        end

        % Value changed function: LoadfileButton
        function LoadfileButtonValueChanged(app, event)
            value = app.LoadfileButton.Value;
            switch value
                case 1
                    % Turn off Live Mode
                    if app.LiveSTICSButton.Value == 1
                        app.LiveSTICSButton.Value = 0;
                    end
                    
                    % Turn lamp yellow
                    app.VecMapLamp.Color = [0.93,0.69,0.13];
                    
                    % Select and load data
                    [app.VelMapFileName, app.VelMapPath] = uigetfile('*.mat', 'Select the Velocity Map');
                    app.ICS_ExplorerUIFigure.Visible = 'off';
                    app.ICS_ExplorerUIFigure.Visible = 'on';  
                    load([app.VelMapPath, app.VelMapFileName], '-mat');
                    
                    % Check if cancel was clicked            
                    if ~isempty(app.VelMapFileName)
                        app.StackLamp.Color = 'g';
                    else
                        app.StackLamp.Color = 'r';
                        return;                        
                    end                    
                    
                    
                    % Import variables
                    app.VelMap = velocityMap;
                    app.PosX = position_x;
                    app.PosY = position_y;
                    app.PosT = position_t;
                    app.Opt = opt;
                    app.fracROIshift = app.Opt.ROIsize/app.Opt.ROIshift;
                    app.fracTOIshift = app.Opt.TOIsize/app.Opt.TOIshift;
                    
                    % Extract and convert velocities:
                    if ~isfield(app.Opt, 'VelUnits')
                        opt.SaveData = 0;
                        [velocityMap, opt] = convertVelocitiesFromPxPy(velocityMap, position_x, position_y, position_t, opt);
                        app.VelMap = velocityMap;
                        app.Opt = opt;
                    end
                    
                    % Populate controls
                    app.ROIsizeEditField.Value = app.Opt.ROIsize;
                    app.ROIshiftEditField.Value = app.Opt.ROIshift;
                    app.TOIsizeEditField.Value = app.Opt.TOIsize;
                    app.TOIshiftEditField.Value = app.Opt.TOIshift;
                    app.CustomROISlider.Value = app.Opt.ROIsize;
                    app.FitRadiuspxSpinner.Value = app.Opt.fitRadius;
                    app.TauLimitSpinner.Value = app.Opt.tauLimit;
                    if isfield(app.Opt, "maxHalfWidth")
                       app.MaxHWSpinner.Value = app.Opt.maxHalfWidth;
                    elseif isfield(app.Opt, "omegaThreshold")
                        app.Opt.maxHalfWidth = app.Opt.omegaThreshold;
                        app.MaxHWSpinner.Value = app.Opt.maxHalfWidth;
                    end
                    app.SDVSpinner.Value = app.Opt.threshVector;
                    if isfield(app.Opt, "maxV")
                        app.MaxVSpinner.Value = app.Opt.maxV;
                    elseif isfield(app.Opt, "threshV")
                        app.Opt.maxV = app.Opt.threshV;
                        app.MaxVSpinner.Value = app.Opt.maxV;                        
%                         app.Opt.maxV = Inf;
                    end
                                      
                    % Set Ranges and Update Navigator
                    setRanges(app);
                    UpdateNavigator(app);                    


                    % Disable Live STICS controls
                    app.ROIsizeEditField.Enable = 'off';
                    app.UsingLabel.Enable = 'off';
                    app.Formula.Enable = 'off';
                    app.CustomSliderLabel.Enable = 'off';
                    app.CustomROISlider.Enable = 'off';
                    app.nSpinner.Enable = 'off';
                    app.nSpinnerLabel.Enable = 'off';
                    app.ROIshiftEditField.Enable = 'off';
                    app.TOIsizeEditField.Enable = 'off';
                    app.TOIshiftEditField.Enable = 'off';    
                    app.FitRadiuspxSpinner.Enable = "off";
                    app.TauLimitSpinner.Enable = "off";
                    app.MaxHWSpinner.Enable = "off";
                    app.SDVSpinner.Enable = "off";
                    app.MaxVSpinner.Enable = "off";
                    
                    app.coordinatesNeedUpdate = 0;
                    
                    app.PlayNav.Enable = 1;
                    app.UpdateNavButton.Enable = 1;
                    
                    app.VecMapLamp.Color = 'g';
                    app.TabGroup.SelectedTab = app.NavigatorTab;
                case 0
                    app.LiveSTICSButton.Value = 1;
            end
            
        end

        % Value changed function: LiveSTICSButton
        function LiveSTICSButtonValueChanged(app, event)
            value = app.LiveSTICSButton.Value;        
            switch value
                case 1
                    % Turn "Load file" off
                    if app.LoadfileButton.Value == 1
                        app.LoadfileButton.Value = 0;
                    end
                    app.VecMapLamp.Color = 'g';    
                    
                    % Enable set options
                    app.ROIsizeEditField.Enable = 'on';
                    app.ROIshiftEditField.Enable = 'on';
                    app.TOIsizeEditField.Enable = 'on';
                    app.TOIshiftEditField.Enable = 'on';
                    app.UsingLabel.Enable = 'on';
                    app.Formula.Enable = 'on';
                    app.CustomSliderLabel.Enable = 'on';
                    app.CustomROISlider.Enable = 'on';
                    app.nSpinner.Enable = 'on';
                    app.nSpinnerLabel.Enable = 'on';
                    app.FitModeDropDown.Enable = 'on';                        
                    app.FitRadiuspxSpinner.Enable = 'on';
                    app.TauLimitSpinner.Enable = "on";
                    app.MaxVSpinner.Enable = "on";
                    app.MaxHWSpinner.Enable = "on";
                    
                    % Create temporary variables
%                     app.Opt = struct();

                    % Generate default values
                    
                    % Populate fields with default values
                    
                    % Activate Update & Play Navigator 
                    app.PlayNav.Enable = 1;
                    app.UpdateNavButton.Enable = 1;                    
                case 0
            end
        end

        % Value changed function: CustomROISlider
        function CustomROISliderValueChanged(app, event)
            value = app.CustomROISlider.Value;
            if app.LiveSTICSButton.Value == 1
                app.ROIsizeEditField.Value = value;
            end
        end

        % Value changed function: nSpinner
        function nSpinnerValueChanged(app, event)
            value = app.nSpinner.Value;
            if app.LiveSTICSButton.Value == 1 && value <= app.CustomROISlider.Limits(2)
                app.ROIsizeEditField.Value = 2^value;
                app.CustomROISlider.Value = app.ROIsizeEditField.Value;
            end            
        end

        % Button pushed function: UpdateNavButton
        function UpdateROI(app, event)
            if isempty(app.ROIsquare)
                app.ROIsquare = drawrectangle(app.Preview,...
                    "Position", app.CurrentROIcoors,...
                    "AspectRatio",1,"FixedAspectRatio",true,...
                    "InteractionsAllowed","translate");                
            end

            roi = app.ROIsquare;
            oldROIpos = app.CurrentROIcoors;
            newROIpos = dragrect(roi.Position);


            if newROIpos(1) ~= oldROIpos(1) || newROIpos(2) ~= oldROIpos(2) % ROI was dragged
                userCurrX = (newROIpos(1) + (newROIpos(3)/2));
                [~, app.vix] = min(abs(userCurrX - app.PosX(:,1)));
                snapCurrX = app.PosX(app.vix,1);
    %             complicated way:
    %             position_x(position_x(:,1) == userCurrX + min(abs(userCurrX - position_x(:,1))))
                app.XindexSpinner.Value = app.vix;
                
                
                userCurrY = (newROIpos(2) + (newROIpos(4)/2));
                [~, app.viy] = min(abs(userCurrY - app.PosY(1,:)));
                snapCurrY = app.PosY(1,app.viy);
    %             complicated way:
    %             position_x(position_x(:,1) == userCurrX + min(abs(userCurrX - position_x(:,1))))
                app.YindexSpinner.Value = app.viy;
                
                finalROIpos = [snapCurrX - (app.Opt.ROIsize/2),...
                   snapCurrY - (app.Opt.ROIsize/2),...
                   app.Opt.ROIsize, app.Opt.ROIsize];
                app.CurrentPosX = snapCurrX;
                app.CurrentPosY = snapCurrY;
            elseif app.coordinatesNeedUpdate
                app.CurrentFrame = app.FSpinner.Value;
                if mod(app.Opt.TOIsize, 2) ==  0 % TOI size is even
                    app.minK = 1;
                else
                    app.minK = 0;
                end
                app.k = app.CurrentFrame - floor(app.Opt.TOIsize/2) + app.minK; % Vector field frame
                
                app.vix = app.XindexSpinner.Value;
                xpos = app.PosX(app.vix,1);
                app.CurrentPosX = xpos;
                
                app.viy = app.YindexSpinner.Value;
                ypos = app.PosY(1,app.viy);
                app.CurrentPosY = ypos;
                
                finalROIpos = [xpos - (app.Opt.ROIsize/2),...
                   ypos - (app.Opt.ROIsize/2),...
                   app.Opt.ROIsize, app.Opt.ROIsize];                
            else
                finalROIpos = newROIpos;
            end
            
            app.CurrentROIcoors = finalROIpos;

            app.TOIFOV = app.Stack( ...
                floor(1+(app.viy-1)/app.fracROIshift*app.Opt.ROIsize):floor(app.Opt.ROIsize*(1+((app.viy-1)/app.fracROIshift))),...
                floor(1+(app.vix-1)/app.fracROIshift*app.Opt.ROIsize):floor(app.Opt.ROIsize*(1+((app.vix-1)/app.fracROIshift))),...
                floor(1+(app.k-1)/app.fracTOIshift*app.Opt.TOIsize):floor(app.Opt.TOIsize*(1+((app.k-1)/app.fracTOIshift))));
            
            % Wrong version:
%             tf = [floor(1+(app.viy-1)/app.fracROIshift*app.Opt.ROIsize),floor(app.Opt.ROIsize*(1+((app.viy-1)/app.fracROIshift)));
%                 floor(1+(app.vix-1)/app.fracROIshift*app.Opt.ROIsize),floor(app.Opt.ROIsize*(1+((app.vix-1)/app.fracROIshift)));
%                 floor(1+(app.k-1)/app.fracTOIshift*app.Opt.TOIsize),floor(app.Opt.TOIsize*(1+((app.k-1)/app.fracTOIshift)))];
            

            UpdateNavigator(app);
            
            switch app.AppModeButtonGroup.SelectedObject
                case app.VectorFieldViewerButton
                    UpdateVectorField(app);
                case app.ICSExplorerButton
                    TauSpinnerValueChanged(app, event);
            end
            assignin("base","velocityMap", app.VelMap);
            
            if isfield(app.VelMap{app.k}, "VelMap")
                app.VxNode.Text = ['Vx = ', num2str(round(-app.VelMap{app.k}.vx(app.viy,app.vix), 3)), ' um/min'];
                app.VyNode.Text = ['Vy = ', num2str(round(-app.VelMap{app.k}.vy(app.viy,app.vix), 3)), ' um/min'];
                app.VNode.Text = ['V = ', num2str(round(-app.VelMap{app.k}.VelMap(app.viy,app.vix), 3)), ' um/min'];
            end
        
        end

        % Value changed function: FSpinner
        function FSpinnerValueChanged(app, event)
            value = app.FSpinner.Value;
            app.CurrentFrame = value;

            app.coordinatesNeedUpdate = 1;
            
            if app.LiveSTICSButton.Value

                try
                    app.MaxVSpinner.Value = max(app.VelMap{app.k}.VelMap,[],'all');
                catch 
                    FrameButtonPushed(app, event);
                    UpdateROI(app,event);
                end
                UpdateROI(app, event);                
            else
                UpdateROI(app,event);            
            end
            AppModeButtonGroupSelectionChanged(app, event);


        end

        % Value changed function: XindexSpinner, YindexSpinner
        function CoordinatesChanged(app, event)

            app.coordinatesNeedUpdate = 1;
                        
            % Get current positions
            app.vix = app.XindexSpinner.Value;
            app.CurrentPosX = app.PosX(app.vix, 1);            
            app.viy = app.YindexSpinner.Value;
            app.CurrentPosY = app.PosY(1, app.viy);
            
            UpdateROI(app,event);            
        end

        % Close request function: ICS_ExplorerUIFigure
        function ICS_ExplorerUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: FitModeDropDown, TauSpinner
        function TauSpinnerValueChanged(app, event)
            value = app.TauSpinner.Value;
            app.tau = value;
            app.coordinatesNeedUpdate = 1;
            Plot3DButtonPushed(app,event)
            Plot2DButtonPushed(app,event)
            
        end

        % Button pushed function: PlayNav
        function PlayNavButtonPushed(app, event)
            app.PlayNav.Enable = 'off';
            switch app.NavSwitch.Value
                case 'TOI'
                    for frame = app.CurrentFrame-floor(app.Opt.TOIsize/2)+app.minK:app.CurrentFrame+floor(app.Opt.TOIsize/2)+app.minK
                        imshow(app.Stack(:,:,frame+app.minK), app.DisplayLimits, 'Parent', app.Preview);
                        imshow(app.Stack(1+floor(app.CurrentROIcoors(2)):1+floor(app.CurrentROIcoors(2))+app.Opt.ROIsize,...
                            1+floor(app.CurrentROIcoors(1)):1+floor(app.CurrentROIcoors(1))+app.Opt.ROIsize,...
                            frame), app.DisplayLimits, 'Parent', app.Zoom);     
%                         imagesc(app.Preview, app.Stack(:,:,frame+app.minK));
%                         colormap(app.Preview, "gray");
%                         imagesc(app.Zoom, app.Stack(1+floor(app.CurrentROIcoors(2)):1+floor(app.CurrentROIcoors(2))+app.Opt.ROIsize,...
%                             1+floor(app.CurrentROIcoors(1)):1+floor(app.CurrentROIcoors(1))+app.Opt.ROIsize,frame));     
%                         colormap(app.Zoom, "gray");                        
                        drawnow
                    end                    
                case 'All'
                    for frame = app.MinFrame:app.MaxFrame
                        imshow(app.Stack(:,:,frame), app.DisplayLimits, 'Parent', app.Preview);
                        imshow(app.Stack(1+floor(app.CurrentROIcoors(2)):1+floor(app.CurrentROIcoors(2))+app.Opt.ROIsize,...
                            1+floor(app.CurrentROIcoors(1)):1+floor(app.CurrentROIcoors(1))+app.Opt.ROIsize,...
                            frame), app.DisplayLimits, 'Parent', app.Zoom);                                
%                         imagesc(app.Preview, app.Stack(:,:,frame)); colormap(app.Preview, "gray");
%                         imagesc(app.Zoom, app.Stack(1+floor(app.CurrentROIcoors(2)):1+floor(app.CurrentROIcoors(2))+app.Opt.ROIsize,...
%                             1+floor(app.CurrentROIcoors(1)):1+floor(app.CurrentROIcoors(1))+app.Opt.ROIsize,frame));                                
%                         colormap(app.Zoom, "gray");
                        drawnow
                    end                    
            end
            UpdateNavigator(app);
%             % Replaced by Update Navigator function
%             rectangle(app.Preview, 'Position',...
%                 [(1+app.Opt.ROIsize)/2, (1+app.Opt.ROIsize)/2,...
%                 size(app.Stack,1)-app.Opt.ROIsize,...
%                 size(app.Stack,1)-app.Opt.ROIsize],...
%                 'FaceColor','none', 'LineStyle','--');
%             rectangle(app.Preview,'Position',...
%                 [app.CurrentPosX-app.Opt.ROIsize/2,...
%                 app.CurrentPosY-app.Opt.ROIsize/2,...
%                 app.Opt.ROIsize, app.Opt.ROIsize]);
%             app.ROIsquare = drawrectangle(app.Preview, "Position",...
%                 [app.CurrentPosX-app.Opt.ROIsize/2,...
%                 app.CurrentPosY-app.Opt.ROIsize/2,...
%                 app.Opt.ROIsize, app.Opt.ROIsize],...
%                 "AspectRatio",1,"FixedAspectRatio",true,"InteractionsAllowed","translate");
%             app.CurrentROIcoors = app.ROIsquare.Position;
%             hold(app.Preview, 'on');
%     
%             app.maskedPosX = app.PosX.* app.Opt.vectorPositions;
%             app.maskedPosX(app.maskedPosX == 0) = NaN;
%     
%             app.maskedPosY = app.PosY.* app.Opt.vectorPositions;
%             app.maskedPosY(app.maskedPosY == 0) = NaN;
%     
%             plot(app.Preview, app.maskedPosY, app.maskedPosX,'.r', 'MarkerSize',1); 
%             plot(app.Preview, app.CurrentPosX, app.CurrentPosY,'+y', 'LineWidth', 1);           
%             hold(app.Preview, 'off');                                    
            
            app.PlayNav.Enable = 'on';            
        end

        % Button pushed function: Play3D
        function Play3DButtonPushed(app, event)
            app.Play3D.Enable = 'off';
            
            for t = 1:size(app.coeffGfit,1)
                app.tau = t-1;
                app.TauSpinner.Value = app.tau;
                TauSpinnerValueChanged(app,event);
                drawnow;    
            end
            app.tau = 0;
            app.TauSpinner.Value = app.tau;
            TauSpinnerValueChanged(app,event);
            drawnow;

            app.Play3D.Enable = 'on';                        
        end

        % Callback function: Plot2DButton, Show2D
        function Plot2DButtonPushed(app, event)
            app.ICF2D.Visible = 'on';

            UpdateHistogram(app);
            
            if app.AppModeButtonGroup.SelectedObject == app.ICSExplorerButton   
                
                app.Histogram.Visible = "off";
                h = histogram(app.Histogram, 1);
                h.Visible = 'off';                
                
                G = app.coeffGfit;
                switch app.Show2D.Value
                    case 'Correlation Function'
                        title(app.ICF2D, "Spatial Correlation Function");
                        [app.meshX,app.meshY] = meshgrid(-((size(app.corrfn,2)-1)/2)*app.Opt.pixelSize:app.Opt.pixelSize:((size(app.corrfn,2)-1)/2)*app.Opt.pixelSize,...
                            -((size(app.corrfn,1)-1)/2)*app.Opt.pixelSize:app.Opt.pixelSize:((size(app.corrfn,1)-1)/2)*app.Opt.pixelSize);                    
                        colormap(app.ICF2D, "parula");
                        imagesc(app.meshX(1,:), app.meshY(:,1), app.corrfn(:,:,app.tau + 1), 'Parent', app.ICF2D);                         
                        hold(app.ICF2D, 'on');
                        plot(app.ICF2D, G(app.tau+1,5), G(app.tau+1,6), "or","LineWidth",1.5);
                        [~, maxidb] = max(app.corrfn(:,:,app.tau+1),[],"all","linear");
                        plot(app.ICF2D, app.meshX(maxidb), app.meshY(maxidb), "+r","LineWidth",1.5);
                        
                        cirspace = linspace(0,2*pi);
                        fitCircle = plot(app.ICF2D,...
                            app.Opt.fitRadius*app.Opt.pixelSize*cos(cirspace) + app.meshX(maxidb),...
                            app.Opt.fitRadius*app.Opt.pixelSize*sin(cirspace) + app.meshY(maxidb), "--");
                        fitCircle.Color = "r";
                        fitCircle.LineWidth = 1;                        
                        
                        xlabel(app.ICF2D,'\eta (\mum)');
                        ylabel(app.ICF2D,'\xi (\mum)');
                        caxis(app.ICF2D,[min(app.corrfn,[],'all'), max(app.corrfn,[],'all')]);  
                        
                        axis(app.ICF2D, 'image');
    %                     axis(app.ICF2D,"ij");
    %                     app.ICF2D.Box = 'on';
    %                     b = app.ICF2D.BoxStyle;
    %                     b.Color = 'm';
    %                     b.LineWidth = 1;
                        hold(app.ICF2D, 'off');
                    case 'Fit'
                        title(app.ICF2D, [app.FitModeDropDown.Value, " Fit"]);                    
                        [app.meshX,app.meshY] = meshgrid(-((size(app.corrfn,2)-1)/2)*app.Opt.pixelSize:app.Opt.pixelSize:((size(app.corrfn,2)-1)/2)*app.Opt.pixelSize,...
                            -((size(app.corrfn,1)-1)/2)*app.Opt.pixelSize:app.Opt.pixelSize:((size(app.corrfn,1)-1)/2)*app.Opt.pixelSize);                    
                        imagesc(app.Z, 'Parent', app.ICF2D); 
                        hold(app.ICF2D, 'on');
                        plot(app.ICF2D,G(app.tau+1,5)./app.Opt.pixelSize+(app.Opt.ROIsize/2)+1, G(app.tau+1,6)./app.Opt.pixelSize+(app.Opt.ROIsize/2)+1, "+r","LineWidth",1.5);                    
    %                     plot(app.ICF2D,G(5), G(6), "+r","LineWidth",1.5);
                        xlabel(app.ICF2D,'X (px)');
                        ylabel(app.ICF2D,'Y (px)');
                        axis(app.ICF2D, 'image');
    %                     axis(app.ICF2D,"ij");                    
                        hold(app.ICF2D, 'off');                    
                    case 'Residuals'
                    case 'Cross-Sections'
                        if strcmpi(app.AxisViewDropDown.Value, 'Z axis (Top)')
                        elseif strcmpi(app.AxisViewDropDown.Value, 'XY axis (Side)')
                        elseif strcmpi(app.AxisViewDropDown.Value, 'Major-Minor axis')
                        end
                    case 'Projections'
                    case 'Trajectory XY'
                      
                        cmap = parula(size(G,1));
                        for i=1:size(G,1)
                            plot(app.ICF2D, G(i,5), G(i,6), "o-","LineWidth",1.5, "Color", cmap(i,:));
                            [~, maxidb] = max(app.corrfn(:,:,i),[],"all","linear");
                            plot(app.ICF2D, app.meshX(maxidb), app.meshY(maxidb), "+","LineWidth", 1.5, "Color", cmap(i,:));                                
                            hold(app.ICF2D, "on");
                        end
                        plot(app.ICF2D, G(app.tau+1,5), G(app.tau+1,6), "-o","LineWidth",1.5, "Color", cmap(app.tau+1,:), "MarkerFaceColor",cmap(app.tau+1,:));
                    
                        if isfield(app.VelMap{app.k}, "VelMap")
                            Q = quiver(app.ICF2D, G(1,6), G(1,5),...
                                -(app.VelMap{app.k}.vx(app.viy, app.vix)),...
                                -(app.VelMap{app.k}.vy(app.viy, app.vix)), 1);
                            Q.LineWidth = 2;
        %                     Q.AutoScaleFactor = 10;
                        end
                        
                        if ~strcmp(app.FitModeDropDown.Value, "Symmetrical Gaussian")
%                             q = quiver(app.ICF2D, G(1,6), G(1,5),...
%                                 -(app.VelMap{app.k}.vx(app.viy, app.vix)),...
%                                 -(app.VelMap{app.k}.vy(app.viy, app.vix)), 10);
%                             q.LineWidth = 2;
%                             q.LineStyle = "--";                            
                        end
                        
                        xlim(app.ICF2D, [-((size(app.corrfn,2)-1)/2)*app.Opt.pixelSize, ((size(app.corrfn,2)-1)/2)*app.Opt.pixelSize]);
                        ylim(app.ICF2D, [-((size(app.corrfn,2)-1)/2)*app.Opt.pixelSize, ((size(app.corrfn,2)-1)/2)*app.Opt.pixelSize]);
                        
                        axis(app.ICF2D,"square");
                        
                        xlabel(app.ICF2D,'\eta (\mum)');
                        ylabel(app.ICF2D,'\xi (\mum)');
                        

                        hold(app.ICF2D,"off");                        
                        colorbar(app.ICF2D,"off");
                        
                    case 'Displacement XT'
                        
                        plot(app.ICF2D, 0:app.Opt.timeFrame:app.Opt.timeFrame*size(app.coeffGfit,1)-1,...
                            app.coeffGfit(:,5), "o-"); 
                        title(app.ICF2D, 'Displacement in X');
                        xlabel(app.ICF2D,'Time delay (s)'); ylabel(app.ICF2D,'Distance in X (um)');
                        axis(app.ICF2D,"square");
                        xlim(app.ICF2D, [0, app.Opt.timeFrame*(size(app.coeffGfit,1)-1)]);
                        ylim(app.ICF2D, [-max(abs(app.coeffGfit(:,5)))*1.1, max(abs(app.coeffGfit(:,5)))*1.1]);
                        
                        hold(app.ICF2D,"on");
                        
                        
%                         [Ex, deltax] = polyval(app.VelMap{app.k}.Px(), opt.timeFrame*(1:size(app.corrfn,3)), Sx);
%                         xresid = squeeze(coeffGtime(1:size(app.corrfn,3),5))' - Ex; 
%                         SSxresid = sum(xresid.^2); 
%                         SSxtotal = (length(squeeze(coeffGtime(1:size(app.corrfn,3),5))')-1 * var(squeeze(coeffGtime(1:size(app.corrfn,3),5))'));
%                         xrsq = 1 - SSxresid/SSxtotal;
%                         
%                         [Ey, deltay] = polyval(Py, opt.timeFrame*(1:size(app.corrfn,3)), Sy);
%                         yresid = squeeze(coeffGtime(1:size(app.corrfn,3),6))' - Ey; 
%                         SSyresid = sum(yresid.^2); 
%                         SSytotal = (length(squeeze(coeffGtime(1:size(app.corrfn,3),6))')-1 * var(squeeze(coeffGtime(1:size(app.corrfn,3),6))'));
%                         yrsq = 1 - SSyresid/SSytotal;

%                         m = app.VelMap{app.k}.vx(app.vix, app.viy);
%                         t = 0:app.Opt.timeFrame:app.Opt.timeFrame*size(app.coeffGfit,1)-1;
%                         xt = m.*t;
%                         plot(app.ICF2D, xt, t); 
                        
                        hold(app.ICF2D,"off");
                        
                    case 'Displacement YT'
                        
                        plot(app.ICF2D, 0:app.Opt.timeFrame:app.Opt.timeFrame*size(app.coeffGfit,1)-1,...
                            app.coeffGfit(:,6), "o-"); 
                        title(app.ICF2D, 'Displacement in Y');
                        xlabel(app.ICF2D,'Time delay (s)'); ylabel(app.ICF2D,'Distance in Y (um)');
                        axis(app.ICF2D,"square");
                        xlim(app.ICF2D, [0, app.Opt.timeFrame*(size(app.coeffGfit,1)-1)]);  
                        ylim(app.ICF2D, [-max(abs(app.coeffGfit(:,6)))*1.1, max(abs(app.coeffGfit(:,6)))*1.1]);
                        
                    case 'Data'
                        imshow(app.Stack(1+floor(app.CurrentROIcoors(2)):1+floor(app.CurrentROIcoors(2))+app.Opt.ROIsize,...
                            1+floor(app.CurrentROIcoors(1)):1+floor(app.CurrentROIcoors(1))+app.Opt.ROIsize,...
                            app.CurrentFrame), [min(app.Stack(1+floor(app.CurrentROIcoors(2)):1+floor(app.CurrentROIcoors(2))+app.Opt.ROIsize,...
                            1+floor(app.CurrentROIcoors(1)):1+floor(app.CurrentROIcoors(1))+app.Opt.ROIsize,...
                            app.CurrentFrame),[],"all"), max(app.Stack(1+floor(app.CurrentROIcoors(2)):1+floor(app.CurrentROIcoors(2))+app.Opt.ROIsize,...
                            1+floor(app.CurrentROIcoors(1)):1+floor(app.CurrentROIcoors(1))+app.Opt.ROIsize,...
                            app.CurrentFrame),[],"all")], 'Parent', app.ICF2D);
                        axis(app.ICF2D, "image");
                        axis(app.ICF2D, "tight");
                        colorbar(app.ICF2D,"off");
                        xlabel(app.ICF2D, "X");
                        ylabel(app.ICF2D, "Y");  
                end
            elseif app.AppModeButtonGroup.SelectedObject == app.VectorFieldViewerButton
               
                switch app.Show2D.Value
                    case 'Selected ROI'
                        imshow(app.Stack(1+floor(app.CurrentROIcoors(2)):1+floor(app.CurrentROIcoors(2))+app.Opt.ROIsize,...
                            1+floor(app.CurrentROIcoors(1)):1+floor(app.CurrentROIcoors(1))+app.Opt.ROIsize,...
                            app.CurrentFrame), [min(app.Stack(1+floor(app.CurrentROIcoors(2)):1+floor(app.CurrentROIcoors(2))+app.Opt.ROIsize,...
                            1+floor(app.CurrentROIcoors(1)):1+floor(app.CurrentROIcoors(1))+app.Opt.ROIsize,...
                            app.CurrentFrame),[],"all"), max(app.Stack(1+floor(app.CurrentROIcoors(2)):1+floor(app.CurrentROIcoors(2))+app.Opt.ROIsize,...
                            1+floor(app.CurrentROIcoors(1)):1+floor(app.CurrentROIcoors(1))+app.Opt.ROIsize,...
                            app.CurrentFrame),[],"all")], 'Parent', app.ICF2D);
                        
                        Q = quiver(app.CurrentPosX, app.CurrentPosY,...
                            -app.VelMap{app.k}.vx(app.viy, app.vix),-app.VelMap{app.k}.vy(app.viy, app.vix), 100,...
                            "Parent", app.VectorField);
                        Q.Color = 'y';
                        Q.LineWidth = 1;                              
                        
                        axis(app.ICF2D, "image");
                        axis(app.ICF2D, "tight");
                        colorbar(app.ICF2D,"off");
                        xlabel(app.ICF2D, "X");
                        ylabel(app.ICF2D, "Y");     
                    case 'Velocity Map'
                        app.Histogram.Visible = "off";
                        h.Visible = "off"; 
                        if app.MaxVSpinner.Value ~= Inf
                            xl = xline(app.Histogram, app.MaxVSpinner.Value,"LineWidth", 2, "Color", "r");
                        end
                        xl.Visible = "off";
                        title(app.ICF2D, 'Velocity Map (\mum/min)');
                        if isfield(app.VelMap{app.k}, "VelMap")
                            
                            cmap = colormap(app.ICF2D, "jet");
                            cmap(1,:) = [0 0 0];
                            cmap(end,:) = [1 1 1];
                            colormap(app.ICF2D, cmap);
                            
                            switch app.InterpolateMap.Value
                                case 0
                                    Map2D = imagesc(app.ICF2D, app.VelMap{app.k}.VelMap.*app.VelMap{app.k}.goodVectors);
                                    Map2D.Visible = "on";   
                                    
                                    caxis(app.ICF2D,[min(app.VelMap{app.k}.VelMap, [], 'all'),...
                                        round(prctile(app.VelMap{app.k}.VelMap.*app.VelMap{app.k}.goodVectors, 99.75, "all"),2)+1]);
                                    
                                    xlim(app.ICF2D, [0, size(app.VelMap{app.k}.VelMap, 2)]+0.5);
                                    ylim(app.ICF2D, [0, size(app.VelMap{app.k}.VelMap, 1)]+0.5);                                     
                                case 1
                                    img = app.VelMap{app.k}.VelMap.*app.VelMap{app.k}.goodVectors;
                                    Map2D = imagesc(app.ICF2D, imresize(img, app.Opt.ROIshift,"bilinear"));
                                    Map2D.Visible = "on";                        
                                    
                                    caxis(app.ICF2D,[min(app.VelMap{app.k}.VelMap, [], 'all'),...
                                       round(prctile(app.VelMap{app.k}.VelMap.*app.VelMap{app.k}.goodVectors, 99.75, "all"),2)]);

                                    xlim(app.ICF2D, [0, size(img,2)*app.Opt.ROIshift]+0.5);
                                    ylim(app.ICF2D, [0, size(img,1)*app.Opt.ROIshift]+0.5);                                           
                                                                              
                            end
                            
                           
                        end
                        
                        clb = colorbar(app.ICF2D);
                        clb.Label.String = '\it\mum/min';
%                         clb.Label.Rotation = -90;
                        xlabel(app.ICF2D, '\itX (vector index)');
                        ylabel(app.ICF2D, '\itY (vector index)');
                        
%                                                'Flow Angle Map'...
%                        'Difusion Map',...
%                        'Divergence Map',...
%                        'Fit Angle Map',...
%                        'Eccentricity Map',...
%                        'Streamlines'}; 
                    case 'Vx Histogram'
                        app.ICF2D.Visible = "off";
                        if app.HistAbsVals.Value
                            h = histogram(app.Histogram, abs(app.VelMap{app.k}.vx), "BinWidth", 0.025);
                        else
                            h = histogram(app.Histogram, app.VelMap{app.k}.vx, "BinWidth", 0.025);  
                        end
                        h.Visible = "on";
                        title(app.Histogram, 'Velocity Distribution in X (\mum/min)');
                        if app.MaxVSpinner.Value ~= Inf
                            xl = xline(app.Histogram, app.MaxVSpinner.Value,"LineWidth", 2, "Color", "r");
                        end
                        app.Histogram.Visible = "on";                             
                    case 'Vy Histogram'
                        app.ICF2D.Visible = "off";
                        if app.HistAbsVals.Value
                            h = histogram(app.Histogram, abs(app.VelMap{app.k}.vy), "BinWidth", 0.025);
                        else
                            h = histogram(app.Histogram, app.VelMap{app.k}.vy, "BinWidth", 0.025);  
                        end
                        h.Visible = "on";
                        title(app.Histogram, 'Velocity Distribution in Y (\mum/min)');
                        if app.MaxVSpinner.Value ~= Inf
                            xl = xline(app.Histogram, app.MaxVSpinner.Value,"LineWidth", 2, "Color", "r");
                        end
                        app.Histogram.Visible = "on";                             
                    case 'V Histogram'
                        app.ICF2D.Visible = "off";
                        if app.HistAbsVals.Value
                            h = histogram(app.Histogram, abs(app.VelMap{app.k}.VelMap), "BinWidth", 0.025);
                        else
                            h = histogram(app.Histogram, app.VelMap{app.k}.VelMap, "BinWidth", 0.025);  
                        end
                        h.Visible = "on";
                        title(app.Histogram, 'Velocity Distribution (\mum/min)');
                        if app.MaxVSpinner.Value ~= Inf
                            xl = xline(app.Histogram, app.MaxVSpinner.Value,"LineWidth", 2, "Color", "r");
                        end
                        app.Histogram.Visible = "on";                        
                    case 'Flow Angle Map'
                        [app.VelMap, app.Opt] = extractFlowAngles(app.VelMap, app.Opt);
%                         [app.ICF2D, cb, q] = plotFlowAngles(app.VelMap, app.Opt, 1, app.k);
                        colormap(app.ICF2D, "hsv");
                        
                        Map2D = imagesc(app.ICF2D, app.VelMap{app.k}.FlowAngles.*app.VelMap{app.k}.goodVectors);
                        Map2D.Visible = "on";
                        colorbar(app.ICF2D);

                        
                        if isfield(app.Opt, 'AngleUnits')
                            if strcmp(app.Opt.AngleUnits, 'Radians')
                                caxis(app.ICF2D,[0, 2*pi]);
                            else                        
                                caxis(app.ICF2D,[0, 360]);
                            end
                        else
                            caxis(app.ICF2D,[0, 360]);
                        end
                        
                        xlim(app.ICF2D, [0, size(app.VelMap{app.k}.VelMap, 2)]+0.5);
                        ylim(app.ICF2D, [0, size(app.VelMap{app.k}.VelMap, 1)]+0.5);                          
                    case 'Diffusion Map'
                    case 'Divergence Map'
                        
                        div = divergence(app.PosY, app.PosX,...
                            -app.VelMap{app.k}.vx,...
                            -app.VelMap{app.k}.vy);
                        
                        Map2D = imagesc(app.ICF2D, div);
                        Map2D.Visible = 'on';
                        
                        divRange = max([abs(min(div,[],"all")), max(div,[],"all")]);
                        caxis(app.ICF2D,[-divRange divRange]);
                        
%                         Map2D = contourf(app.ICF2D, div);
%                         caxis(app.ICF2D, 'auto');
                        
                    otherwise
                        app.ICF2D.Visible = "off";
                        Map2D = imagesc(app.ICF2D, app.VelMap{app.k}.VelMap);                        
                        Map2D.Visible = "off";
                        colorbar(app.ICF2D, "off");
                end
            end
            
            c = colorbar(app.ICF2D);
            c.Color = [0.9 0.9 0.9];
            
            
        end

        % Button pushed function: Plot3DButton
        function Plot3DButtonPushed(app, event)
            %%% 3D & 2D plots
            app.VectorField.Visible = "off";
%             app.Visualization3DVF.Visible = "off";
            app.ICF3D.Visible = 'on';
            app.Controls3D.Visible = "on";
            
            [app.corrfn] = stics(app.TOIFOV, app.Opt.tauLimit);
            caxis(app.ICF3D, [min(app.corrfn,[],'all'), max(app.corrfn,[],'all')]);               
            if app.LiveSTICSButton.Value          
                switch app.FitModeDropDown.Value
                    case 'Symmetrical Gaussian'
                        % Isotropic
                        fitMode = 'time';
                    case 'Asymmetrical Gaussian'
                        % Asymmetric
                        fitMode = 'timeasym';
                    case 'Rotated Elliptical Gaussian'
                        % Rotated
                        fitMode = 'rotated';                
                end
            elseif app.LoadfileButton.Value
                fitMode = 'time'; % Or fetch the fitMode from Opt
                app.FitModeDropDown.Enable = 0;
            end
            
            [app.coeffGfit] = gaussfit(app.corrfn,fitMode,...
                app.Opt.pixelSize,'n',app.Opt.fitRadius);
            
            newG = zeros(size(app.coeffGfit));
            [app.coeffGfit, ~] = qualityControlCoeffGfit(app.coeffGfit, app.corrfn, app.Opt);
            newG(1:size(app.coeffGfit,1),:) = app.coeffGfit;
            app.coeffGfit = newG;
            
            try 
                app.TauSpinner.Limits = [0, min(app.Opt.tauLimit, size(app.coeffGfit,1))-1];
                app.TauSpinner.Enable = "on";
            catch ME
                app.TauSpinner.Enable = "off";
                app.TauSpinner.Value = 0;
            end
            
            G = app.coeffGfit(app.tau + 1,:);            

            [app.meshX,app.meshY] = meshgrid(-((size(app.corrfn,2)-1)/2)*app.Opt.pixelSize:app.Opt.pixelSize:((size(app.corrfn,2)-1)/2)*app.Opt.pixelSize,...
                -((size(app.corrfn,1)-1)/2)*app.Opt.pixelSize:app.Opt.pixelSize:((size(app.corrfn,1)-1)/2)*app.Opt.pixelSize);
%             % High Resolution Grid
%             h=3; % Resolution factor
%             [xh,yh] = meshgrid(-app.Opt.ROIsize/2:app.Opt.pixelSize/h:app.Opt.ROIsize/2,...
%                 -app.Opt.ROIsize/2:1/h:app.Opt.ROIsize/2);
            
            
            switch fitMode
                case "time"
                    A = G(1);
                    Sx = G(2);
                    Sy = G(3);
                    Offset = G(4);
                    X_0 = G(5);
                    Y_0 = G(6);
                    app.Z = A.*exp(-(((app.meshX-X_0).^2)/(2*Sx.^2) + ((app.meshY-Y_0).^2)/(2*Sy.^2))) + Offset;
                    
            %         app.coeffGfit(:,7) = zeros(size(app.coeffGfit,1),1);
                    G(7) = 0;
                case "timeasym"
                    A = G(1);
                    Sx = G(2);
                    Sy = G(3);
                    Offset = G(4);
                    X_0 = G(5);
                    Y_0 = G(6);
                    app.Z = A.*exp(-(((app.meshX-X_0).^2)/(2*Sx.^2) + ((app.meshY-Y_0).^2)/(2*Sy.^2))) + Offset;
                    
            %         app.coeffGfit(:,7) = zeros(size(app.coeffGfit,1),1);
                    G(7) = 0;
                case "rotated"
                    A = G(1);
                    Sx = G(2);
                    Sy = G(3);
                    X_0 = G(5);
                    Y_0 = G(6);        
                    app.Z = A*exp( -(...
                        ( app.meshX*cos(G(7))-app.meshY*sin(G(7)) - X_0*cos(G(7)) + Y_0*sin(G(7)) ).^2/(2*Sx^2) + ... 
                        ( app.meshX*sin(G(7))+app.meshY*cos(G(7)) - X_0*sin(G(7)) - Y_0*cos(G(7)) ).^2/(2*Sy^2) ) );
            end

            app.TypeNode.Text = ['Type: ', app.FitModeDropDown.Value];
            if correlationSignificance(app.corrfn)
                app.SignificantNode.Text = 'Significance Test: Pass';
            else
                app.SignificantNode.Text = 'Significance Test: Fail';
            end
            app.AmplitudeNode.Text = ['Amplitude: ', num2str(G(1))];
            if strcmp(fitMode, 'rotated')
                app.WidthXNode.Text = ['Major Axis Half Length: ', num2str(G(2)), ' um'];
                app.WidthYNode.Text = ['Minor Axis Half Length: ', num2str(G(3)), ' um'];
%                 eccentricity = (2*G(2))/(2*G(3));
            else
                app.WidthXNode.Text = ['Half Width X: ', num2str(G(2)), ' um'];
                app.WidthYNode.Text = ['Half Width Y: ', num2str(G(3)), ' um'];                
            end
            app.OffsetNode.Text = ['Offset: ', num2str(G(4))];
            app.CenterNode.Text = ['Center: (', num2str(G(5)),', ',num2str(G(6)),') um'];
            app.AngleNode.Text = ['Major axis angle: ', num2str(G(7)), ' rad'];
%             app.EccentricityNode.Text = ['Eccentricity: ', num2str(eccentricity)];


            s = surf(app.ICF3D, app.meshX, app.meshY, app.corrfn(:,:,app.tau+1)); axis tight
            s.EdgeColor = 'none';
            s.FaceAlpha = 0.75;
            
            shading interp
            hold(app.ICF3D, 'on');
            if G(1) > 0
                m = mesh(app.ICF3D, app.meshX, app.meshY, app.Z);
                m.EdgeColor = [0 0 0];
                m.EdgeAlpha = 0.5;
                m.FaceColor = [1,1,1];
                m.FaceAlpha = 0.25;
                
                [~, c] = contour(app.ICF3D, app.meshX, app.meshY, app.corrfn(:,:,app.tau+1));
            end
            xPanPos = 0; yPanPos = 0; zPanPos = 0;
            xPan = fill3(app.ICF3D,[xPanPos;xPanPos;xPanPos;xPanPos],...
                        [app.meshY(1,1);app.meshY(1,1);app.meshY(end,1);app.meshY(end,1)],...
                        [min(app.corrfn(:,:,1),[],'all');max(app.corrfn(:,:,1),[],'all');max(app.corrfn(:,:,1),[],'all');min(app.corrfn(:,:,1),[],'all')],'g');
            xPan.FaceAlpha = 0.15;
            xPan.EdgeColor = 'g'; 
            xPan.LineWidth = 1;
            
            yPan = fill3(app.ICF3D,[app.meshX(1,1);app.meshX(1,1);app.meshX(1,end);app.meshX(1,end)],...
                        [yPanPos;yPanPos;yPanPos;yPanPos],...
                        [min(app.corrfn(:,:,1),[],'all');max(app.corrfn(:,:,1),[],'all');max(app.corrfn(:,:,1),[],'all');min(app.corrfn(:,:,1),[],'all')],'r');
            yPan.FaceAlpha = 0.15;
            yPan.EdgeColor = 'r'; 
            yPan.LineWidth = 1;
            
            zPan = fill3(app.ICF3D,[app.meshX(1,1);app.meshX(1,1);app.meshX(1,end);app.meshX(1,end)],...
                        [app.meshY(1,1);app.meshY(end,1);app.meshY(end,1);app.meshY(1,1)],...
                        [zPanPos;zPanPos;zPanPos;zPanPos],'m');
            zPan.FaceAlpha = 0;
            zPan.EdgeColor = 'm'; 
            zPan.LineWidth = 1;
            
            hold(app.ICF3D, 'off');                
            
            if app.AppModeButtonGroup.SelectedObject == app.VectorFieldViewerButton
                s.Visible = "off";
                m.Visible = "off";
                c.Visible = "off";
                xPan.Visible = "off";
                yPan.Visible = "off";
                zPan.Visible = "off";
            end
        end

        % Callback function: AppModeButtonGroup, ICSExplorerToggle, 
        % ICSExplorerToggle, VectorFieldViewerToggle, 
        % VectorFieldViewerToggle
        function AppModeButtonGroupSelectionChanged(app, event)
            if app.ICSExplorerToggle.State == "on"
                app.VectorFieldViewerToggle.State = "off";
%                 UpdateVectorField(app);
%                 TauSpinnerValueChanged(app,event);                
            elseif app.VectorFieldViewerToggle == "on"
                app.ICSExplorerToggle.State = "off";
%                 TauSpinnerValueChanged(app,event);
%                 UpdateVectorField(app);                
            end
            
            selectedButton = app.AppModeButtonGroup.SelectedObject;
            switch selectedButton
                case app.ICSExplorerButton
                    app.Show2D.Items = {'Correlation Function','Fit',...
                        'Residuals','Cross-Sections','Projections',...
                        'Trajectory XY','Displacement XT',...
                        'Displacement YT','Data'};
                    
                    UpdateVectorField(app);
                    TauSpinnerValueChanged(app,event);
                case app.VectorFieldViewerButton

                    % Update 2D Plot options
                    app.Show2D.Items = {'Velocity Map',...
                        'Vx Histogram',...
                        'Vy Histogram',...
                        'V Histogram',...
                        'Flow Angle Map'...
                        'Difusion Map',...
                        'Divergence Map',...
                        'Fit Angle Map',...
                        'Eccentricity Map',...
                        'Streamlines'};                     
                    
                    TauSpinnerValueChanged(app,event);
                    UpdateVectorField(app);
            end
        end

        % Button pushed function: SetOptionsButton
        function SetOptions(app, event)
            if app.LiveSTICSButton.Value

                frameLocationChanged = 0;     
                vectorPositionsChanged = 0;                   
                
                if isempty(app.Opt)
                    app.Opt = setupSTICS();
                    assignin("base", "opt", app.Opt);
                    app.ROIsizeEditField.Value = app.Opt.ROIsize;
                    app.ROIshiftEditField.Value = app.Opt.ROIshift;
                    app.TOIsizeEditField.Value = app.Opt.TOIsize;
                    app.TOIshiftEditField.Value = app.Opt.TOIshift;  
                    if isempty(app.Opt.CropArea)
                        app.Stack = readFileToStack(app.Opt.filePath{1,1});
                    else
                        app.Stack = readFileToStack(app.Opt.filePath{1,1});
                        for i = 1:size(app.Stack,3)
                            TempStack(:,:,i) = imcrop(app.Stack(:,:,i), app.Opt.CropArea);
                        end
                        app.Stack = [];
                        app.Stack = TempStack;
                    end                    
                    % ^ May need to change "{1,1}" for 2 channels
                    app.FitRadiuspxSpinner.Value = app.Opt.fitRadius;
                    app.TauLimitSpinner.Value = app.Opt.tauLimit;
                    app.MaxHWSpinner.Value = app.Opt.maxHalfWidth;
                    app.SDVSpinner.Value = app.Opt.threshVector;
                    app.MaxVSpinner.Value = app.Opt.maxV;
                    app.FitModeDropDown.Value = 'Symmetrical Gaussian';
                    
                    frameLocationChanged = 1;     
                    vectorPositionsChanged = 1;                    
                    
                else
                    % Check whether the vector frame location changed
                    if app.TOIsizeEditField.Value ~= app.Opt.TOIsize || ...
                            app.TOIshiftEditField.Value ~= app.Opt.TOIshift
                        frameLocationChanged = 1;
                    end
                    
                    % Check whether the vector positions changed
                    if app.ROIsizeEditField.Value ~= app.Opt.ROIsize || ...
                            app.ROIshiftEditField.Value ~= app.Opt.ROIshift                       
                        vectorPositionsChanged = 1;                        
                    end

                    % Retrieve Options
                    app.Opt.ROIsize = app.ROIsizeEditField.Value;
                    app.Opt.ROIshift = app.ROIshiftEditField.Value;
                    app.Opt.TOIsize = app.TOIsizeEditField.Value;
                    app.Opt.TOIshift = app.TOIshiftEditField.Value;                     

                end
                
                if app.CurrentFrame == 1, frameLocationChanged = 1; end
                if frameLocationChanged
                    app.MinFrame = ceil(app.Opt.TOIsize/2);
                    app.MaxFrame = size(app.Stack, 3) - floor(app.Opt.TOIsize/2);            
                    app.FSpinner.Limits = [app.MinFrame, app.MaxFrame];
                    app.FSpinner.Value = app.MinFrame;
                    app.CurrentFrame = app.FSpinner.Value;
                    
                    % Extract vector field frame index (k)
                    if mod(app.Opt.TOIsize, 2) ==  0 % TOI size is even
                        app.minK = 1;
                    else
                        app.minK = 0;
                    end
                    app.k = app.CurrentFrame - floor(app.Opt.TOIsize/2) + app.minK; % Vector field frame                      
                end

                if vectorPositionsChanged
                    % Define New Positions
                    app.fracROIshift = app.Opt.ROIsize/app.Opt.ROIshift;
                    app.fracTOIshift = app.Opt.TOIsize/app.Opt.TOIshift;
                    
                    along_y = floor((size(app.Stack,2)-app.Opt.ROIsize)/app.Opt.ROIshift)+1;
                    along_x = floor((size(app.Stack,1)-app.Opt.ROIsize)/app.Opt.ROIshift)+1;
                    
                    app.PosX = zeros(1,along_x);
                    app.PosY = zeros(1,along_y);
        
                    
                    % defining all of the ROI centers positons in x and y
                    for i = 1:along_x
                        app.PosX(i) = 1/2 + (i-1)/app.fracROIshift*app.Opt.ROIsize + app.Opt.ROIsize/2;
                    end
                    position_x = repmat(app.PosX,along_y,1);
                    app.PosX = position_x';
                    
                    for j = 1:along_y
                        app.PosY(j) = 1/2 + (j-1)/app.fracROIshift*app.Opt.ROIsize + app.Opt.ROIsize/2;
                    end
                    position_y = repmat(app.PosY ,along_x, 1);
                    app.PosY = position_y;
                    
                    if min(app.Opt.maskCell,[],"all") == 0 % Mask is not just ones
                        vectorPositions = zeros(along_x,along_y);
                        for w = 1:along_x
                            for s = 1:along_y
                                indx = position_x(w,1);
                                indy = position_y(1,s); 
                                indx = floor(indx);
                                indy = floor(indy);
                                if(app.Opt.maskCell(indx,indy)==1)
                                    vectorPositions(w,s)=1;
                                end
                            end
                        end
                        app.Opt.vectorPositions = vectorPositions;
                    else
                        app.Opt.vectorPositions = ones(size(app.PosX));
                    end
    
                    app.XindexSpinner.Value = 1;
                    app.YindexSpinner.Value = 1;                    
                end
                app.LiveSTICSButton.Value = 0;                
                app.LiveSTICSButton.Value = 1;
            end
            
            app.FrameButton.Enable = "on";
            app.StackButton.Enable = "on";
            
            app.UpdateNavButton.Enable = "on";
            app.PlayNav.Enable = "on";
                    
            setRanges(app);
            
            frameLocationChanged = 0;     
            vectorPositionsChanged = 0;   
                     
%             app.TabGroup.SelectedTab = app.NavigatorTab;
        end

        % Button pushed function: FrameButton
        function FrameButtonPushed(app, event)
%             app.FSpinner.Limits = [app.CurrentFrame];
            app.VecMapLamp.Color = [0.93,0.69,0.13];
            app.Opt.AllFrames = 0;
            app.Opt.FrameRange = app.CurrentFrame;            
            
            app.Opt.SaveData = 0;
            app.Opt.ProgressBar = 1;
            GenerateVectorMap(app);
%             plotSingleVectorMapOnImage(app.VelMap, app.PosT, app.PosX, app.PosY, app.Opt, app.k);

            app.coordinatesNeedUpdate = 1;
            UpdateROI(app, event);
            app.TabGroup.SelectedTab = app.NavigatorTab;
            
            if app.AppModeButtonGroup.SelectedObject == app.ICSExplorerButton
                Plot3DButtonPushed(app);
                Plot2DButtonPushed(app);
            elseif app.AppModeButtonGroup.SelectedObject == app.VectorFieldViewerButton
                UpdateVectorField(app);
            end
            
            app.VecMapLamp.Color = 'g';            
        end

        % Button pushed function: StackButton
        function StackButtonPushed(app, event)
%             setRanges(app);
            app.VecMapLamp.Color = [0.93,0.69,0.13];
            app.Opt.AllFrames = 1;

            fig = uifigure;
            msg = 'Save results? Saving could overwrite previous files.';
            title = 'Confirm Save';
            selection = uiconfirm(fig,msg,title,...
                       'Options',{'Save','Run without saving','Cancel'},...
                       'DefaultOption', 2, 'CancelOption', 3);            
            if strcmp(selection, 'Run without saving')
                app.Opt.SaveData = 0;
            elseif strcmp(selection, 'Save')
                app.Opt.SaveData = 1;
            end 
            close(fig);
            
            app.Opt.ProgressBar = 1;            
            GenerateVectorMap(app);
            app.VecMapLamp.Color = 'g';
            UpdateROI(app, event);
            app.TabGroup.SelectedTab = app.NavigatorTab;
            
            if app.AppModeButtonGroup.SelectedObject == app.ICSExplorerButton
                Plot3DButtonPushed(app);
                Plot2DButtonPushed(app);
            elseif app.AppModeButtonGroup.SelectedObject == app.VectorFieldViewerButton
                UpdateVectorField(app);
            end                 
        end

        % Button pushed function: PlayVF
        function PlayVFButtonPushed(app, event)
%             app.PlayVF.Enable = 'off';
            switch app.VFSwitch.Value
                case 'TOI'
                    for frame = app.CurrentFrame-floor(app.Opt.TOIsize/2)+app.minK:app.CurrentFrame+floor(app.Opt.TOIsize/2)+app.minK
                        app.FSpinner.Value = frame;
                        FSpinnerValueChanged(app,event);                              
                        drawnow
                    end                    
                case 'All'
                    for frame = app.MinFrame:app.MaxFrame
                        app.FSpinner.Value = frame;                        
                        FSpinnerValueChanged(app,event);                                                      
                        drawnow
                    end                    
            end            
        end

        % Value changed function: FitRadiuspxSpinner
        function FitRadiuspxSpinnerValueChanged(app, event)
            value = app.FitRadiuspxSpinner.Value;
            app.Opt.fitRadius = value;
            switch app.AppModeButtonGroup.SelectedObject
                case app.ICSExplorerButton
                    TauSpinnerValueChanged(app, event);
                case app.VectorFieldViewerButton
                    FrameButtonPushed(app, event);
            end
        end

        % Button pushed function: Set2DVizButton
        function Set2DVisButtonPushed(app, event)
            if app.Visualization2D.Visible
                app.Visualization2D.Visible = "off";
            elseif ~app.Visualization2D.Visible
                app.Visualization2D.Visible = "on";
            end
        end

        % Value changed function: TauLimitSpinner
        function TauLimitSpinnerValueChanged(app, event)
            value = app.TauLimitSpinner.Value;
            app.Opt.tauLimit = value;
            if app.TauSpinner.Value > value
                app.TauSpinner.Value = 0;
            end
            if app.AppModeButtonGroup.SelectedObject == app.ICSExplorerButton
                TauSpinnerValueChanged(app, event);
            elseif  app.AppModeButtonGroup.SelectedObject == app.VectorFieldViewerButton
                
            end
                
        end

        % Value changed function: HistAbsVals
        function HistAbsValsValueChanged(app, event)
            Plot2DButtonPushed(app,event);
            
        end

        % Value changed function: MaxVSpinner
        function MaxVSpinnerValueChanged(app, event)
            value = app.MaxVSpinner.Value;
%             xline(app.Histogram, value,"LineWidth", 2, "Color", "r");
            app.Opt.maxV = value;

        end

        % Value changed function: MaxHWSpinner
        function MaxHWSpinnerValueChanged(app, event)
            value = app.MaxHWSpinner.Value;
            app.Opt.maxHalfWidth = value;
      
        end

        % Value changed function: VFColorScaleCheckBox
        function VFColorScaleCheckBoxValueChanged(app, event)
            UpdateVectorField(app);
            
        end

        % Button pushed function: Set2DVizButton_2
        function Set2DVizButton_2Pushed(app, event)
            if app.Visualization3DVF.Visible
                app.Visualization3DVF.Visible = "off";
            elseif ~app.Visualization3DVF.Visible
                app.Visualization3DVF.Visible = "on";
            end
        end

        % Value changed function: InterpolateMap
        function InterpolateMapValueChanged(app, event)
            Plot2DButtonPushed(app,event);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ICS_ExplorerUIFigure = uifigure('Visible', 'off');
            app.ICS_ExplorerUIFigure.Name = 'ICS_Explorer';
            app.ICS_ExplorerUIFigure.CloseRequestFcn = createCallbackFcn(app, @ICS_ExplorerUIFigureCloseRequest, true);
            app.Menu = uimenu(app.ICS_ExplorerUIFigure);
            app.Menu.Text = 'Menu';
            app.Toolbar = uitoolbar(app.ICS_ExplorerUIFigure);
            app.UpdateTool = uipushtool(app.Toolbar);
            app.StopTool = uipushtool(app.Toolbar);
            app.ICSExplorerToggle = uitoggletool(app.Toolbar);
            app.ICSExplorerToggle.ClickedCallback = createCallbackFcn(app, @AppModeButtonGroupSelectionChanged, true);
            app.ICSExplorerToggle.Separator = 'on';
            app.ICSExplorerToggle.OnCallback = createCallbackFcn(app, @AppModeButtonGroupSelectionChanged, true);
            app.ICSExplorerToggle.State = 'on';
            app.VectorFieldViewerToggle = uitoggletool(app.Toolbar);
            app.VectorFieldViewerToggle.ClickedCallback = createCallbackFcn(app, @AppModeButtonGroupSelectionChanged, true);
            app.VectorFieldViewerToggle.OnCallback = createCallbackFcn(app, @AppModeButtonGroupSelectionChanged, true);
            app.GridLayout = uigridlayout(app.ICS_ExplorerUIFigure);
            app.ICF3D = uiaxes(app.GridLayout);
            title(app.ICF3D, '3D Plot')
            xlabel(app.ICF3D, 'X')
            ylabel(app.ICF3D, 'Y')
            zlabel(app.ICF3D, 'Z')
            app.ICF2D = uiaxes(app.GridLayout);
            title(app.ICF2D, '2D Plot')
            xlabel(app.ICF2D, 'X')
            ylabel(app.ICF2D, 'Y')
            zlabel(app.ICF2D, 'Z')
            app.VectorField = uiaxes(app.GridLayout);
            title(app.VectorField, 'Vector Field')
            xlabel(app.VectorField, 'X \it (\mum)')
            ylabel(app.VectorField, 'Y \it (\mum)')
            zlabel(app.VectorField, 'Z')
            app.VectorField.Visible = 'off';
            app.Histogram = uiaxes(app.GridLayout);
            title(app.Histogram, 'Histogram')
            xlabel(app.Histogram, 'X')
            ylabel(app.Histogram, 'Y')
            zlabel(app.Histogram, 'Z')
            app.Histogram.Visible = 'off';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.SelectTab = uitab(app.TabGroup);
            app.SelectTab.Title = 'Select';
            app.GridLayout2 = uigridlayout(app.SelectTab);
            app.OptionsPanel = uipanel(app.GridLayout2);
            app.OptionsPanel.Title = 'Options';
            app.nSpinnerLabel = uilabel(app.OptionsPanel);
            app.nSpinnerLabel.Text = 'n = ';
            app.nSpinner = uispinner(app.OptionsPanel);
            app.nSpinner.ValueChangedFcn = createCallbackFcn(app, @nSpinnerValueChanged, true);
            app.CustomSliderLabel = uilabel(app.OptionsPanel);
            app.CustomSliderLabel.Text = 'Custom:';
            app.CustomROISlider = uislider(app.OptionsPanel);
            app.CustomROISlider.ValueChangedFcn = createCallbackFcn(app, @CustomROISliderValueChanged, true);
            app.ROIshiftEditFieldLabel = uilabel(app.OptionsPanel);
            app.ROIshiftEditFieldLabel.Text = 'shift:';
            app.ROIshiftEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.TOIsizeEditFieldLabel = uilabel(app.OptionsPanel);
            app.TOIsizeEditFieldLabel.Text = 'TOI size:';
            app.TOIsizeEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.TOIshiftEditFieldLabel = uilabel(app.OptionsPanel);
            app.TOIshiftEditFieldLabel.Text = 'shift:';
            app.TOIshiftEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.ROIsizeLabel = uilabel(app.OptionsPanel);
            app.ROIsizeLabel.Text = 'ROI size:';
            app.ROIsizeEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.UsingLabel = uilabel(app.OptionsPanel);
            app.UsingLabel.Text = 'Using';
            app.Formula = uilabel(app.OptionsPanel);
            app.Formula.Text = '2n';
            app.SetOptionsButton = uibutton(app.OptionsPanel, 'push');
            app.SetOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @SetOptions, true);
            app.SetOptionsButton.Text = 'Set Options';
            app.FrameButton = uibutton(app.OptionsPanel, 'push');
            app.FrameButton.ButtonPushedFcn = createCallbackFcn(app, @FrameButtonPushed, true);
            app.FrameButton.Text = 'Frame';
            app.StackButton = uibutton(app.OptionsPanel, 'push');
            app.StackButton.ButtonPushedFcn = createCallbackFcn(app, @StackButtonPushed, true);
            app.StackButton.Text = 'Stack';
            app.SourcePanel = uipanel(app.GridLayout2);
            app.SourcePanel.Title = 'Source';
            app.SelectfileButton = uibutton(app.SourcePanel, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.StackLamp = uilamp(app.SourcePanel);
            app.VecMapLamp = uilamp(app.SourcePanel);
            app.LoadfileButton = uibutton(app.SourcePanel, 'state');
            app.LoadfileButton.ValueChangedFcn = createCallbackFcn(app, @LoadfileButtonValueChanged, true);
            app.LoadfileButton.Text = 'Load file';
            app.LiveSTICSButton = uibutton(app.SourcePanel, 'state');
            app.LiveSTICSButton.ValueChangedFcn = createCallbackFcn(app, @LiveSTICSButtonValueChanged, true);
            app.LiveSTICSButton.Text = 'Live mode';
            app.CheckBox = uicheckbox(app.SourcePanel);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Visible = 'off';
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.SourcePanel);
            app.CheckBox_2.Enable = 'off';
            app.CheckBox_2.Visible = 'off';
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.SourcePanel);
            app.CheckBox_3.Enable = 'off';
            app.CheckBox_3.Visible = 'off';
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.SourcePanel);
            app.CheckBox_4.Enable = 'off';
            app.CheckBox_4.Visible = 'off';
            app.CheckBox_4.Text = '';
            app.Ch1Label = uilabel(app.SourcePanel);
            app.Ch1Label.Visible = 'off';
            app.Ch1Label.Text = 'Ch 1';
            app.Label = uilabel(app.SourcePanel);
            app.Label.Visible = 'off';
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.SourcePanel);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.SourcePanel);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '2';
            app.ImagestackLabel = uilabel(app.SourcePanel);
            app.ImagestackLabel.Text = 'Image stack:';
            app.VectormapLabel = uilabel(app.SourcePanel);
            app.VectormapLabel.Text = 'Vector map:';
            app.AppModeButtonGroup = uibuttongroup(app.GridLayout2);
            app.AppModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AppModeButtonGroupSelectionChanged, true);
            app.AppModeButtonGroup.Title = 'App Mode';
            app.ICSExplorerButton = uitogglebutton(app.AppModeButtonGroup);
            app.ICSExplorerButton.Text = 'ICS Explorer';
            app.ICSExplorerButton.Value = true;
            app.VectorFieldViewerButton = uitogglebutton(app.AppModeButtonGroup);
            app.VectorFieldViewerButton.Text = 'Vector Field Viewer';
            app.NavigatorTab = uitab(app.TabGroup);
            app.NavigatorTab.Title = 'Navigator';
            app.GridLayout3 = uigridlayout(app.NavigatorTab);
            app.NavigatorPanel = uipanel(app.GridLayout3);
            app.NavigatorPanel.Title = 'Navigator';
            app.Zoom = uiaxes(app.NavigatorPanel);
            zlabel(app.Zoom, 'Z')
            app.Zoom.XTick = [];
            app.Zoom.YTick = [];
            app.Preview = uiaxes(app.NavigatorPanel);
            zlabel(app.Preview, 'Z')
            app.Preview.XLimitMethod = 'tight';
            app.Preview.YLimitMethod = 'tight';
            app.Preview.XTick = [];
            app.Preview.YTick = [];
            app.UpdateNavButton = uibutton(app.NavigatorPanel, 'push');
            app.UpdateNavButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateROI, true);
            app.UpdateNavButton.Text = 'Update';
            app.PlayNav = uibutton(app.NavigatorPanel, 'push');
            app.PlayNav.ButtonPushedFcn = createCallbackFcn(app, @PlayNavButtonPushed, true);
            app.PlayNav.Text = 'Play';
            app.FLabel = uilabel(app.NavigatorPanel);
            app.FLabel.Text = 'F';
            app.FSpinner = uispinner(app.NavigatorPanel);
            app.FSpinner.Limits = [1 100];
            app.FSpinner.ValueChangedFcn = createCallbackFcn(app, @FSpinnerValueChanged, true);
            app.FSpinner.Value = 1;
            app.XSpinnerLabel = uilabel(app.NavigatorPanel);
            app.XSpinnerLabel.Text = 'X';
            app.XindexSpinner = uispinner(app.NavigatorPanel);
            app.XindexSpinner.Limits = [1 100];
            app.XindexSpinner.ValueChangedFcn = createCallbackFcn(app, @CoordinatesChanged, true);
            app.XindexSpinner.Value = 1;
            app.YSpinnerLabel = uilabel(app.NavigatorPanel);
            app.YSpinnerLabel.Text = 'Y';
            app.YindexSpinner = uispinner(app.NavigatorPanel);
            app.YindexSpinner.Limits = [1 100];
            app.YindexSpinner.ValueChangedFcn = createCallbackFcn(app, @CoordinatesChanged, true);
            app.YindexSpinner.Value = 1;
            app.NavSwitch = uiswitch(app.NavigatorPanel, 'slider');
            app.NavSwitch.Items = {'TOI', 'All'};
            app.NavSwitch.Value = 'TOI';
            app.CSpinnerLabel = uilabel(app.NavigatorPanel);
            app.CSpinnerLabel.Text = 'C';
            app.CSpinner = uispinner(app.NavigatorPanel);
            app.CSpinner.Limits = [1 100];
            app.CSpinner.Value = 1;
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.GridLayout4 = uigridlayout(app.InfoTab);
            app.Tree = uitree(app.GridLayout4);
            app.MetadataNode = uitreenode(app.Tree);
            app.MetadataNode.Text = 'Metadata';
            app.PixelSizeNode = uitreenode(app.MetadataNode);
            app.PixelSizeNode.Text = 'Pixel Size';
            app.FramerateNode = uitreenode(app.MetadataNode);
            app.FramerateNode.Text = 'Frame rate';
            app.OptionsNode = uitreenode(app.Tree);
            app.OptionsNode.Text = 'Options';
            app.ROIsizeNode = uitreenode(app.OptionsNode);
            app.ROIsizeNode.Text = 'ROI size:';
            app.ROIshiftNode = uitreenode(app.OptionsNode);
            app.ROIshiftNode.Text = 'ROI shift:';
            app.TOIsizeNode = uitreenode(app.OptionsNode);
            app.TOIsizeNode.Text = 'TOI size:';
            app.TOIshiftNode = uitreenode(app.OptionsNode);
            app.TOIshiftNode.Text = 'TOI shift:';
            app.TaulimitNode = uitreenode(app.OptionsNode);
            app.TaulimitNode.Text = 'Tau limit:';
            app.VectorMapNode = uitreenode(app.Tree);
            app.VectorMapNode.Text = 'Vector Map';
            app.Node_2 = uitreenode(app.VectorMapNode);
            app.Node_2.Text = 'Node';
            app.VectorNode = uitreenode(app.Tree);
            app.VectorNode.Text = 'Vector';
            app.VNode = uitreenode(app.VectorNode);
            app.VNode.Text = 'V';
            app.VxNode = uitreenode(app.VectorNode);
            app.VxNode.Text = 'Vx';
            app.VyNode = uitreenode(app.VectorNode);
            app.VyNode.Text = 'Vy';
            app.PxNode = uitreenode(app.VectorNode);
            app.PxNode.Text = 'Px';
            app.PyNode = uitreenode(app.VectorNode);
            app.PyNode.Text = 'Py';
            app.Node_3 = uitreenode(app.VectorNode);
            app.Node_3.Text = 'Node';
            app.GaussianNode = uitreenode(app.Tree);
            app.GaussianNode.Text = 'Gaussian';
            app.TypeNode = uitreenode(app.GaussianNode);
            app.TypeNode.Text = 'Type';
            app.SignificantNode = uitreenode(app.GaussianNode);
            app.SignificantNode.Text = 'Significant';
            app.AmplitudeNode = uitreenode(app.GaussianNode);
            app.AmplitudeNode.Text = 'Amplitude';
            app.WidthXNode = uitreenode(app.GaussianNode);
            app.WidthXNode.Text = 'Width X';
            app.WidthYNode = uitreenode(app.GaussianNode);
            app.WidthYNode.Text = 'Width Y';
            app.OffsetNode = uitreenode(app.GaussianNode);
            app.OffsetNode.Text = 'Offset';
            app.CenterNode = uitreenode(app.GaussianNode);
            app.CenterNode.Text = 'Center';
            app.AngleNode = uitreenode(app.GaussianNode);
            app.AngleNode.Text = 'Angle';
            app.EccentricityNode = uitreenode(app.GaussianNode);
            app.EccentricityNode.Text = 'Eccentricity';
            app.ResidualsNormNode = uitreenode(app.GaussianNode);
            app.ResidualsNormNode.Text = 'Residuals Norm';
            app.GOFNode = uitreenode(app.GaussianNode);
            app.GOFNode.Text = 'GOF';
            app.Controls3D = uipanel(app.GridLayout);
            app.Controls3D.Title = '3D Controls';
            app.Plot3DButton = uibutton(app.Controls3D, 'push');
            app.Plot3DButton.ButtonPushedFcn = createCallbackFcn(app, @Plot3DButtonPushed, true);
            app.Plot3DButton.Text = 'Plot 3D';
            app.Play3D = uibutton(app.Controls3D, 'push');
            app.Play3D.ButtonPushedFcn = createCallbackFcn(app, @Play3DButtonPushed, true);
            app.Play3D.Text = 'Play';
            app.FitModeLabel = uilabel(app.Controls3D);
            app.FitModeLabel.Text = 'Fit Mode';
            app.FitModeDropDown = uidropdown(app.Controls3D);
            app.FitModeDropDown.Items = {'Symmetrical Gaussian', 'Asymmetrical Gaussian', 'Rotated Elliptical Gaussian'};
            app.FitModeDropDown.ValueChangedFcn = createCallbackFcn(app, @TauSpinnerValueChanged, true);
            app.FitModeDropDown.Value = 'Symmetrical Gaussian';
            app.TauSpinnerLabel = uilabel(app.Controls3D);
            app.TauSpinnerLabel.Text = 'Tau';
            app.TauSpinner = uispinner(app.Controls3D);
            app.TauSpinner.Limits = [0 Inf];
            app.TauSpinner.ValueChangedFcn = createCallbackFcn(app, @TauSpinnerValueChanged, true);
            app.FitRadiuspxSpinnerLabel = uilabel(app.Controls3D);
            app.FitRadiuspxSpinnerLabel.Text = 'Fit Radius (px)';
            app.FitRadiuspxSpinner = uispinner(app.Controls3D);
            app.FitRadiuspxSpinner.Limits = [1 Inf];
            app.FitRadiuspxSpinner.ValueChangedFcn = createCallbackFcn(app, @FitRadiuspxSpinnerValueChanged, true);
            app.FitRadiuspxSpinner.Value = 1;
            app.TauLimitSpinnerLabel = uilabel(app.Controls3D);
            app.TauLimitSpinnerLabel.Text = 'Tau Limit';
            app.TauLimitSpinner = uispinner(app.Controls3D);
            app.TauLimitSpinner.Limits = [1 Inf];
            app.TauLimitSpinner.ValueChangedFcn = createCallbackFcn(app, @TauLimitSpinnerValueChanged, true);
            app.TauLimitSpinner.Value = 1;
            app.MaxHWCheckBox = uicheckbox(app.Controls3D);
            app.MaxHWCheckBox.Text = '';
            app.MaxHWSpinnerLabel = uilabel(app.Controls3D);
            app.MaxHWSpinnerLabel.Text = 'Max Half Width';
            app.MaxHWSpinner = uispinner(app.Controls3D);
            app.MaxHWSpinner.Step = 0.1;
            app.MaxHWSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxHWSpinnerValueChanged, true);
            app.SDVCheckBox = uicheckbox(app.Controls3D);
            app.SDVCheckBox.Text = '';
            app.SDVLabel = uilabel(app.Controls3D);
            app.SDVLabel.Text = 'SD( | V | )';
            app.SDVSpinner = uispinner(app.Controls3D);
            app.VelThresholdCheckBox = uicheckbox(app.Controls3D);
            app.VelThresholdCheckBox.Text = '';
            app.MaxVSpinnerLabel = uilabel(app.Controls3D);
            app.MaxVSpinnerLabel.Text = 'Max |V|';
            app.MaxVSpinner = uispinner(app.Controls3D);
            app.MaxVSpinner.Step = 0.05;
            app.MaxVSpinner.Limits = [0 Inf];
            app.MaxVSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxVSpinnerValueChanged, true);
            app.Set2DVizButton_2 = uibutton(app.Controls3D, 'push');
            app.Set2DVizButton_2.ButtonPushedFcn = createCallbackFcn(app, @Set2DVizButton_2Pushed, true);
            app.Set2DVizButton_2.Icon = 'artist-palette_1f3a8.png';
            app.Set2DVizButton_2.Text = '';
            app.Controls2D = uipanel(app.GridLayout);
            app.Controls2D.Title = '2D Controls';
            app.Plot2DButton = uibutton(app.Controls2D, 'push');
            app.Plot2DButton.ButtonPushedFcn = createCallbackFcn(app, @Plot2DButtonPushed, true);
            app.Plot2DButton.Text = 'Plot 2D';
            app.Set2DVizButton = uibutton(app.Controls2D, 'push');
            app.Set2DVizButton.ButtonPushedFcn = createCallbackFcn(app, @Set2DVisButtonPushed, true);
            app.Set2DVizButton.Icon = 'artist-palette_1f3a8.png';
            app.Set2DVizButton.Text = '';
            app.AxisViewDropDownLabel = uilabel(app.Controls2D);
            app.AxisViewDropDownLabel.Text = 'Axis View:';
            app.AxisViewDropDown = uidropdown(app.Controls2D);
            app.AxisViewDropDown.Items = {'Z axis (Top)', 'XY axis (Side)', 'Major-Minor axis'};
            app.AxisViewDropDown.Value = 'Z axis (Top)';
            app.ShowDropDownLabel = uilabel(app.Controls2D);
            app.ShowDropDownLabel.Text = 'Show:';
            app.Show2D = uidropdown(app.Controls2D);
            app.Show2D.Items = {'Correlation Function', 'Fit', 'Residuals', 'Cross-Sections', 'Projections', 'Trajectory XY', 'Displacement XT', 'Displacement YT', 'Data'};
            app.Show2D.ValueChangedFcn = createCallbackFcn(app, @Plot2DButtonPushed, true);
            app.Show2D.Value = 'Correlation Function';
            app.Visualization3DVF = uipanel(app.GridLayout);
            app.Visualization3DVF.Title = '<< Visualization Controls';
            app.Visualization3DVF.Visible = 'off';
            app.TabGroup2 = uitabgroup(app.Visualization3DVF);
            app.DPlotTab = uitab(app.TabGroup2);
            app.DPlotTab.Title = '3D Plot';
            app.XPanelCheckBox = uicheckbox(app.DPlotTab);
            app.XPanelCheckBox.Text = 'X Panel';
            app.XPanelCheckBox.Value = true;
            app.YPanelCheckBox = uicheckbox(app.DPlotTab);
            app.YPanelCheckBox.Text = 'Y Panel';
            app.YPanelCheckBox.Value = true;
            app.ZPanelCheckBox = uicheckbox(app.DPlotTab);
            app.ZPanelCheckBox.Text = 'Z Panel';
            app.ZPanelCheckBox.Value = true;
            app.CorrelationCheckBox = uicheckbox(app.DPlotTab);
            app.CorrelationCheckBox.Text = 'Correlation';
            app.CorrelationCheckBox.Value = true;
            app.FitCheckBox = uicheckbox(app.DPlotTab);
            app.FitCheckBox.Text = 'Fit';
            app.FitCheckBox.Value = true;
            app.ResidualsCheckBox = uicheckbox(app.DPlotTab);
            app.ResidualsCheckBox.Text = 'Residuals';
            app.ContourCheckBox = uicheckbox(app.DPlotTab);
            app.ContourCheckBox.Text = 'Contour';
            app.ContourCheckBox.Value = true;
            app.asDropDownLabel = uilabel(app.DPlotTab);
            app.asDropDownLabel.Text = 'as:';
            app.asDropDown = uidropdown(app.DPlotTab);
            app.asDropDown.Items = {'Surface', 'Mesh'};
            app.asDropDown.Value = 'Surface';
            app.asDropDown_2 = uidropdown(app.DPlotTab);
            app.asDropDown_2.Items = {'Surface', 'Mesh'};
            app.asDropDown_2.Value = 'Mesh';
            app.asDropDown_2Label = uilabel(app.DPlotTab);
            app.asDropDown_2Label.Text = 'as:';
            app.asDropDown_3 = uidropdown(app.DPlotTab);
            app.asDropDown_3.Items = {'Surface', 'Mesh'};
            app.asDropDown_3.Value = 'Surface';
            app.asDropDown_3Label = uilabel(app.DPlotTab);
            app.asDropDown_3Label.Text = 'as:';
            app.VectorFieldTab = uitab(app.TabGroup2);
            app.VectorFieldTab.Title = 'Vector Field';
            app.VFSwitch = uiswitch(app.VectorFieldTab, 'slider');
            app.VFSwitch.Items = {'TOI', 'All'};
            app.VFSwitch.Value = 'TOI';
            app.PlayVF = uibutton(app.VectorFieldTab, 'push');
            app.PlayVF.ButtonPushedFcn = createCallbackFcn(app, @PlayVFButtonPushed, true);
            app.PlayVF.Text = 'Play';
            app.VFColorScaleCheckBox = uicheckbox(app.VectorFieldTab);
            app.VFColorScaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @VFColorScaleCheckBoxValueChanged, true);
            app.VFColorScaleCheckBox.Text = 'Color Scale';
            app.Visualization2D = uipanel(app.GridLayout);
            app.Visualization2D.Title = '2D Visualization Controls >>';
            app.Visualization2D.Visible = 'off';
            app.HistAbsVals = uicheckbox(app.Visualization2D);
            app.HistAbsVals.ValueChangedFcn = createCallbackFcn(app, @HistAbsValsValueChanged, true);
            app.HistAbsVals.Text = 'Absolute values';
            app.HistAbsVals.Value = true;
            app.InterpolateMap = uicheckbox(app.Visualization2D);
            app.InterpolateMap.ValueChangedFcn = createCallbackFcn(app, @InterpolateMapValueChanged, true);
            app.InterpolateMap.Text = 'Interpolate Map';
            % Show the figure after all components are created
            app.ICS_ExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICS_Explorer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ICS_ExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ICS_ExplorerUIFigure)
        end
    end
end