classdef STICS_App_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        RightPanel                  matlab.ui.container.Panel
        TabGroup                    matlab.ui.container.TabGroup
        FilesMasksTab               matlab.ui.container.Tab
        SelectfilestoanalyzePanel   matlab.ui.container.Panel
        FileIDListBoxLabel          matlab.ui.control.Label
        FileIDListBox               matlab.ui.control.ListBox
        AddButton                   matlab.ui.control.Button
        RemoveButton                matlab.ui.control.Button
        Panel2                      matlab.ui.container.Panel
        Image                       matlab.ui.control.Image
        FrameSliderLabel            matlab.ui.control.Label
        FrameSlider                 matlab.ui.control.Slider
        SaveMaskButton              matlab.ui.control.Button
        PolygonMaskCheckBox         matlab.ui.control.CheckBox
        MetadataTab                 matlab.ui.container.Tab
        UITable                     matlab.ui.control.Table
        Button4                     matlab.ui.control.Button
        Button5                     matlab.ui.control.Button
        Button6                     matlab.ui.control.Button
        Tab3                        matlab.ui.container.Tab
        STICScalculationPanel       matlab.ui.container.Panel
        RuninParallelCheckBox       matlab.ui.control.CheckBox
        CoresSpinnerLabel           matlab.ui.control.Label
        CoresSpinner                matlab.ui.control.Spinner
        STICS_sizes                 matlab.ui.control.Table
        NumberofRunsperFileSpinnerLabel  matlab.ui.control.Label
        NumberofRunsperFileSpinner  matlab.ui.control.Spinner
        ChooseimmobilesubstractionalgorithmDropDownLabel  matlab.ui.control.Label
        ChooseimmobilesubstractionalgorithmDropDown  matlab.ui.control.DropDown
        MovingaveragewindowsizepixelsEditFieldLabel  matlab.ui.control.Label
        MovingaveragewindowsizepixelsEditField  matlab.ui.control.NumericEditField
        DoneButton                  matlab.ui.control.Button
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        metadata = struct(); % Description
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FileIDListBox
        function FileIDListBoxValueChanged(app, event)
            value = app.FileIDListBox.Value;
%             file = [app.FileIDListBox.Items] == value;
            app.Image.ImageSource = [app.metadata.file_path, value];
%             app.FrameSlider.Limits = [0, n_frames];
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
           [file_id, file_path] = uigetfile("*","MultiSelect","on");
           app.FileIDListBox.Items = [app.FileIDListBox.Items, file_id];
           file = length(app.FileIDListBox.Items);
           app.metadata(file).file_id = file_id;
           app.metadata(file).file_path = file_path;
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            value = app.FrameSlider.Value;
            if value == 0
                % Display average
            else
                % Display corresponding frame
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {525, 525};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {246, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.FilesMasksTab = uitab(app.TabGroup);
            app.FilesMasksTab.Title = 'Files & Masks';
            app.SelectfilestoanalyzePanel = uipanel(app.FilesMasksTab);
            app.SelectfilestoanalyzePanel.Title = 'Select files to analyze';
            app.FileIDListBoxLabel = uilabel(app.SelectfilestoanalyzePanel);
            app.FileIDListBoxLabel.Text = 'File ID';
            app.FileIDListBox = uilistbox(app.SelectfilestoanalyzePanel);
            app.FileIDListBox.Items = {};
            app.FileIDListBox.ValueChangedFcn = createCallbackFcn(app, @FileIDListBoxValueChanged, true);
            app.FileIDListBox.Value = {};
            app.AddButton = uibutton(app.SelectfilestoanalyzePanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.SelectfilestoanalyzePanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.Panel2 = uipanel(app.FilesMasksTab);
            app.Panel2.Title = 'Panel2';
            app.Image = uiimage(app.Panel2);
            app.FrameSliderLabel = uilabel(app.Panel2);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.Panel2);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.SaveMaskButton = uibutton(app.Panel2, 'push');
            app.SaveMaskButton.Text = 'Save Mask';
            app.PolygonMaskCheckBox = uicheckbox(app.Panel2);
            app.PolygonMaskCheckBox.Text = 'Polygon Mask';
            app.MetadataTab = uitab(app.TabGroup);
            app.MetadataTab.Title = 'Metadata';
            app.UITable = uitable(app.MetadataTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Button4 = uibutton(app.MetadataTab, 'push');
            app.Button4.Text = 'Button4';
            app.Button5 = uibutton(app.MetadataTab, 'push');
            app.Button5.Text = 'Button5';
            app.Button6 = uibutton(app.MetadataTab, 'push');
            app.Button6.Text = 'Button6';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.STICScalculationPanel = uipanel(app.Tab3);
            app.STICScalculationPanel.Title = 'STICS calculation';
            app.RuninParallelCheckBox = uicheckbox(app.STICScalculationPanel);
            app.RuninParallelCheckBox.Text = 'Run in Parallel';
            app.CoresSpinnerLabel = uilabel(app.STICScalculationPanel);
            app.CoresSpinnerLabel.Text = 'Cores';
            app.CoresSpinner = uispinner(app.STICScalculationPanel);
            app.CoresSpinner.Limits = [0 Inf];
            app.STICS_sizes = uitable(app.STICScalculationPanel);
            app.STICS_sizes.ColumnName = {'File ID'; 'Immobile filter'; 'ROI size'; 'ROI shift'; 'TOI size'; 'TOI shift'; 'Tau limit'};
            app.STICS_sizes.ColumnEditable = true;
            app.NumberofRunsperFileSpinnerLabel = uilabel(app.STICScalculationPanel);
            app.NumberofRunsperFileSpinnerLabel.Text = 'Number of Runs per File';
            app.NumberofRunsperFileSpinner = uispinner(app.STICScalculationPanel);
            app.NumberofRunsperFileSpinner.Limits = [1 Inf];
            app.NumberofRunsperFileSpinner.Value = 1;
            app.ChooseimmobilesubstractionalgorithmDropDownLabel = uilabel(app.STICScalculationPanel);
            app.ChooseimmobilesubstractionalgorithmDropDownLabel.Text = 'Choose immobile substraction algorithm';
            app.ChooseimmobilesubstractionalgorithmDropDown = uidropdown(app.STICScalculationPanel);
            app.ChooseimmobilesubstractionalgorithmDropDown.Items = {'Whole Series Average Substraction', 'Moving Average', 'Butterworth IIR', 'None'};
            app.ChooseimmobilesubstractionalgorithmDropDown.Value = 'Whole Series Average Substraction';
            app.MovingaveragewindowsizepixelsEditFieldLabel = uilabel(app.STICScalculationPanel);
            app.MovingaveragewindowsizepixelsEditFieldLabel.Enable = 'off';
            app.MovingaveragewindowsizepixelsEditFieldLabel.Text = 'Moving average window size (pixels)';
            app.MovingaveragewindowsizepixelsEditField = uieditfield(app.STICScalculationPanel, 'numeric');
            app.MovingaveragewindowsizepixelsEditField.Limits = [0 Inf];
            app.MovingaveragewindowsizepixelsEditField.Enable = 'off';
            app.DoneButton = uibutton(app.RightPanel, 'push');
            app.DoneButton.Text = 'Done';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STICS_App_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end